classdef GeodesicCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        CenterPanel                     matlab.ui.container.Panel
        CALCULADORADEGEODESIAGENERALLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        CCoordenadasTab                 matlab.ui.container.Tab
        QudeseacalcularPanel            matlab.ui.container.Panel
        CalculodetransformacindeCoordenadasPanel  matlab.ui.container.Panel
        DeCoordenadasxyzaLatitudLongitudyAlturaLabel  matlab.ui.control.Label
        IngreseelvalordexEditFieldLabel  matlab.ui.control.Label
        IngreseelvalordexEditField      matlab.ui.control.NumericEditField
        IngreseelvalordeyEditFieldLabel  matlab.ui.control.Label
        IngreseelvalordeyEditField      matlab.ui.control.NumericEditField
        IngreseelvalordezEditFieldLabel  matlab.ui.control.Label
        IngreseelvalordezEditField      matlab.ui.control.NumericEditField
        LatitudEditFieldLabel           matlab.ui.control.Label
        LatitudEditField                matlab.ui.control.NumericEditField
        LongitudEditFieldLabel          matlab.ui.control.Label
        LongitudEditField               matlab.ui.control.NumericEditField
        AlturaEditFieldLabel            matlab.ui.control.Label
        AlturaEditField                 matlab.ui.control.NumericEditField
        DeCoordenadasLatitudLongitudyAlturaaxyzLabel  matlab.ui.control.Label
        IngreseelvalordelalatitudendecimalesEditFieldLabel  matlab.ui.control.Label
        IngreseelvalordelalatitudendecimalesEditField  matlab.ui.control.NumericEditField
        IngreseelvalordelalongitudendecimalesEditFieldLabel  matlab.ui.control.Label
        IngreseelvalordelalongitudendecimalesEditField  matlab.ui.control.NumericEditField
        EnGradosMinutosySegundosLabel   matlab.ui.control.Label
        LatitudLabel                    matlab.ui.control.Label
        LongitudLabel                   matlab.ui.control.Label
        IngreseelvalordelaalturaEditFieldLabel  matlab.ui.control.Label
        IngreseelvalordelaalturaEditField  matlab.ui.control.NumericEditField
        CalcularButton                  matlab.ui.control.Button
        CalcularButton_2                matlab.ui.control.Button
        CalcularButton_3                matlab.ui.control.Button
        TiposdeElipsodidesalquedeseaustedcalcularLabel  matlab.ui.control.Label
        GRS80Label                      matlab.ui.control.Label
        CalcularButton_4                matlab.ui.control.Button
        CalcularButton_5                matlab.ui.control.Button
        WGS84Label                      matlab.ui.control.Label
        HayfordLabel                    matlab.ui.control.Label
        GradosEditFieldLabel            matlab.ui.control.Label
        GradosEditField                 matlab.ui.control.NumericEditField
        MinutosEditFieldLabel           matlab.ui.control.Label
        MinutosEditField                matlab.ui.control.NumericEditField
        SegundosEditFieldLabel          matlab.ui.control.Label
        SegundosEditField               matlab.ui.control.NumericEditField
        GradosEditField_2Label          matlab.ui.control.Label
        GradosEditField_2               matlab.ui.control.NumericEditField
        MinutosEditField_2Label         matlab.ui.control.Label
        MinutosEditField_2              matlab.ui.control.NumericEditField
        SegundosEditField_9Label        matlab.ui.control.Label
        SegundosEditField_9             matlab.ui.control.NumericEditField
        CalcularButton_6                matlab.ui.control.Button
        GRS80Label_2                    matlab.ui.control.Label
        WGS84Label_2                    matlab.ui.control.Label
        HayfordLabel_2                  matlab.ui.control.Label
        IngreseelvalordelaAlturaEditFieldLabel  matlab.ui.control.Label
        IngreseelvalordelaAlturaEditField  matlab.ui.control.NumericEditField
        CalcularButton_7                matlab.ui.control.Button
        CalcularButton_8                matlab.ui.control.Button
        CalcularButton_9                matlab.ui.control.Button
        GRS80Label_3                    matlab.ui.control.Label
        WGS84Label_3                    matlab.ui.control.Label
        HayfordLabel_3                  matlab.ui.control.Label
        xEditField_3Label               matlab.ui.control.Label
        xEditField_3                    matlab.ui.control.EditField
        yEditField_3Label               matlab.ui.control.Label
        yEditField_3                    matlab.ui.control.EditField
        zEditField_3Label               matlab.ui.control.Label
        zEditField_3                    matlab.ui.control.EditField
        xEditField_4Label               matlab.ui.control.Label
        xEditField_4                    matlab.ui.control.EditField
        yEditField_4Label               matlab.ui.control.Label
        yEditField_4                    matlab.ui.control.EditField
        zEditField_4Label               matlab.ui.control.Label
        zEditField_4                    matlab.ui.control.EditField
        SButton                         matlab.ui.control.Button
        OButton                         matlab.ui.control.Button
        ReiniciarButton                 matlab.ui.control.Button
        ReiniciarButton_4               matlab.ui.control.Button
        ReiniciarButton_5               matlab.ui.control.Button
        MetrosLabel                     matlab.ui.control.Label
        RPrincipalesTab                 matlab.ui.container.Tab
        QudeseacalcularRadiosPrincipalesdeCurvaturaPanel  matlab.ui.container.Panel
        RadiosPrincipalesPanel          matlab.ui.container.Panel
        RadiodeCurvaturaconunAzimutdeunaseccinNormalLabel  matlab.ui.control.Label
        AzimutLabel                     matlab.ui.control.Label
        GradosEditField_3Label          matlab.ui.control.Label
        GradosEditField_3               matlab.ui.control.NumericEditField
        MinutosEditField_3Label         matlab.ui.control.Label
        MinutosEditField_3              matlab.ui.control.NumericEditField
        SegundosEditField_2Label        matlab.ui.control.Label
        SegundosEditField_2             matlab.ui.control.NumericEditField
        GradosEditField_4Label          matlab.ui.control.Label
        GradosEditField_4               matlab.ui.control.NumericEditField
        MinutosEditField_4Label         matlab.ui.control.Label
        MinutosEditField_4              matlab.ui.control.NumericEditField
        SegundosEditField_3Label        matlab.ui.control.Label
        SegundosEditField_3             matlab.ui.control.NumericEditField
        LatitudLabel_2                  matlab.ui.control.Label
        RazEditFieldLabel               matlab.ui.control.Label
        RazEditField                    matlab.ui.control.EditField
        CalcularButton_10               matlab.ui.control.Button
        GRS80Label_4                    matlab.ui.control.Label
        CalcularButton_11               matlab.ui.control.Button
        WGS84Label_4                    matlab.ui.control.Label
        CalcularButton_12               matlab.ui.control.Button
        HayfordLabel_4                  matlab.ui.control.Label
        RadiodeCurvaturaconunAzimutdeunaseccinNormalLabel_2  matlab.ui.control.Label
        AzimutEditFieldLabel            matlab.ui.control.Label
        AzimutEditField                 matlab.ui.control.NumericEditField
        LatitudEditField_2Label         matlab.ui.control.Label
        LatitudEditField_2              matlab.ui.control.NumericEditField
        ReiniciarButton_2               matlab.ui.control.Button
        RadiodeCurvaturaconunAzimutdeunaseccinNormalLabel_3  matlab.ui.control.Label
        RmEditFieldLabel                matlab.ui.control.Label
        RmEditField                     matlab.ui.control.EditField
        RadiodeCurvaturaconunAzimutdeunaseccinNormalLabel_4  matlab.ui.control.Label
        RpEditFieldLabel                matlab.ui.control.Label
        RpEditField                     matlab.ui.control.EditField
        GRS80Label_5                    matlab.ui.control.Label
        CalcularButton_13               matlab.ui.control.Button
        WGS84Label_5                    matlab.ui.control.Label
        CalcularButton_14               matlab.ui.control.Button
        HayfordLabel_5                  matlab.ui.control.Label
        CalcularButton_15               matlab.ui.control.Button
        GRS80Label_6                    matlab.ui.control.Label
        CalcularButton_16               matlab.ui.control.Button
        WGS84Label_6                    matlab.ui.control.Label
        CalcularButton_17               matlab.ui.control.Button
        HayfordLabel_6                  matlab.ui.control.Label
        CalcularButton_18               matlab.ui.control.Button
        SButton_2                       matlab.ui.control.Button
        ArcosMeridianoyParaleloTab      matlab.ui.container.Tab
        ClculodeLongituddeArcodeParaleloPanel  matlab.ui.container.Panel
        LongituddeArcodeParaleloLabel   matlab.ui.control.Label
        LatitudLabel_3                  matlab.ui.control.Label
        LongitudALabel                  matlab.ui.control.Label
        GradosEditField_5Label          matlab.ui.control.Label
        GradosEditField_5               matlab.ui.control.NumericEditField
        MinutosEditField_5Label         matlab.ui.control.Label
        MinutosEditField_5              matlab.ui.control.NumericEditField
        SegundosEditField_4Label        matlab.ui.control.Label
        SegundosEditField_4             matlab.ui.control.NumericEditField
        GradosEditField_6Label          matlab.ui.control.Label
        GradosEditField_6               matlab.ui.control.NumericEditField
        MinutosEditField_6Label         matlab.ui.control.Label
        MinutosEditField_6              matlab.ui.control.NumericEditField
        CalcularButton_30               matlab.ui.control.Button
        CalcularButton_31               matlab.ui.control.Button
        CalcularButton_32               matlab.ui.control.Button
        GRS80Label_7                    matlab.ui.control.Label
        WGS84Label_7                    matlab.ui.control.Label
        HayfordLabel_7                  matlab.ui.control.Label
        LatitudEditField_3Label         matlab.ui.control.Label
        LatitudEditField_3              matlab.ui.control.NumericEditField
        LongitudEditField_2Label        matlab.ui.control.Label
        LongitudEditField_2             matlab.ui.control.NumericEditField
        SpEditFieldLabel                matlab.ui.control.Label
        SpEditField                     matlab.ui.control.EditField
        SegundosEditField_7Label        matlab.ui.control.Label
        SegundosEditField_7             matlab.ui.control.NumericEditField
        ReiniciarButton_3               matlab.ui.control.Button
        SButton_3                       matlab.ui.control.Button
        OButton_2                       matlab.ui.control.Button
        LongitudBLabel                  matlab.ui.control.Label
        GradosEditField_9Label          matlab.ui.control.Label
        GradosEditField_9               matlab.ui.control.NumericEditField
        MinutosEditField_9Label         matlab.ui.control.Label
        MinutosEditField_9              matlab.ui.control.NumericEditField
        SegundosEditField_8Label        matlab.ui.control.Label
        SegundosEditField_8             matlab.ui.control.NumericEditField
        LongitudBEditFieldLabel         matlab.ui.control.Label
        LongitudBEditField              matlab.ui.control.NumericEditField
        Principales                     matlab.ui.container.Tab
        ClculodelProblemaDirectodelaGeodesiaPanel  matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        LatitudLabel_4                  matlab.ui.control.Label
        LongitudLabel_2                 matlab.ui.control.Label
        AzimutLabel_2                   matlab.ui.control.Label
        GradosEditField_10Label         matlab.ui.control.Label
        GradosEditField_10              matlab.ui.control.NumericEditField
        GradosEditField_11Label         matlab.ui.control.Label
        GradosEditField_11              matlab.ui.control.NumericEditField
        GradosEditField_12Label         matlab.ui.control.Label
        GradosEditField_12              matlab.ui.control.NumericEditField
        MinutosEditField_10Label        matlab.ui.control.Label
        MinutosEditField_10             matlab.ui.control.NumericEditField
        MinutosEditField_11Label        matlab.ui.control.Label
        MinutosEditField_11             matlab.ui.control.NumericEditField
        MinutosEditField_12Label        matlab.ui.control.Label
        MinutosEditField_12             matlab.ui.control.NumericEditField
        SegundosEditField_10Label       matlab.ui.control.Label
        SegundosEditField_10            matlab.ui.control.NumericEditField
        SegundosEditField_11Label       matlab.ui.control.Label
        SegundosEditField_11            matlab.ui.control.NumericEditField
        SegundosEditField_12Label       matlab.ui.control.Label
        SegundosEditField_12            matlab.ui.control.NumericEditField
        SButton_4                       matlab.ui.control.Button
        OButton_3                       matlab.ui.control.Button
        GRS80Button                     matlab.ui.control.Button
        HAYFORDButton                   matlab.ui.control.Button
        LatitudEditField_4Label         matlab.ui.control.Label
        LatitudEditField_4              matlab.ui.control.EditField
        LongitudEditField_3Label        matlab.ui.control.Label
        LongitudEditField_3             matlab.ui.control.EditField
        AzimutEditField_2Label          matlab.ui.control.Label
        AzimutEditField_2               matlab.ui.control.EditField
        Label_2                         matlab.ui.control.Label
        AzimutEditField_3Label          matlab.ui.control.Label
        AzimutEditField_3               matlab.ui.control.NumericEditField
        DistanciaEditFieldLabel         matlab.ui.control.Label
        DistanciaEditField              matlab.ui.control.NumericEditField
        WGS84Button                     matlab.ui.control.Button
        LongitudEditField_4Label        matlab.ui.control.Label
        LongitudEditField_4             matlab.ui.control.NumericEditField
        GRS80Button_2                   matlab.ui.control.Button
        WGS84Button_2                   matlab.ui.control.Button
        HAYFORDButton_2                 matlab.ui.control.Button
        RESULTADOSLabel                 matlab.ui.control.Label
        LatitudEditField_5Label         matlab.ui.control.Label
        LatitudEditField_5              matlab.ui.control.EditField
        LongitudEditField_5Label        matlab.ui.control.Label
        LongitudEditField_5             matlab.ui.control.EditField
        AzimutEditField_4Label          matlab.ui.control.Label
        AzimutEditField_4               matlab.ui.control.EditField
        LatitudEditField_6Label         matlab.ui.control.Label
        LatitudEditField_6              matlab.ui.control.NumericEditField
        ReiniciarButton_6               matlab.ui.control.Button
        ReiniciarButton_7               matlab.ui.control.Button
        PInversoTab                     matlab.ui.container.Tab
        ProblemaInversodelaGeodesiaPanel  matlab.ui.container.Panel
        GradosEditField_13Label         matlab.ui.control.Label
        GradosEditField_13              matlab.ui.control.NumericEditField
        LatitudLabel_5                  matlab.ui.control.Label
        MinutosEditField_13Label        matlab.ui.control.Label
        MinutosEditField_13             matlab.ui.control.NumericEditField
        SegundosEditField_13Label       matlab.ui.control.Label
        SegundosEditField_13            matlab.ui.control.NumericEditField
        LatitudLabel_6                  matlab.ui.control.Label
        GradosEditField_14Label         matlab.ui.control.Label
        GradosEditField_14              matlab.ui.control.NumericEditField
        MinutosEditField_14Label        matlab.ui.control.Label
        MinutosEditField_14             matlab.ui.control.NumericEditField
        SegundosEditField_14Label       matlab.ui.control.Label
        SegundosEditField_14            matlab.ui.control.NumericEditField
        IngresodelaLongitudPanel        matlab.ui.container.Panel
        GradosEditField_15Label         matlab.ui.control.Label
        GradosEditField_15              matlab.ui.control.NumericEditField
        MinutosEditField_15Label        matlab.ui.control.Label
        MinutosEditField_15             matlab.ui.control.NumericEditField
        SegundosEditField_15Label       matlab.ui.control.Label
        SegundosEditField_15            matlab.ui.control.NumericEditField
        GradosEditField_16Label         matlab.ui.control.Label
        GradosEditField_16              matlab.ui.control.NumericEditField
        MinutosEditField_16Label        matlab.ui.control.Label
        MinutosEditField_16             matlab.ui.control.NumericEditField
        SegundosEditField_16Label       matlab.ui.control.Label
        SegundosEditField_16            matlab.ui.control.NumericEditField
        LongitudLabel_3                 matlab.ui.control.Label
        LongitudLabel_4                 matlab.ui.control.Label
        OButton_4                       matlab.ui.control.Button
        ResultadosPanel                 matlab.ui.container.Panel
        DistanciaEditField_2Label       matlab.ui.control.Label
        DistanciaEditField_2            matlab.ui.control.EditField
        AzimutEditField_5Label          matlab.ui.control.Label
        AzimutEditField_5               matlab.ui.control.EditField
        CalcularButton_25               matlab.ui.control.Button
        ReiniciarButton_14              matlab.ui.control.Button
        metrosLabel                     matlab.ui.control.Label
        GRS80Label_10                   matlab.ui.control.Label
        metrosLabel_2                   matlab.ui.control.Label
        CalcularButton_26               matlab.ui.control.Button
        WGS84Label_8                    matlab.ui.control.Label
        metrosLabel_3                   matlab.ui.control.Label
        CalcularButton_27               matlab.ui.control.Button
        HAYFORDLabel                    matlab.ui.control.Label
        DistanciaEditField_3Label       matlab.ui.control.Label
        DistanciaEditField_3            matlab.ui.control.EditField
        AzimutEditField_6Label          matlab.ui.control.Label
        AzimutEditField_6               matlab.ui.control.EditField
        DistanciaEditField_4Label       matlab.ui.control.Label
        DistanciaEditField_4            matlab.ui.control.EditField
        AzimutEditField_7Label          matlab.ui.control.Label
        AzimutEditField_7               matlab.ui.control.EditField
        SButton_5                       matlab.ui.control.Button
        GravedadNormalTab               matlab.ui.container.Tab
        GeodesiaFsicaPanel              matlab.ui.container.Panel
        GravedadNormalyGravedadEncimadelElipsoidePanel  matlab.ui.container.Panel
        IngreseelvalordelaLatitudendecimalesLabel  matlab.ui.control.Label
        LatitudEditField_7Label         matlab.ui.control.Label
        LatitudEditField_7              matlab.ui.control.NumericEditField
        CalcularButton_19               matlab.ui.control.Button
        GRS80Label_8                    matlab.ui.control.Label
        GravedadNormalEditFieldLabel    matlab.ui.control.Label
        GravedadNormalEditField         matlab.ui.control.EditField
        ReiniciarButton_8               matlab.ui.control.Button
        ClculodelaGravedadNormalEncimadelElipsoideLabel  matlab.ui.control.Label
        IngreseelvalordelaLatitudendecimalesLabel_2  matlab.ui.control.Label
        LatitudEditField_8Label         matlab.ui.control.Label
        LatitudEditField_8              matlab.ui.control.NumericEditField
        GRS80Label_9                    matlab.ui.control.Label
        CalcularButton_20               matlab.ui.control.Button
        GravedadResultadoEditFieldLabel  matlab.ui.control.Label
        GravedadResultadoEditField      matlab.ui.control.EditField
        ReiniciarButton_9               matlab.ui.control.Button
        VerificarButton                 matlab.ui.control.Button
        AlturaEditField_2Label          matlab.ui.control.Label
        AlturaEditField_2               matlab.ui.control.NumericEditField
        AlturasyAnomalasTab             matlab.ui.container.Tab
        ClculodeAlturasRelativasPanel   matlab.ui.container.Panel
        AlturaElipsoidalEditFieldLabel  matlab.ui.control.Label
        AlturaElipsoidalEditField       matlab.ui.control.NumericEditField
        AlturaOrtomtricaoNiveladaEditFieldLabel  matlab.ui.control.Label
        AlturaOrtomtricaoNiveladaEditField  matlab.ui.control.NumericEditField
        NEGM08EditFieldLabel            matlab.ui.control.Label
        NEGM08EditField                 matlab.ui.control.NumericEditField
        CalcularButton_21               matlab.ui.control.Button
        ResultadoEditFieldLabel         matlab.ui.control.Label
        ResultadoEditFieldH             matlab.ui.control.EditField
        AnomalasdeGravedadconCorreccinalAireLibrePanel  matlab.ui.container.Panel
        LatitudEditField_10Label        matlab.ui.control.Label
        LatitudEditField_10             matlab.ui.control.NumericEditField
        AlturaEditField_3Label          matlab.ui.control.Label
        AlturaEditField_3               matlab.ui.control.NumericEditField
        GravedadenelPuntoObservadoEditFieldLabel  matlab.ui.control.Label
        GravedadenelPuntoObservadoEditField  matlab.ui.control.NumericEditField
        GravedadCA                      matlab.ui.control.Button
        ResultadoEditField_2Label       matlab.ui.control.Label
        ResultadoEditField_2            matlab.ui.control.EditField
        AnomaladeGravedadconCorrecionesdemonsieurBouguerPanel  matlab.ui.container.Panel
        LatitudEditField_11Label        matlab.ui.control.Label
        LatitudEditField_11             matlab.ui.control.NumericEditField
        AlturaEditField_4Label          matlab.ui.control.Label
        AlturaEditField_4               matlab.ui.control.NumericEditField
        DensidadEditFieldLabel          matlab.ui.control.Label
        DensidadEditField               matlab.ui.control.NumericEditField
        NotaTextAreaLabel               matlab.ui.control.Label
        NotaTextArea                    matlab.ui.control.TextArea
        CalcularButton_23               matlab.ui.control.Button
        ResultadoEditField_3Label       matlab.ui.control.Label
        ResultadoEditField_3            matlab.ui.control.EditField
        MetrosLabel_3                   matlab.ui.control.Label
        gcm3Label                       matlab.ui.control.Label
        GravedadenelPuntoObservadoEditField_2Label  matlab.ui.control.Label
        GravedadenelPuntoObservadoEditField_2  matlab.ui.control.NumericEditField
        ReiniciarButton_12              matlab.ui.control.Button
        mGALLabel_4                     matlab.ui.control.Label
        mGALLabel_5                     matlab.ui.control.Label
        PerturbacionesdeGravedadPanel   matlab.ui.container.Panel
        LatitudEditField_12Label        matlab.ui.control.Label
        LatitudEditField_12             matlab.ui.control.NumericEditField
        AlturaEditField_5Label          matlab.ui.control.Label
        AlturaEditField_5               matlab.ui.control.NumericEditField
        GravedadenelpuntoobservadoEditFieldLabel  matlab.ui.control.Label
        GravedadenelpuntoobservadoEditField  matlab.ui.control.NumericEditField
        CalcularButton_24               matlab.ui.control.Button
        ResultadoEditField_4Label       matlab.ui.control.Label
        ResultadoEditField_4            matlab.ui.control.EditField
        mGALLabel                       matlab.ui.control.Label
        MetrosLabel_4                   matlab.ui.control.Label
        mGALLabel_6                     matlab.ui.control.Label
        ReiniciarButton_13              matlab.ui.control.Button
        MetrosLabel_2                   matlab.ui.control.Label
        mGALLabel_2                     matlab.ui.control.Label
        ReiniciarButton_11              matlab.ui.control.Button
        mGALLabel_3                     matlab.ui.control.Label
        AlturaElipsoidalBEditFieldLabel  matlab.ui.control.Label
        AlturaElipsoidalBEditField      matlab.ui.control.NumericEditField
        NEGM08_BEditFieldLabel          matlab.ui.control.Label
        NEGM08_BEditField               matlab.ui.control.NumericEditField
        ReiniciarButton_10              matlab.ui.control.Button
        TMarcosTab                      matlab.ui.container.Tab
        IngresodeTraslacionesyDiferenciadeEscalaPanel  matlab.ui.container.Panel
        TxEditFieldLabel                matlab.ui.control.Label
        TxEditField                     matlab.ui.control.NumericEditField
        TyEditFieldLabel                matlab.ui.control.Label
        TyEditField                     matlab.ui.control.NumericEditField
        TzEditFieldLabel                matlab.ui.control.Label
        TzEditField                     matlab.ui.control.NumericEditField
        metrosLabel_4                   matlab.ui.control.Label
        metrosLabel_5                   matlab.ui.control.Label
        metrosLabel_6                   matlab.ui.control.Label
        DiferenciadeEscalaEditFieldLabel  matlab.ui.control.Label
        DiferenciadeEscalaEditField     matlab.ui.control.NumericEditField
        RotacionesPanel                 matlab.ui.container.Panel
        RxEditFieldLabel                matlab.ui.control.Label
        RxEditField                     matlab.ui.control.NumericEditField
        RyEditFieldLabel                matlab.ui.control.Label
        RyEditField                     matlab.ui.control.NumericEditField
        RzEditFieldLabel                matlab.ui.control.Label
        RzEditField                     matlab.ui.control.NumericEditField
        TextArea                        matlab.ui.control.TextArea
        ConversindeRotacionesPanel      matlab.ui.container.Panel
        RxEditField_2Label              matlab.ui.control.Label
        RxEditField_2                   matlab.ui.control.NumericEditField
        RyEditField_2Label              matlab.ui.control.Label
        RyEditField_2                   matlab.ui.control.NumericEditField
        RzEditField_2Label              matlab.ui.control.Label
        RzEditField_2                   matlab.ui.control.NumericEditField
        mAsLabel                        matlab.ui.control.Label
        mAsLabel_2                      matlab.ui.control.Label
        mAsLabel_3                      matlab.ui.control.Label
        CalcularButton_29               matlab.ui.control.Button
        ResultadoPanel                  matlab.ui.container.Panel
        RxEditField_3Label              matlab.ui.control.Label
        RxEditField_3                   matlab.ui.control.EditField
        RyEditField_3Label              matlab.ui.control.Label
        RyEditField_3                   matlab.ui.control.EditField
        RzEditField_3Label              matlab.ui.control.Label
        RzEditField_3                   matlab.ui.control.EditField
        IngresodeCoordenadasInicialesPanel  matlab.ui.container.Panel
        XEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        ZEditField                      matlab.ui.control.NumericEditField
        metrosLabel_7                   matlab.ui.control.Label
        metrosLabel_8                   matlab.ui.control.Label
        metrosLabel_9                   matlab.ui.control.Label
        CoordenadasFinalesPanel         matlab.ui.container.Panel
        XEditField_2Label               matlab.ui.control.Label
        XEditField_2                    matlab.ui.control.EditField
        YEditField_2Label               matlab.ui.control.Label
        YEditField_2                    matlab.ui.control.EditField
        ZEditField_2Label               matlab.ui.control.Label
        ZEditField_2                    matlab.ui.control.EditField
        metrosLabel_10                  matlab.ui.control.Label
        metrosLabel_11                  matlab.ui.control.Label
        metrosLabel_12                  matlab.ui.control.Label
        CalcularButton_28               matlab.ui.control.Button
        ReiniciarButton_15              matlab.ui.control.Button
        AcercadeTab                     matlab.ui.container.Tab
        FnUnicodelaCalculadoradeGeodesiaGeneralPanel  matlab.ui.container.Panel
        TextArea_2                      matlab.ui.control.TextArea
        AcercadelAutorPanel             matlab.ui.container.Panel
        TextArea_3                      matlab.ui.control.TextArea
        RightPanel                      matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        x = 0; % Valor inicial para x
        y = 0; % Valor inicial para y
        z = 0; % Valor inicial para z
        latitud=0;
        longitud=0;
        latitudb=0;
        longitudb=0;
        altura=0;
        Latitud=0;
        Latitudb=0;
        Longitud=0;
        Longitudb=0;
        Latitud1=0;
        Longitud1=0;
        v1=0;
        v2=0;
        v3=0;
        v11=0;
        v12=0;
        v13=0;
        Sur=0;
        Oeste=0;
        la1=0;
        la2=0;
        
        %-------------- VARIABLES DE RADIOS DE CURVATURA------------------
        w1=0; %AZIMUT
        w2=0;
        w3=0;
        w11=0; % LATITUDES
        w12=0;
        w13=0;
        d_latitud=0;
        d_azimut=0;
        m1=0;
        m2=0;
        m3=0;
        m1a=0;
        m2a=0;
        m3a=0;
        m11=0;
        m12=0;
        m13=0;
        m11b=0;
        m12b=0;
        m13b=0;
        Azimut=0;
        azimut=0;
        distancia=0;
        Fi=0;
        Landa=0;
        %----------Variables para Cálculo de Problema Directo--------%
        Grados=0;
        Minutos=0;
        Segundos=0;
        Grados1=0;
        Minutos1=0;
        Segundos1=0;
        Grados2=0;
        Minutos2=0;
        Segundos2=0;
        Distancia=0;
        %------------Variables para el Cálculo de la Gravedad Normal y sobre el elipsoide------
        gammae=0;
        gammap=0;
        %----------- Variables para las alturas relativas----------------
        AlturaOrtometrica=0;
        NrGeo=0;
        NEGM08=0;
	    NEGM08B=0;
        AlturaElipsoidalA=0;
	    AlturaElipsoidalB=0;
        %------------ Anomalías de Gravedad con Corrección al Aire Libre--
        Altura=0;
        GravedadPo=0; % GRAVEDAD EN EL PUNTO OBSERVADO.
        AgAl=0;
        %---------------- Anomalías con la corrección de Bouguer----------
        Densidad=0;
        AgBs=0;
        GravedadPobservado=0;
        %---------------- Perturbaciones de Gravedad ----------------
        GravedadObservado=0;
        %------------- Problema Inverso de la Geodesia----------------
        g1=0;
        g11=0;
        g111=0;
        g2=0;
        g22=0;
        g222=0;
        g3=0;
        g33=0;
        g333=0;
        g4=0;
        g44=0;
        g444=0;
        %---------- TRANSFORMACION DE PARÁMETROS-----------------------
        T=0;
        de=0;
        ro=0;
        co=0;
        Tx=0;
        Ty=0;
        Tz=0;
        Rx=0;
        Ry=0;
        Rz=0;
        Rx1=0;
        Ry1=0;
        Rz1=0;
        DiferenciadeEscala=0;
        X=0;
        Y=0;
        Z=0;
        
    end
    
    methods (Access = public)
        
        function startupFcn(app)
            % ESTO ES PARA GRS 80 EN EL TIPO DE ELIPSOIDE.
            app.gammae = 978032.67715;
            app.gammap = 983218.63685;
            %app.valorGravedad=0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
            a= 6378137.0;
            f= 1/298.257222101;
            % wgs 84
            a1= 6378137.0;
            f1= 1/298.257223563;
            % HAYFORD
            a2= 6378388;
            f2=1/297;
            %******************* CALCULOS***************************
            e1= 2*(f)-(f)^2;
            b= a*sqrt(1-e1);
            e2= ((a/b)^2)-1;
            p=sqrt(app.x^2 + app.y^2);
            la= atand(app.y/app.x);
            la1=0;
            la2=0;
            
             % Verificar si los valores de entrada han cambiado
            if app.x == 0 && app.y == 0 && app.z == 0
                msgbox('Los valores de entrada son los mismos que antes. Por favor, cambia los valores antes de recalcular.');
                return;
            end
            
           if app.x~=0
               
               if (app.x<0 & app.y>0)
                   la1=(180+la);
               elseif (app.x<0 & app.y<0)
                   la1=-(180-la);
               elseif (app.x>0 & app.y<0)
                   la1=(-1)*la;
               elseif (app.x>0 & app.y>0)
                   la1=la;
               end
               
               % Check if la1 is valid before further processing
               if isnan(la1) || isinf(la1)
                   disp('la1 is not a valid number');
                   return;
               end
               
               teta=atand((app.z*a)/(p*b))
               fi= atand((app.z+e2*b*sind(teta)^3)/(p-e1*a*cosd(teta)^3))
               fi1=round(fi)
               N=(a)/(sqrt(1-e1*sind(fi1)^2))
               h= (p/cosd(fi1))- N
               h = round(h)
               
               % Check if the values are valid before assigning
               if isnan(fi1) || isinf(fi1)
                   disp('fi1 is not a valid number');
                   return;
               end
               if isnan(h) || isinf(h)
                   disp('h is not a valid number');
                   return;
               end
               
               app.LatitudEditField.Value=double(fi1);
               app.LongitudEditField.Value=double(la1);
               app.AlturaEditField.Value=double(h);
               
           elseif app.x==0
               la=90;
               la2=la;
               
               % Check if la2 is valid before further processing
               if isnan(la2) || isinf(la2)
                   disp('la2 is not a valid number');
                   return;
               end
               
               teta=atand((app.z*a)/(p*b));
               fi= atand((app.z+e2*b*sind(teta)^3)/(p-e1*a*cosd(teta)^3));
               fi1=round(fi);
               N=(a)/(sqrt(1-e1*sind(fi1)^2));
               h= (p/cosd(fi1))- N;
               h = round(h);
               
               % Check if the values are valid before assigning
               if isnan(fi1) || isinf(fi1)
                   disp('fi1 is not a valid number');
                   return;
               end
               if isnan(h) || isinf(h)
                   disp('h is not a valid number');
                   return;
               end
               
               app.LatitudEditField.Value=double(fi1);
               app.LongitudEditField.Value=double(la2);
               app.AlturaEditField.Value=double(h);
           end

            
        end

        % Value changed function: IngreseelvalordexEditField
        function IngreseelvalordexEditFieldValueChanged(app, event)
            app.x = app.IngreseelvalordexEditField.Value;
           
        end

        % Value changed function: IngreseelvalordeyEditField
        function IngreseelvalordeyEditFieldValueChanged(app, event)
            
            app.y = app.IngreseelvalordeyEditField.Value;
            
        end

        % Value changed function: IngreseelvalordezEditField
        function IngreseelvalordezEditFieldValueChanged(app, event)
             app.z = app.IngreseelvalordezEditField.Value;
            
        end

        % Value changed function: LatitudEditField
        function LatitudEditFieldValueChanged(app, event)
            % Ningún Valor
        end

        % Value changed function: LongitudEditField
        function LongitudEditFieldValueChanged(app, event)
           %Ningún Valor
        end

        % Value changed function: AlturaEditField
        function AlturaEditFieldValueChanged(app, event)
           %Ningún Valor
        end

        % Button pushed function: CalcularButton_4
        function CalcularButton_4Pushed(app, event)
            % ELIPSOIDE WGS 84
            
            a1= 6378137.0;
            f1= 1/298.257223563;
            e1= 2*(f1)-(f1)^2;
            b1= a1*sqrt(1-e1);
            %******************EFECTUACION DE LOS CALCULOS*****************
            e2= ((a1/b1)^2)-1;
            p1=sqrt(app.x^2 + app.y^2);
            e2= ((a1/b1)^2)-1;
            la= atand(app.y/app.x);
            la1=0;
            la2=0;
            
            % Verificar si los valores de entrada han cambiado
            if app.x == 0 && app.y == 0 && app.z == 0
                msgbox('Los valores de entrada son los mismos que antes. Por favor, cambia los valores antes de recalcular.');
                return;
            end
            
           if app.x~=0
               
               if (app.x<0 & app.y>0)
                   la1=(180+la);
               elseif (app.x<0 & app.y<0)
                   la1=-(180-la);
               elseif (app.x>0 & app.y<0)
                   la1=(-1)*la;
               elseif (app.x>0 & app.y>0)
                   la1=la;
               end
               
               % Check if la1 is valid before further processing
               if isnan(la1) || isinf(la1)
                   disp('la1 is not a valid number');
                   return;
               end
               
               teta=atand((app.z*a1)/(p1*b1))
               fi= atand((app.z+e2*b1*sind(teta)^3)/(p1-e1*a1*cosd(teta)^3))
               fi1=round(fi)
               N=(a1)/(sqrt(1-e1*sind(fi1)^2))
               h= (p1/cosd(fi1))- N
               h = round(h)
               
               % Check if the values are valid before assigning
               if isnan(fi1) || isinf(fi1)
                   disp('fi1 is not a valid number');
                   return;
               end
               if isnan(h) || isinf(h)
                   disp('h is not a valid number');
                   return;
               end
               
               app.LatitudEditField.Value=double(fi1);
               app.LongitudEditField.Value=double(la1);
               app.AlturaEditField.Value=double(h);
               
           elseif app.x==0
               la=90;
               la2=la;
               
               % Check if la2 is valid before further processing
               if isnan(la2) || isinf(la2)
                   disp('la2 is not a valid number');
                   return;
               end
               
               teta=atand((app.z*a1)/(p1*b1));
               fi= atand((app.z+e2*b1*sind(teta)^3)/(p1-e1*a1*cosd(teta)^3));
               fi1=round(fi);
               N=(a1)/(sqrt(1-e1*sind(fi1)^2));
               h= (p1/cosd(fi1))- N;
               h = round(h);
               
               % Check if the values are valid before assigning
               if isnan(fi1) || isinf(fi1)
                   disp('fi1 is not a valid number');
                   return;
               end
               if isnan(h) || isinf(h)
                   disp('h is not a valid number');
                   return;
               end
               
               app.LatitudEditField.Value=double(fi1);
               app.LongitudEditField.Value=double(la2);
               app.AlturaEditField.Value=double(h);
           end
            
        end

        % Button pushed function: CalcularButton_5
        function CalcularButton_5Pushed(app, event)
             % HAYFORD
            a2= 6378388;
            f2=1/297;
            %******************* CALCULOS***************************
            e1= 2*(f2)-(f2)^2;
            b2= a2*sqrt(1-e1);
            e2= ((a2/b2)^2)-1;
            p2=sqrt(app.x^2 + app.y^2);
            la= atand(app.y/app.x);
            la1=0;
            la2=0;
            
             % Verificar si los valores de entrada han cambiado
            if app.x == 0 && app.y == 0 && app.z == 0
                msgbox('Los valores de entrada son los mismos que antes. Por favor, cambia los valores antes de recalcular.');
                return;
            end
            
           if app.x~=0
               
               if (app.x<0 & app.y>0)
                   la1=(180+la);
               elseif (app.x<0 & app.y<0)
                   la1=-(180-la);
               elseif (app.x>0 & app.y<0)
                   la1=(-1)*la;
               elseif (app.x>0 & app.y>0)
                   la1=la;
               end
               
               % Check if la1 is valid before further processing
               if isnan(la1) || isinf(la1)
                   disp('la1 is not a valid number');
                   return;
               end
               
               teta=atand((app.z*a2)/(p2*b2))
               fi= atand((app.z+e2*b2*sind(teta)^3)/(p2-e1*a2*cosd(teta)^3))
               fi1=round(fi)
               N=(a2)/(sqrt(1-e1*sind(fi1)^2))
               h= (p2/cosd(fi1))- N
               h = round(h)
               
               % Check if the values are valid before assigning
               if isnan(fi1) || isinf(fi1)
                   disp('fi1 is not a valid number');
                   return;
               end
               if isnan(h) || isinf(h)
                   disp('h is not a valid number');
                   return;
               end
               
               app.LatitudEditField.Value=double(fi1);
               app.LongitudEditField.Value=double(la1);
               app.AlturaEditField.Value=double(h);
               
           elseif app.x==0
               la=90;
               la2=la;
               
               % Check if la2 is valid before further processing
               if isnan(la2) || isinf(la2)
                   disp('la2 is not a valid number');
                   return;
               end
               
               teta=atand((app.z*a2)/(p2*b2));
               fi= atand((app.z+e2*b2*sind(teta)^3)/(p2-e1*a2*cosd(teta)^3));
               fi1=round(fi);
               N=(a2)/(sqrt(1-e1*sind(fi1)^2));
               h= (p2/cosd(fi1))- N;
               h = round(h);
               
               % Check if the values are valid before assigning
               if isnan(fi1) || isinf(fi1)
                   disp('fi1 is not a valid number');
                   return;
               end
               if isnan(h) || isinf(h)
                   disp('h is not a valid number');
                   return;
               end
               
               app.LatitudEditField.Value=double(fi1);
               app.LongitudEditField.Value=double(la2);
               app.AlturaEditField.Value=double(h);
           end

        end

        % Value changed function: 
        % IngreseelvalordelalatitudendecimalesEditField
        function IngreseelvalordelalatitudendecimalesEditFieldValueChanged(app, event)
            app.latitud = app.IngreseelvalordelalatitudendecimalesEditField.Value;
            
        end

        % Value changed function: 
        % IngreseelvalordelalongitudendecimalesEditField
        function IngreseelvalordelalongitudendecimalesEditFieldValueChanged(app, event)
            app.longitud = app.IngreseelvalordelalongitudendecimalesEditField.Value;
            
        end

        % Callback function
        function IngreseelvalordelaalturaEditFieldValueChanged(app, event)
            app.altura = app.IngreseelvalordelaalturaEditField.Value;
            
        end

        % Button pushed function: CalcularButton_2
        function CalcularButton_2Pushed(app, event)
            % Conversión de Cordenadas Geodésicas a Cartesianas
            % Información Preliminar.
            % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
            format long
            app.latitud;
            app.longitud;
            app.altura;
            a= 6378137.0;
            f= 1/298.257222101;
            % wgs 84
            a1= 6378137.0;
            f1= 1/298.257223563;
            % HAYFORD
            a2= 6378388;
            f2=1/297;
            % ********** EFECTUACION DE LOS CALCULOS**********************
            e1= 2*(f)-(f)^2; 
            N=(a)/(sqrt(1-e1*sind(app.latitud)^2))
            X=(N+app.altura)*cosd(app.latitud)*cosd(app.longitud)
            round(X)
            %X1=round(X,12,"significant");
            Y=(N+app.altura)*cosd(app.latitud)*sind(app.longitud)
            round(Y)
            %Y1=round(Y,12,"significant");
            Z=((1-e1)*N+app.altura)*sind(app.latitud)
             round(Z)
            %Z1=round(Z,12,"significant");
                        
            % Formatear los números con 5 decimales
            X_str = sprintf('%.5f', X)
            Y_str = sprintf('%.5f', Y)
            Z_str = sprintf('%.5f', Z)
               
                       
            app.xEditField_3.Value = X_str;
            app.yEditField_3.Value = Y_str;
            app.zEditField_3.Value = Z_str;
             
        end

        % Button pushed function: CalcularButton_3
        function CalcularButton_3Pushed(app, event)
             % Conversión de Cordenadas Geodésicas a Cartesianas
            % Información Preliminar.
            % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
            format long
            a= 6378137.0;
            f= 1/298.257222101;
            % wgs 84
            a1= 6378137.0;
            f1= 1/298.257223563;
            % HAYFORD
            a2= 6378388;
            f2=1/297;
            % ********** EFECTUACION DE LOS CALCULOS**********************
            e1= 2*(f1)-(f1)^2; 
            N=(a1)/(sqrt(1-e1*sind(app.latitud)^2));
            X=(N+app.altura)*cosd(app.latitud)*cosd(app.longitud);
            round(X)
            %X1=round(X,12,"significant");
            Y=(N+app.altura)*cosd(app.latitud)*sind(app.longitud);
            round(Y)
            %Y1=round(Y,12,"significant");
            Z=((1-e1)*N+app.altura)*sind(app.latitud);
             round(Z)
            %Z1=round(Z,12,"significant");
                        
            % Formatear los números con 5 decimales
            X_str = sprintf('%.5f', X);
            Y_str = sprintf('%.5f', Y);
            Z_str = sprintf('%.5f', Z);
               
                       
            app.xEditField_3.Value = X_str;
            app.yEditField_3.Value = Y_str;
            app.zEditField_3.Value = Z_str;
        end

        % Button pushed function: CalcularButton_6
        function CalcularButton_6Pushed(app, event)
            fprintf('Calculo de la primera excentricidad con GRS80: \n')
                    format long
            a= 6378137.0;
            f= 1/298.257222101;
            % wgs 84
            a1= 6378137.0;
            f1= 1/298.257223563;
            % HAYFORD
            a2= 6378388;
            f2=1/297;
            % ********** EFECTUACION DE LOS CALCULOS**********************
            e1= 2*(f2)-(f2)^2; 
            N=(a2)/(sqrt(1-e1*sind(app.latitud)^2));
            X=(N+app.altura)*cosd(app.latitud)*cosd(app.longitud);
            round(X)
            %X1=round(X,12,"significant");
            Y=(N+app.altura)*cosd(app.latitud)*sind(app.longitud);
            round(Y)
            %Y1=round(Y,12,"significant");
            Z=((1-e1)*N+app.altura)*sind(app.latitud);
             round(Z)
            %Z1=round(Z,12,"significant");
                        
            % Formatear los números con 5 decimales
            X_str = sprintf('%.5f', X);
            Y_str = sprintf('%.5f', Y);
            Z_str = sprintf('%.5f', Z);
               
                       
            app.xEditField_3.Value = X_str;
            app.yEditField_3.Value = Y_str;
            app.zEditField_3.Value = Z_str;
        end

        % Value changed function: GradosEditField
        function GradosEditFieldValueChanged(app, event)
            app.v11 = app.GradosEditField.Value; % LATITUD OJO
            
        end

        % Value changed function: MinutosEditField
        function MinutosEditFieldValueChanged(app, event)
            app.v12 = app.MinutosEditField.Value;
            
        end

        % Value changed function: SegundosEditField
        function SegundosEditFieldValueChanged(app, event)
            app.v13 = app.SegundosEditField.Value;
            
        end

        % Value changed function: GradosEditField_2
        function GradosEditField_2ValueChanged(app, event)
            app.v1 = app.GradosEditField_2.Value; %PARA LA LONGITUD
            
        end

        % Value changed function: MinutosEditField_2
        function MinutosEditField_2ValueChanged(app, event)
            app.v2 = app.MinutosEditField_2.Value;
            
        end

        % Value changed function: SegundosEditField_9
        function SegundosEditField_9ValueChanged(app, event)
            app.v3 = app.SegundosEditField_9.Value;
            
        end

        % Value changed function: IngreseelvalordelaAlturaEditField
        function IngreseelvalordelaAlturaEditFieldValueChanged(app, event)
            app.altura = app.IngreseelvalordelaAlturaEditField.Value;
            
        end

        % Button pushed function: CalcularButton_7
        function CalcularButton_7Pushed(app, event)
            % Conversión de Cordenadas Geodésicas a Cartesianas
            % Información Preliminar.
            % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
            format long
            a= 6378137.0;
            f= 1/298.257222101;
            % wgs 84
            a1= 6378137.0;
            f1= 1/298.257223563;
            % HAYFORD
            a2= 6378388;
            f2=1/297;
            % ********** EFECTUACION DE LOS CALCULOS**********************
                              
            e1= 2*(f)-(f)^2; 
            N=(a)/(sqrt(1-e1*sind(app.Latitud1)^2));
            X=(N+app.altura)*cosd(app.Latitud1)*cosd(app.Longitud1);
            
            %X1=round(X,12,"significant");
            Y=(N+app.altura)*cosd(app.Latitud1)*sind(app.Longitud1);
            
            %Y1=round(Y,12,"significant");
            Z=((1-e1)*N+app.altura)*sind(app.Latitud1);
            
            %Z1=round(Z,12,"significant");
                        
            % Formatear los números con 5 decimales
            X_str = sprintf('%.5f', X);
            Y_str = sprintf('%.5f', Y);
            Z_str = sprintf('%.5f', Z);
               
                       
            app.xEditField_4.Value = X_str;
            app.yEditField_4.Value = Y_str;
            app.zEditField_4.Value = Z_str;
        end

        % Button pushed function: CalcularButton_8
        function CalcularButton_8Pushed(app, event)
            % Conversión de Cordenadas Geodésicas a Cartesianas
            % Información Preliminar.
            % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
            format long
            a= 6378137.0;
            f= 1/298.257222101;
            % wgs 84
            a1= 6378137.0;
            f1= 1/298.257223563;
            % HAYFORD
            a2= 6378388;
            f2=1/297;
            % ********** EFECTUACION DE LOS CALCULOS**********************
                                 
            e1= 2*(f1)-(f1)^2; 
            N=(a1)/(sqrt(1-e1*sind(app.Latitud1)^2));
            X=(N+app.altura)*cosd(app.Latitud1)*cosd(app.Longitud1);
            
            %X1=round(X,12,"significant");
            Y=(N+app.altura)*cosd(app.Latitud1)*sind(app.Longitud1);
            
            %Y1=round(Y,12,"significant");
            Z=((1-e1)*N+app.altura)*sind(app.Latitud1);
            
            %Z1=round(Z,12,"significant");
                        
            % Formatear los números con 5 decimales
            X_str = sprintf('%.5f', X);
            Y_str = sprintf('%.5f', Y);
            Z_str = sprintf('%.5f', Z);
               
                       
            app.xEditField_4.Value = X_str;
            app.yEditField_4.Value = Y_str;
            app.zEditField_4.Value = Z_str;
        end

        % Button pushed function: CalcularButton_9
        function CalcularButton_9Pushed(app, event)
            % Conversión de Cordenadas Geodésicas a Cartesianas
            % Información Preliminar.
            % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
            format long
            a= 6378137.0;
            f= 1/298.257222101;
            % wgs 84
            a1= 6378137.0;
            f1= 1/298.257223563;
            % HAYFORD
            a2= 6378388;
            f2=1/297;
            % ********** EFECTUACION DE LOS CALCULOS**********************
                            
            e1= 2*(f2)-(f2)^2; 
            N=(a2)/(sqrt(1-e1*sind(app.Latitud1)^2));
            X=(N+app.altura)*cosd(app.Latitud1)*cosd(app.Longitud1);
            
            %X1=round(X,12,"significant");
            Y=(N+app.altura)*cosd(app.Latitud1)*sind(app.Longitud1);
            
            %Y1=round(Y,12,"significant");
            Z=((1-e1)*N+app.altura)*sind(app.Latitud1);
            
            %Z1=round(Z,12,"significant");
                        
            % Formatear los números con 5 decimales
            X_str = sprintf('%.5f', X);
            Y_str = sprintf('%.5f', Y);
            Z_str = sprintf('%.5f', Z);
               
                       
            app.xEditField_4.Value = X_str;
            app.yEditField_4.Value = Y_str;
            app.zEditField_4.Value = Z_str;
        end

        % Callback function
        function NButtonPushed(app, event)
               % AQUI NO HAY NADA
            
        end

        % Callback function
        function EButtonPushed(app, event)
            % AQUI NO HAY NADA 
        end

        % Button pushed function: SButton
        function SButtonPushed(app, event)
            %app.Sur=1;
            app.latitud=[app.v11 app.v12 app.v13];
            app.longitud=[app.v1 app.v2 app.v3];
            dms=[app.latitud;app.longitud];
            angleInDegrees=dms2degrees(dms);
            Latitud=angleInDegrees(1,1);
            Longitud=angleInDegrees(2,1);
            app.Sur=1;
            %app.Latitud1 = Latitud;
            if app.Sur == 1
                app.Latitud1 = Latitud * -1;
            end
           % Desactivar el botón S
           app.SButton.Enable = 'off';
        end

        % Button pushed function: OButton
        function OButtonPushed(app, event)
            %app.Oeste=1;    
            app.latitud=[app.v11 app.v12 app.v13];
            app.longitud=[app.v1 app.v2 app.v3];
            dms=[app.latitud;app.longitud];
            angleInDegrees=dms2degrees(dms);
            Latitud=angleInDegrees(1,1);
            Longitud=angleInDegrees(2,1);
            app.Oeste=1;
            %app.Longitud1 = Longitud;
            if app.Oeste == 1
                app.Longitud1 = Longitud * -1;
            end
            app.OButton.Enable='off';
        end

        % Button pushed function: ReiniciarButton
        function ReiniciarButtonPushed(app, event)
            x = 0; % Valor inicial para x
            y = 0; % Valor inicial para y
            z = 0; % Valor inicial para z
            latitud=0;
            longitud=0;
            altura=0;
            Latitud=0;
            Longitud=0;
            Latitud1=0;
            Longitud1=0;
            v1=0;
            v2=0;
            v3=0;
            v11=0;
            v12=0;
            v13=0;
            Sur=0;
            Oeste=0;
            app.v11 = 0;
            app.v12 = 0;
            app.v13 = 0;
            app.v1 = 0;
            app.v2 = 0;
            app.v3 = 0;
            app.latitud = [];
            app.longitud = [];
            app.Latitud1 = 0;
            app.Longitud1 = 0;
            app.Sur = 0;
            app.Oeste = 0;
            app.altura = 0;
            app.xEditField_4.Value = '';
            app.yEditField_4.Value = '';
            app.zEditField_4.Value = '';
            la1=0;
            fi1=0
            h=0;

            % Reactivar los botones S y O
            app.SButton.Enable = 'on';
            app.OButton.Enable = 'on';
            
        end

        % Value changed function: IngreseelvalordelaalturaEditField
        function IngreseelvalordelaalturaEditFieldValueChanged2(app, event)
           
            app.altura=app.IngreseelvalordelaalturaEditField.Value;
        end

        % Value changed function: GradosEditField_3
        function GradosEditField_3ValueChanged(app, event)
            app.w1=app.GradosEditField_3.Value;
        end

        % Value changed function: MinutosEditField_3
        function MinutosEditField_3ValueChanged(app, event)
            app.w2 = app.MinutosEditField_3.Value;
            
        end

        % Value changed function: SegundosEditField_2
        function SegundosEditField_2ValueChanged(app, event)
            app.w3 = app.SegundosEditField_2.Value;
            
        end

        % Value changed function: GradosEditField_4
        function GradosEditField_4ValueChanged(app, event)
            app.w11 = app.GradosEditField_4.Value;
            
        end

        % Value changed function: MinutosEditField_4
        function MinutosEditField_4ValueChanged(app, event)
            app.w12 = app.MinutosEditField_4.Value;
            
        end

        % Value changed function: SegundosEditField_3
        function SegundosEditField_3ValueChanged(app, event)
            app.w13 = app.SegundosEditField_3.Value;
            
        end

        % Button pushed function: CalcularButton_10
        function CalcularButton_10Pushed(app, event)
            % Información Preliminar.
            % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
            format long
            a= 6378137.0;
            f= 1/298.257222101;
            % wgs 84
            a1= 6378137.0;
            f1= 1/298.257223563;
            % HAYFORD
            a2= 6378388;
            f2=1/297;
            % ********** EFECTUACION DE LOS CALCULOS**********************
            
            format long
            e1= 2*(f)-(f)^2;
            app.d_latitud=[app.w11, app.w12, app.w13]
            app.d_azimut=[app.w1,app.w2,app.w3]
            dms=[app.d_azimut;app.d_latitud]
            angleInDegrees=dms2degrees(dms);
            d1_latitud=angleInDegrees(1,1);
            d1_azimut=angleInDegrees(2,1);
            
            N=((a)/(sqrt(1-e1*sind(d1_latitud)^2)));
            %Calculamos M
            M=a*(1-e1)/(1-e1*sind(d1_latitud)^2).^(3/2);
            Raz= ((M*N)/((N*cosd(d1_azimut)^2)+M*sind(d1_azimut)^2));
            %Mostramos los valores
             Raz_str = sprintf('%.5f', Raz);
           
                       
            app.RazEditField.Value = Raz_str;


        end

        % Value changed function: AzimutEditField
        function AzimutEditFieldValueChanged(app, event)
            app.d_azimut = app.AzimutEditField.Value;
            
        end

        % Value changed function: LatitudEditField_2
        function LatitudEditField_2ValueChanged(app, event)
            app.d_latitud= app.LatitudEditField_2.Value;
            
        end

        % Button pushed function: CalcularButton_11
        function CalcularButton_11Pushed(app, event)
            % Información Preliminar.
            % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
            format long
            a= 6378137.0;
            f= 1/298.257222101;
            % wgs 84
            a1= 6378137.0;
            f1= 1/298.257223563;
            % HAYFORD
            a2= 6378388;
            f2=1/297;
            % ********** EFECTUACION DE LOS CALCULOS**********************
            
            format long
            e1= 2*(f1)-(f1)^2;
            app.d_latitud=[app.w11, app.w12, app.w13]
            app.d_azimut=[app.w1,app.w2,app.w3]
            dms=[app.d_azimut;app.d_latitud]
            angleInDegrees=dms2degrees(dms);
            d1_latitud=angleInDegrees(1,1);
            d1_azimut=angleInDegrees(2,1);
            
            N=((a1)/(sqrt(1-e1*sind(d1_latitud)^2)));
            %Calculamos M
            M=a1*(1-e1)/(1-e1*sind(d1_latitud)^2).^(3/2);
            Raz= ((M*N)/((N*cosd(d1_azimut)^2)+M*sind(d1_azimut)^2));
            %Mostramos los valores
             Raz_str = sprintf('%.5f', Raz);
           
                       
            app.RazEditField.Value = Raz_str;
            
        end

        % Button pushed function: CalcularButton_12
        function CalcularButton_12Pushed(app, event)
            % Información Preliminar.
            % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
            format long
            a= 6378137.0;
            f= 1/298.257222101;
            % wgs 84
            a1= 6378137.0;
            f1= 1/298.257223563;
            % HAYFORD
            a2= 6378388;
            f2=1/297;
            % ********** EFECTUACION DE LOS CALCULOS**********************
            
            format long
            e1= 2*(f2)-(f2)^2;
            app.d_latitud=[app.w11, app.w12, app.w13]
            app.d_azimut=[app.w1,app.w2,app.w3]
            dms=[app.d_azimut;app.d_latitud]
            angleInDegrees=dms2degrees(dms);
            d1_latitud=angleInDegrees(1,1);
            d1_azimut=angleInDegrees(2,1);
            
            N=((a2)/(sqrt(1-e1*sind(d1_latitud)^2)));
            %Calculamos M
            M=a2*(1-e1)/(1-e1*sind(d1_latitud)^2).^(3/2);
            Raz= ((M*N)/((N*cosd(d1_azimut)^2)+M*sind(d1_azimut)^2));
            %Mostramos los valores
             Raz_str = sprintf('%.5f', Raz);
           
                       
            app.RazEditField.Value = Raz_str;
            
        end

        % Button pushed function: CalcularButton_13
        function CalcularButton_13Pushed(app, event)
            % Información Preliminar.
            % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
            format long
            a= 6378137.0;
            f= 1/298.257222101;
            % wgs 84
            a1= 6378137.0;
            f1= 1/298.257223563;
            % HAYFORD
            a2= 6378388;
            f2=1/297;
            % ********** EFECTUACION DE LOS CALCULOS**********************
            
            format long
            e1= 2*(f)-(f)^2;
            app.d_latitud=[app.w11, app.w12, app.w13]
            app.d_azimut=[app.w1,app.w2,app.w3]
            dms=[app.d_azimut;app.d_latitud]
            angleInDegrees=dms2degrees(dms);
            d1_latitud=angleInDegrees(1,1);
            d1_azimut=angleInDegrees(2,1);
            
            N=((a)/(sqrt(1-e1*sind(d1_latitud)^2)));
            %Calculamos M
            M=a*(1-e1)/(1-e1*sind(d1_latitud)^2).^(3/2);
            Rm=sqrt(M*N);
            %Mostramos los valores
             Rm_str = sprintf('%.5f', Rm);
             app.RmEditField.Value = Rm_str;
        end

        % Button pushed function: CalcularButton_14
        function CalcularButton_14Pushed(app, event)
             % Información Preliminar.
            % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
            format long
            a= 6378137.0;
            f= 1/298.257222101;
            % wgs 84
            a1= 6378137.0;
            f1= 1/298.257223563;
            % HAYFORD
            a2= 6378388;
            f2=1/297;
            % ********** EFECTUACION DE LOS CALCULOS**********************
            
            format long
            e1= 2*(f1)-(f1)^2;
            app.d_latitud=[app.w11, app.w12, app.w13]
            app.d_azimut=[app.w1,app.w2,app.w3]
            dms=[app.d_azimut;app.d_latitud]
            angleInDegrees=dms2degrees(dms);
            d1_latitud=angleInDegrees(1,1);
            d1_azimut=angleInDegrees(2,1);
            
            N=((a1)/(sqrt(1-e1*sind(d1_latitud)^2)));
            %Calculamos M
            M=a1*(1-e1)/(1-e1*sind(d1_latitud)^2).^(3/2);
            Rm=sqrt(M*N);
            %Mostramos los valores
             Rm_str = sprintf('%.5f', Rm);
           
                       
            app.RmEditField.Value = Rm_str;
        end

        % Button pushed function: CalcularButton_15
        function CalcularButton_15Pushed(app, event)
             % Información Preliminar.
            % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
            format long
            a= 6378137.0;
            f= 1/298.257222101;
            % wgs 84
            a1= 6378137.0;
            f1= 1/298.257223563;
            % HAYFORD
            a2= 6378388;
            f2=1/297;
            % ********** EFECTUACION DE LOS CALCULOS**********************
            
            format long
            e1= 2*(f2)-(f2)^2;
            app.d_latitud=[app.w11, app.w12, app.w13]
            app.d_azimut=[app.w1,app.w2,app.w3]
            dms=[app.d_azimut;app.d_latitud]
            angleInDegrees=dms2degrees(dms);
            d1_latitud=angleInDegrees(1,1);
            d1_azimut=angleInDegrees(2,1);
            
            N=((a2)/(sqrt(1-e1*sind(d1_latitud)^2)));
            %Calculamos M
            M=a2*(1-e1)/(1-e1*sind(d1_latitud)^2).^(3/2);
            Rm=sqrt(M*N);
            %Mostramos los valores
             Rm_str = sprintf('%.5f', Rm);
           
                       
            app.RmEditField.Value = Rm_str;
        end

        % Button pushed function: CalcularButton_16
        function CalcularButton_16Pushed(app, event)
             % Información Preliminar.
            % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
            format long
            a= 6378137.0;
            f= 1/298.257222101;
            e1= 2*(f)-(f)^2;
            % wgs 84
            a1= 6378137.0;
            f1= 1/298.257223563;
            % HAYFORD
            a2= 6378388;
            f2=1/297;
            % ********** EFECTUACION DE LOS CALCULOS**********************
            format long
            N=((a*cosd(app.Latitud1))/(sqrt(1-e1*sind(app.Latitud1)^2)));
            Rp=N;
            %Mostramos los valores
             Rp_str = sprintf('%.5f', Rp);
             app.RpEditField.Value = Rp_str;
        
        end

        % Button pushed function: CalcularButton_17
        function CalcularButton_17Pushed(app, event)
             % Información Preliminar.
            % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
            format long
            a= 6378137.0;
            f= 1/298.257222101;
            
            % wgs 84
            a1= 6378137.0;
            f1= 1/298.257223563;
            e1= 2*(f1)-(f1)^2;
            % HAYFORD
            a2= 6378388;
            f2=1/297;
            % ********** EFECTUACION DE LOS CALCULOS**********************
            format long
                       
            N=((a1*cosd(app.Latitud1))/(sqrt(1-e1*sind(app.Latitud1)^2)));
            Rp=N;
            %Mostramos los valores
             Rp_str = sprintf('%.5f', Rp);
             app.RpEditField.Value = Rp_str;
        end

        % Button pushed function: CalcularButton_18
        function CalcularButton_18Pushed(app, event)
             % Información Preliminar.
            % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
            format long
            a= 6378137.0;
            f= 1/298.257222101;
            % wgs 84
            a1= 6378137.0;
            f1= 1/298.257223563;
            % HAYFORD
            a2= 6378388;
            f2=1/297;
            e1= 2*(f2)-(f2)^2;
            % ********** EFECTUACION DE LOS CALCULOS**********************
            
            format long
            N=((a2*cosd(app.Latitud1))/(sqrt(1-e1*sind(app.Latitud1)^2)));
            Rp=N;
            %Mostramos los valores
            Rp_str = sprintf('%.5f', Rp);
            app.RpEditField.Value = Rp_str;
        end

        % Button pushed function: SButton_2
        function SButton_2Pushed(app, event)
            app.d_latitud=[app.w11, app.w12, app.w13]
            app.d_azimut=[app.w1,app.w2,app.w3]
            dms=[app.d_azimut;app.d_latitud]
            angleInDegrees=dms2degrees(dms);
            d1_latitud=angleInDegrees(1,1);
            d1_azimut=angleInDegrees(2,1);
 
            app.Sur=1;
            %app.Latitud1 = Latitud;
            if app.Sur == 1
                app.Latitud1 = d1_latitud * -1;
            end
           % Desactivar el botón S
           app.SButton.Enable = 'off';
        end

        % Button pushed function: ReiniciarButton_2
        function ReiniciarButton_2Pushed(app, event)
            app.d_latitud=[];
            app.d_azimut=[];
            d1_latitud=0;
            d1_azimut=0;
            
            % Reactivar los botones S
            app.SButton.Enable = 'on';
            app.RazEditField.Value = '';
            app.RmEditField.Value='';
            app.RpEditField.Value='';
            
        end

        % Button pushed function: SButton_3
        function SButton_3Pushed(app, event)
             %app.Sur=1;
            app.latitud=[app.m1 app.m2 app.m3];
            app.longitud=[app.m11 app.m12 app.m13];
            dms=[app.latitud;app.longitud];
            angleInDegrees=dms2degrees(dms);
            Latitud=angleInDegrees(1,1);
            Longitud=angleInDegrees(2,1);
            app.Sur=1;
            %app.Latitud1 = Latitud;
            if app.Sur == 1
                app.Latitud1 = Latitud * -1;
            end
           % Desactivar el botón S
           app.SButton.Enable = 'off';
        end

        % Button pushed function: OButton_2
        function OButton_2Pushed(app, event)
          %app.Oeste=1;    
            app.latitud=[app.m1 app.m2 app.m3]; 
            app.longitud=[app.m11 app.m12 app.m13];
            app.longitudb=[app.m11b app.m12b app.m13b]
            dms=[app.latitud;app.longitud;app.longitudb];
            angleInDegrees=dms2degrees(dms);
            Latitud=angleInDegrees(1,1);
            Longitud=angleInDegrees(2,1);
            Longitudb=angleInDegrees(3,1)
            app.Oeste=1;
            %app.Longitud1 = Longitud;
            if app.Oeste == 1
                app.Longitud1 = Longitud * -1;
                app.Longitudb= Longitudb*-1
            end
            app.OButton.Enable='off';   
        end

        % Callback function
        function SButton_4Pushed(app, event)
            app.latitud=[app.m1 app.m2 app.m3];
            app.latitudb=[app.m1a app.m2a app.m3a];
            dms=[app.latitud;app.latitudb];
            angleInDegrees=dms2degrees(dms);
            Latitud=angleInDegrees(1,1); 
            Latitudb=angleInDegrees(2,1);
            app.Sur=1;
            %app.Latitud1 = Latitud;
            if app.Sur == 1
                app.Latitud1 = Latitud * -1;
                app.Latitudb= Latitud * -1;
            end
           % Desactivar el botón S
           app.SButton.Enable = 'off';
        end

        % Value changed function: GradosEditField_5
        function GradosEditField_5ValueChanged(app, event)
            app.m1 = app.GradosEditField_5.Value;
            
        end

        % Value changed function: MinutosEditField_5
        function MinutosEditField_5ValueChanged(app, event)
            app.m2 = app.MinutosEditField_5.Value;
            
        end

        % Value changed function: SegundosEditField_4
        function SegundosEditField_4ValueChanged(app, event)
            app.m3 = app.SegundosEditField_4.Value;
            
        end

        % Value changed function: GradosEditField_6
        function GradosEditField_6ValueChanged(app, event)
            app.m11 = app.GradosEditField_6.Value;
            
        end

        % Value changed function: MinutosEditField_6
        function MinutosEditField_6ValueChanged(app, event)
            app.m12 = app.MinutosEditField_6.Value;
            
        end

        % Value changed function: SegundosEditField_7
        function SegundosEditField_7ValueChanged(app, event)
            app.m13 = app.SegundosEditField_7.Value;
            
        end

        % Value changed function: LatitudEditField_3
        function LatitudEditField_3ValueChanged(app, event)
            app.Latitud = app.LatitudEditField_3.Value;
            
        end

        % Value changed function: LongitudEditField_2
        function LongitudEditField_2ValueChanged(app, event)
            app.Longitud = app.LongitudEditField_2.Value;
            
        end

        % Button pushed function: CalcularButton_30
        function CalcularButton_30Pushed(app, event)
              % Información Preliminar.
            % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
            format long
            a= 6378137.0;
            f= 1/298.257222101;
            % wgs 84
            a1= 6378137.0;
            f1= 1/298.257223563;
            % HAYFORD
            a2= 6378388;
            f2=1/297;
            % ********** EFECTUACION DE LOS CALCULOS**********************
            
            format long
            e1= 2*(f)-(f)^2
            Rp=((a*cosd(app.Latitud1))/(sqrt(1-e1*sind(app.Latitud1)^2)));
            N=((a)/(sqrt(1-e1*sind(app.Latitud1)^2)));
            
            sp=(N*cosd(app.Latitud1))*deg2rad(app.Longitud1-app.Longitudb)
            
            %Mostramos los valores
             Sp_str = sprintf('%.5f', Rp);
          
                       
            app.SpEditField.Value = Sp_str;
        end

        % Value changed function: GradosEditField_9
        function GradosEditField_9ValueChanged(app, event)
            app.m11b = app.GradosEditField_9.Value;
            
        end

        % Value changed function: MinutosEditField_9
        function MinutosEditField_9ValueChanged(app, event)
            app.m12b = app.MinutosEditField_9.Value;
            
        end

        % Value changed function: SegundosEditField_8
        function SegundosEditField_8ValueChanged(app, event)
            app.m13b = app.SegundosEditField_8.Value;
            
        end

        % Callback function
        function GradosEditField_7ValueChanged(app, event)
            app.m1a = app.GradosEditField_7.Value;
            
        end

        % Callback function
        function MinutosEditField_7ValueChanged(app, event)
            app.m2a = app.MinutosEditField_7.Value;
            
        end

        % Callback function
        function SegundosEditField_6ValueChanged(app, event)
           app.m3a = app.SegundosEditField_6.Value;
            
        end

        % Value changed function: LongitudBEditField
        function LongitudBEditFieldValueChanged(app, event)
            app.Longitudb = app.LongitudBEditField.Value;
            
        end

        % Callback function
        function LatitudBEditFieldValueChanged(app, event)
            app.Latitudb = app.LatitudBEditField.Value;
            
        end

        % Button pushed function: CalcularButton_31
        function CalcularButton_31Pushed(app, event)
             % Información Preliminar.
            % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
            format long
            a= 6378137.0;
            f= 1/298.257222101;
            % wgs 84
            a1= 6378137.0;
            f1= 1/298.257223563;
            % HAYFORD
            a2= 6378388;
            f2=1/297;
            % ********** EFECTUACION DE LOS CALCULOS**********************
            
            format long
            e1= 2*(f1)-(f1)^2
            Rp=((a1*cosd(app.Latitud1))/(sqrt(1-e1*sind(app.Latitud1)^2)));
            N=((a1)/(sqrt(1-e1*sind(app.Latitud1)^2)));
            
            sp=(N*cosd(app.Latitud1))*deg2rad(app.Longitud1-app.Longitudb)
            
            %Mostramos los valores
             Sp_str = sprintf('%.5f', Rp);
          
                       
            app.SpEditField.Value = Sp_str;
        end

        % Button pushed function: CalcularButton_32
        function CalcularButton_32Pushed(app, event)
             % Información Preliminar.
            % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
            format long
            a= 6378137.0;
            f= 1/298.257222101;
            % wgs 84
            a1= 6378137.0;
            f1= 1/298.257223563;
            % HAYFORD
            a2= 6378388;
            f2=1/297;
            % ********** EFECTUACION DE LOS CALCULOS**********************
            
            format long
            e1= 2*(f2)-(f2)^2
            Rp=((a2*cosd(app.Latitud1))/(sqrt(1-e1*sind(app.Latitud1)^2)));
            N=((a2)/(sqrt(1-e1*sind(app.Latitud1)^2)));
            
            sp=(N*cosd(app.Latitud1))*deg2rad(app.Longitud1-app.Longitudb)
            
            %Mostramos los valores
             Sp_str = sprintf('%.5f', Rp);
          
                       
            app.SpEditField.Value = Sp_str;
        end

        % Callback function
        function CalcularButton_19Pushed(app, event)
              % Información Preliminar.
            % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
            format long
            a= 6378137.0;
            f= 1/298.257222101;
            % wgs 84
            a1= 6378137.0;
            f1= 1/298.257223563;
            % HAYFORD
            a2= 6378388;
            f2=1/297;
            % ********** EFECTUACION DE LOS CALCULOS**********************
            
            format long
            e1= 2*(f)-(f)^2
            M=a2*(1-e1)/(1-e1*sin(app.Latitud1 - app.Latitudb)^2).^(3/2);
            
            Sm=(M)*deg2rad(app.Latitud1-app.Latitudb)
            
            %Mostramos los valores
             Sm_str = sprintf('%.5f', Sm);
          
                       
            app.SmEditField.Value = Sm_str;
        end

        % Button pushed function: ReiniciarButton_3
        function ReiniciarButton_3Pushed(app, event)
            m1=0;
            m2=0;
            m3=0;
            m1a=0;
            m2a=0;
            m3a=0;
            m11=0;
            m12=0;
            m13=0;
            m11b=0;
            m12b=0;
            m13b=0;
            latitud=[];
            latitudb=[];
            longitud=[];
            longitudb=[];
            app.SpEditField.Value='';
            
        end

        % Callback function
        function DistanciasEditFieldValueChanged(app, event)
            app.distancia = app.DistanciasEditField.Value;
            
            
        end

        % Callback function
        function LatitudEditField_4ValueChanged(app, event)
            app.Latitud = app.LaEditField.Value;
            
        end

        % Callback function
        function LongitudEditField_3ValueChanged(app, event)
            app.Longitud = app.LoEditField.Value;
            
        end

        % Callback function
        function CalcularButton_19Pushed2(app, event)
             % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
            format long
            a= 6378137.0;
            f= 1/298.257222101;
            % wgs 84
            a1= 6378137.0;
            f1= 1/298.257223563;
            % HAYFORD
            a2= 6378388;
            f2=1/297;
            % ********** EFECTUACION DE LOS CALCULOS**********************
            
            format long
            e1= 2*(f2)-(f2)^2;
            N=((a)/(sqrt(1-e1*sind(app.Latitud)^2)));
            
            M=a*(1-e1)/(1-e1*sind(app.Latitud)^2).^(3/2);
            %fprintf('El valor de M es: %.5f''\n',M)
            % APLICAMOS EL PROBLEMA DIRECTO. latitud,longitd,azimut,s.
            
            Latitud= app.Latitud+(cosd(app.Azimut)/M)*app.distancia +(-((sind(app.Azimut)^2)/(M*N))*tand(app.Latitud))*(app.distancia^2)/2
            %fprintf('El valor de la latitud es: %.5f''\n',Fi)
            Longitud=app.Longitud+((sind(app.Azimut))/(N*cosd(app.Latitud)))*app.distancia +((tand(app.Latitud)*secd(app.Latitud)*sind(2*app.Azimut))/(M*N))*(app.distancia^2)/2
            %fprintf('El valor de longitud es: %.5f''\n',Landa)
            if app.Azimut<180
                Az= app.Azimut+180+((sind(app.Azimut)*tand(app.Latitud))/(N))*app.distancia +((sind(app.Azimut)*cosd(app.Azimut)*(1+2*tand(app.Latitud)^2))/(M*N))*(app.distancia^2)/2
                
            elseif app.Azimut>180
                Az= app.Azimut+180-((sind(app.Azimut)*tand(app.Latitud))/(N))*app.distancia +((sind(app.Azimut)*cosd(app.Azimut)*(1+2*tand(app.Latitud)^2))/(M*N))*(app.distancia^2)/2
                
            else
                disp('No existe valor alguno')
            end
            Latitud_str = sprintf('%.5f', Latitud);
            Longitud_str = sprintf('%.5f', Longitud);
            Az_str = sprintf('%.5f', Az);
            app.LatitudEditField.Value = str2double(Latitud_str)
            app.LongitudEditField.Value = str2double(Longitud_str)
            app.AzimutEditField.Value = str2double(Az_str)
  

        end

        % Callback function
        function AzimutEditField_2ValueChanged(app, event)
            app.Azimut = app.AzEditField.Value;
            
        end

        % Callback function
        function SButton_4Pushed2(app, event)
            app.latitud=[app.m1 app.m2 app.m3];
            app.longitud=[app.m11 app.m12 app.m13]
            app.azimut=[app.m1a app.m2a app.m3a]
            dms=[app.latitud;app.longitud;app.azimut];
            angleInDegrees=dms2degrees(dms);
            Latitud=angleInDegrees(1,1); 
            Longitud=angleInDegrees(2,1);
            Azimut=angleInDegrees(3,1);
            app.Sur=1;
            %app.Latitud1 = Latitud;
            if app.Sur == 1
                app.Latitud1 = Latitud * -1;
            end
           % Desactivar el botón S
           app.SButton.Enable = 'off';
        end

        % Callback function
        function GradosEditField_10ValueChanged(app, event)
            app.m1 = app.GradosEditField_10.Value;
            
        end

        % Callback function
        function MinutosEditField_10ValueChanged(app, event)
            app.m2 = app.MinutosEditField_10.Value;
            
        end

        % Callback function
        function SegundosEditField_10ValueChanged(app, event)
            app.m3 = app.SegundosEditField_10.Value;
            
        end

        % Callback function
        function CalcularButton_19Pushed3(app, event)
            % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
            a= 6378137.0;
            f= 1/298.257222101;
            % wgs 84
            a1= 6378137.0;
            f1= 1/298.257223563;
            % HAYFORD
            a2= 6378388;
            f2=1/297;
            %%%%%%%% APLICACIÓN DEL PROBLEA PRINCIPAL DIRECTO DE LA GEODESIA%%%%%%%%%%%
            e1= 2*(f)-(f)^2
           N=((a)/(sqrt(1-e1*sind(app.Latitud1)^2)));
           M=a*(1-e1)/(1-e1*sind(app.Latitud1)^2).^(3/2);
           
           Fi= app.Latitud1+(cosd(app.Azimut)/M)*app.distancia +(-((sind(app.Azimut)^2)/(M*N))*tand(app.Latitud1))*(app.distancia^2)/2;
           
           Landa=app.Longitud1+((sind(app.Azimut))/(N*cosd(app.Latitud1)))*app.distancia +((tand(app.Latitud1)*secd(app.Latitud1)*sind(2*app.Azimut))/(M*N))*(app.distancia^2)/2;
          
      if app.Azimut<180
          app.azimut= app.Azimut+180+((sind(app.Azimut)*tand(app.Latitud1))/(N))*app.distancia +((sind(app.Azimut)*cosd(app.Azimut)*(1+2*tand(app.Latitud1)^2))/(M*N))*(app.distancia^2)/2;
          
      elseif Az>180
          app.azimut= app.Azimut-180+((sind(app.Azimut)*tand(app.Latitud1))/(N))*app.distancia +((sind(app.Azimut)*cosd(app.Azimut)*(1+2*tand(app.Latitud1)^2))/(M*N))*(app.distancia^2)/2;
          
      else
            
      end
            La_str = sprintf('%.5f', Fi)
            Lo_str = sprintf('%.5f', Landa)
            Az_str = sprintf('%.5f', app.azimut)
           
            app.La.Value = La_str
            app.Lo.Value = Lo_str
            app.Az.Value = Az_str
            
        end

        % Callback function
        function GradosEditField_11ValueChanged(app, event)
            app.m11 = app.GradosEditField_11.Value;
            
        end

        % Callback function
        function MinutosEditField_11ValueChanged(app, event)
            app.m12 = app.MinutosEditField_11.Value; %LONGITUD
            
        end

        % Callback function
        function SegundosEditField_11ValueChanged(app, event)
            app.m13 = app.SegundosEditField_11.Value;
            
        end

        % Callback function
        function GradosEditField_12ValueChanged(app, event)
            app.m1a = app.GradosEditField_12.Value;
            
        end

        % Callback function
        function MinutosEditField_12ValueChanged(app, event)
            app.m2a = app.MinutosEditField_12.Value;
            
        end

        % Callback function
        function SegundosEditField_12ValueChanged(app, event)
            app.m3a = app.SegundosEditField_12.Value;
            
        end

        % Callback function
        function DistanciaEditFieldValueChanged(app, event)
            app.distancia = app.DistanciaEditField.Value;
            
        end

        % Callback function
        function OButton_3Pushed(app, event)
            app.latitud=[app.m1 app.m2 app.m3];
            app.longitud=[app.m11 app.m12 app.m13]
            app.azimut=[app.m1a app.m2a app.m3a]
            dms=[app.latitud;app.longitud;app.azimut];
            angleInDegrees=dms2degrees(dms);
            Latitud=angleInDegrees(1,1); 
            Longitud=angleInDegrees(2,1);
            Azimut=angleInDegrees(3,1);
            app.Sur=1;
            %app.Latitud1 = Latitud;
            if app.Sur == 1
                app.Longitud1 = Longitud * -1;
            end
        end

        % Value changed function: GradosEditField_10
        function GradosEditField_10ValueChanged2(app, event)
            app.Grados = app.GradosEditField_10.Value; %CPD
            
        end

        % Value changed function: MinutosEditField_10
        function MinutosEditField_10ValueChanged2(app, event)
            app.Minutos = app.MinutosEditField_10.Value; %CPD
            
        end

        % Value changed function: SegundosEditField_10
        function SegundosEditField_10ValueChanged2(app, event)
            app.Segundos = app.SegundosEditField_10.Value; %CPD
            
        end

        % Value changed function: GradosEditField_11
        function GradosEditField_11ValueChanged2(app, event)
            app.Grados1 = app.GradosEditField_11.Value;
            
        end

        % Value changed function: MinutosEditField_11
        function MinutosEditField_11ValueChanged2(app, event)
            app.Minutos1 = app.MinutosEditField_11.Value;
            
        end

        % Value changed function: SegundosEditField_11
        function SegundosEditField_11ValueChanged2(app, event)
            app.Segundos1 = app.SegundosEditField_11.Value;
            
        end

        % Value changed function: GradosEditField_12
        function GradosEditField_12ValueChanged2(app, event)
            app.Grados2 = app.GradosEditField_12.Value;
            
        end

        % Value changed function: MinutosEditField_12
        function MinutosEditField_12ValueChanged2(app, event)
            app.Minutos2 = app.MinutosEditField_12.Value;
            
        end

        % Value changed function: SegundosEditField_12
        function SegundosEditField_12ValueChanged2(app, event)
            app.Segundos2 = app.SegundosEditField_12.Value;
            
        end

        % Button pushed function: SButton_4
        function SButton_4Pushed3(app, event)
              %app.Sur=1;
            app.latitud=[app.Grados app.Minutos app.Segundos];
            app.longitud=[app.Grados1 app.Minutos1 app.Segundos1];
            app.Azimut=[app.Grados2 app.Minutos2 app.Segundos2];
            dms=[app.latitud;app.longitud;app.Azimut];
            angleInDegrees=dms2degrees(dms);
            Latitud=angleInDegrees(1,1);
            Longitud=angleInDegrees(2,1);
            azimut=angleInDegrees(3,1);
            app.Azimut=azimut;
            app.Sur=1;
            %app.Latitud1 = Latitud;
            if app.Sur == 1
                app.Latitud1 = Latitud * -1;
            end
           % Desactivar el botón S
           app.SButton.Enable = 'off';
        end

        % Button pushed function: OButton_3
        function OButton_3Pushed2(app, event)
            app.latitud=[app.Grados app.Minutos app.Segundos];
            app.longitud=[app.Grados1 app.Minutos1 app.Segundos1];
            app.Azimut=[app.Grados2 app.Minutos2 app.Segundos2];
            dms=[app.latitud;app.longitud;app.Azimut];
            angleInDegrees=dms2degrees(dms);
            Latitud=angleInDegrees(1,1);
            Longitud=angleInDegrees(2,1);
            azimut=angleInDegrees(3,1);
            app.Azimut=azimut;
            app.Oeste=1;
            if app.Oeste == 1
                app.Longitud1 = Longitud * -1;
            end
            % Desactivar el botón S
            app.OButton.Enable = 'off';
        end

        % Value changed function: AzimutEditField_3
        function AzimutEditField_3ValueChanged(app, event)
            app.Azimut = app.AzimutEditField_3.Value; %CPDG
            
        end

        % Button pushed function: GRS80Button
        function GRS80ButtonPushed(app, event)
            % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
            a= 6378137.0;
            f= 1/298.257222101;
            % wgs 84
            a1= 6378137.0;
            f1= 1/298.257223563;
            % HAYFORD
            a2= 6378388;
            f2=1/297;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.latitud=[app.Grados app.Minutos app.Segundos];
            app.longitud=[app.Grados1 app.Minutos1 app.Segundos1];
            app.Azimut=[app.Grados2 app.Minutos2 app.Segundos2];
            dms=[app.latitud;app.longitud;app.Azimut];
            angleInDegrees=dms2degrees(dms);
            Latitud=angleInDegrees(1,1);
            Longitud=angleInDegrees(2,1);
            azimut=angleInDegrees(3,1);
           
            app.Azimut=azimut
            e1= 2*(f)-(f)^2; %Cálculo de la Primera Excentricidad.
            b= a*sqrt(1-e1);
            e2= ((a/b)^2)-1;
        
            N=((a)/(sqrt(1-e1*sind(app.Latitud1)^2)));
            
            M=a*(1-e1)/(1-e1*sind(app.Latitud1)^2).^(3/2);
           
            % APLICAMOS EL PROBLEMA DIRECTO.
            
            Fi= app.Latitud1+(cosd(app.Azimut)/M)*app.Distancia +(-((sind(app.Azimut)^2)/(M*N))*tand(app.Latitud1))*(app.Distancia^2)/2;
           
            Landa=app.Longitud1+((sind(app.Azimut))/(N*cosd(app.Latitud1)))*app.Distancia +((tand(app.Latitud1)*secd(app.Latitud1)*sind(2*app.Azimut))/(M*N))*(app.Distancia^2)/2;
         
            if app.Azimut < 180
                Az1= app.Azimut+180+((sind(app.Azimut)*tand(app.Latitud1))/(N))*app.Distancia +((sind(app.Azimut)*cosd(app.Azimut)*(1+2*tand(app.Latitud1)^2))/(M*N))*(app.Distancia^2)/2;
              
            elseif app.Azimut > 180
                Az1= app.Azimut-180+((sind(app.Azimut)*tand(app.Latitud1))/(N))*app.Distancia +((sind(app.Azimut)*cosd(app.Azimut)*(1+2*tand(app.Latitud1)^2))/(M*N))*(app.Distancia^2)/2;
                         
            end
            Fi_str = sprintf('%.5f', Fi);
            Landa_str = sprintf('%.5f', Landa);
            Az1_str = sprintf('%.5f', Az1);
            
            app.LatitudEditField_4.Value=Fi_str;
            app.LongitudEditField_3.Value=Landa_str;
            app.AzimutEditField_2.Value=Az1_str;
        end

        % Value changed function: DistanciaEditField
        function DistanciaEditFieldValueChanged2(app, event)
            app.Distancia = app.DistanciaEditField.Value;
            
        end

        % Value changed function: LatitudEditField_4
        function LatitudEditField_4ValueChanged2(app, event)
            %value = app.LatitudEditField_4.Value;
            %Nada por aquí...
        end

        % Value changed function: LongitudEditField_3
        function LongitudEditField_3ValueChanged2(app, event)
            %value = app.LongitudEditField_3.Value;
            %Nada por aquí...
        end

        % Value changed function: AzimutEditField_2
        function AzimutEditField_2ValueChanged2(app, event)
            %value = app.AzimutEditField_2.Value;
            %Nada por aquí...
        end

        % Callback function
        function WGS84ButtonPushed(app, event)
% NO VALIO EN ESTE BOTON...
        end

        % Button pushed function: WGS84Button
        function WGS84ButtonPushed2(app, event)
              % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
            a= 6378137.0;
            f= 1/298.257222101;
            % wgs 84
            a1= 6378137.0;
            f1= 1/298.257223563;
            % HAYFORD
            a2= 6378388;
            f2=1/297;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.latitud=[app.Grados app.Minutos app.Segundos];
            app.longitud=[app.Grados1 app.Minutos1 app.Segundos1];
            app.Azimut=[app.Grados2 app.Minutos2 app.Segundos2];
            dms=[app.latitud;app.longitud;app.Azimut];
            angleInDegrees=dms2degrees(dms);
            Latitud=angleInDegrees(1,1);
            Longitud=angleInDegrees(2,1);
            azimut=angleInDegrees(3,1);
           
            app.Azimut=azimut
            e1= 2*(f1)-(f1)^2; %Cálculo de la Primera Excentricidad.
            b= a1*sqrt(1-e1);
            e2= ((a1/b)^2)-1;
        
            N=((a1)/(sqrt(1-e1*sind(app.Latitud1)^2)));
            
            M=a1*(1-e1)/(1-e1*sind(app.Latitud1)^2).^(3/2);
           
            % APLICAMOS EL PROBLEMA DIRECTO.
            
            Fi= app.Latitud1+(cosd(app.Azimut)/M)*app.Distancia +(-((sind(app.Azimut)^2)/(M*N))*tand(app.Latitud1))*(app.Distancia^2)/2;
           
            Landa=app.Longitud1+((sind(app.Azimut))/(N*cosd(app.Latitud1)))*app.Distancia +((tand(app.Latitud1)*secd(app.Latitud1)*sind(2*app.Azimut))/(M*N))*(app.Distancia^2)/2;
         
            if app.Azimut < 180
                Az1= app.Azimut+180+((sind(app.Azimut)*tand(app.Latitud1))/(N))*app.Distancia +((sind(app.Azimut)*cosd(app.Azimut)*(1+2*tand(app.Latitud1)^2))/(M*N))*(app.Distancia^2)/2;
              
            elseif app.Azimut > 180
                Az1= app.Azimut-180+((sind(app.Azimut)*tand(app.Latitud1))/(N))*app.Distancia +((sind(app.Azimut)*cosd(app.Azimut)*(1+2*tand(app.Latitud1)^2))/(M*N))*(app.Distancia^2)/2;
                         
            end
            Fi_str = sprintf('%.5f', Fi);
            Landa_str = sprintf('%.5f', Landa);
            Az1_str = sprintf('%.5f', Az1);
            
            app.LatitudEditField_4.Value=Fi_str;
            app.LongitudEditField_3.Value=Landa_str;
            app.AzimutEditField_2.Value=Az1_str;
        end

        % Button pushed function: HAYFORDButton
        function HAYFORDButtonPushed(app, event)
              % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
            a= 6378137.0;
            f= 1/298.257222101;
            % wgs 84
            a1= 6378137.0;
            f1= 1/298.257223563;
            % HAYFORD
            a2= 6378388;
            f2=1/297;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.latitud=[app.Grados app.Minutos app.Segundos];
            app.longitud=[app.Grados1 app.Minutos1 app.Segundos1];
            app.Azimut=[app.Grados2 app.Minutos2 app.Segundos2];
            dms=[app.latitud;app.longitud;app.Azimut];
            angleInDegrees=dms2degrees(dms);
            Latitud=angleInDegrees(1,1);
            Longitud=angleInDegrees(2,1);
            azimut=angleInDegrees(3,1);
           
            app.Azimut=azimut
            e1= 2*(f2)-(f2)^2; %Cálculo de la Primera Excentricidad.
            b= a2*sqrt(1-e1);
            e2= ((a2/b)^2)-1;
        
            N=((a2)/(sqrt(1-e1*sind(app.Latitud1)^2)));
            
            M=a2*(1-e1)/(1-e1*sind(app.Latitud1)^2).^(3/2);
           
            % APLICAMOS EL PROBLEMA DIRECTO.
            
            Fi= app.Latitud1+(cosd(app.Azimut)/M)*app.Distancia +(-((sind(app.Azimut)^2)/(M*N))*tand(app.Latitud1))*(app.Distancia^2)/2;
           
            Landa=app.Longitud1+((sind(app.Azimut))/(N*cosd(app.Latitud1)))*app.Distancia +((tand(app.Latitud1)*secd(app.Latitud1)*sind(2*app.Azimut))/(M*N))*(app.Distancia^2)/2;
         
            if app.Azimut < 180
                Az1= app.Azimut+180+((sind(app.Azimut)*tand(app.Latitud1))/(N))*app.Distancia +((sind(app.Azimut)*cosd(app.Azimut)*(1+2*tand(app.Latitud1)^2))/(M*N))*(app.Distancia^2)/2;
              
            elseif app.Azimut > 180
                Az1= app.Azimut-180+((sind(app.Azimut)*tand(app.Latitud1))/(N))*app.Distancia +((sind(app.Azimut)*cosd(app.Azimut)*(1+2*tand(app.Latitud1)^2))/(M*N))*(app.Distancia^2)/2;
                         
            end
            Fi_str = sprintf('%.5f', Fi);
            Landa_str = sprintf('%.5f', Landa);
            Az1_str = sprintf('%.5f', Az1);
            
            app.LatitudEditField_4.Value=Fi_str;
            app.LongitudEditField_3.Value=Landa_str;
            app.AzimutEditField_2.Value=Az1_str;
        end

        % Callback function
        function LatiutudEditFieldValueChanged(app, event)
            %app.Latitud = app.LatiutudEditField.Value;
            
        end

        % Value changed function: LongitudEditField_4
        function LongitudEditField_4ValueChanged(app, event)
            app.Longitud = app.LongitudEditField_4.Value;
            
        end

        % Button pushed function: GRS80Button_2
        function GRS80Button_2Pushed(app, event)
              % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
    a= 6378137.0;
    f= 1/298.257222101;
    % wgs 84
    a1= 6378137.0;
    f1= 1/298.257223563;
    % HAYFORD
    a2= 6378388;
    f2=1/297;
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
             
    app.Azimut
    e1= 2*(f)-(f)^2; %Cálculo de la Primera Excentricidad.
    b= a*sqrt(1-e1);
    e2= ((a/b)^2)-1;

    N=((a)/(sqrt(1-e1*sind(app.Latitud)^2)));

    M=a*(1-e1)/(1-e1*sind(app.Latitud)^2).^(3/2);

    % APLICAMOS EL PROBLEMA DIRECTO.

    Fi= app.Latitud+(cosd(app.Azimut)/M)*app.Distancia +(-((sind(app.Azimut)^2)/(M*N))*tand(app.Latitud))*(app.Distancia^2)/2;

    Landa=app.Longitud+((sind(app.Azimut))/(N*cosd(app.Latitud)))*app.Distancia +((tand(app.Latitud)*secd(app.Latitud)*sind(2*app.Azimut))/(M*N))*(app.Distancia^2)/2;

    if app.Azimut < 180
        Az1= app.Azimut+180+((sind(app.Azimut)*tand(app.Latitud))/(N))*app.Distancia +((sind(app.Azimut)*cosd(app.Azimut)*(1+2*tand(app.Latitud)^2))/(M*N))*(app.Distancia^2)/2;

    elseif app.Azimut > 180
        Az1= app.Azimut-180+((sind(app.Azimut)*tand(app.Latitud))/(N))*app.Distancia +((sind(app.Azimut)*cosd(app.Azimut)*(1+2*tand(app.Latitud)^2))/(M*N))*(app.Distancia^2)/2;

    end

    Fi_str = sprintf('%.5f', Fi);
    Landa_str = sprintf('%.5f', Landa);
    Az1_str = sprintf('%.5f', Az1);

    app.LatitudEditField_5.Value = Fi_str;
    app.LongitudEditField_5.Value = Landa_str;
    app.AzimutEditField_4.Value = Az1_str;
        end

        % Value changed function: LatitudEditField_6
        function LatitudEditField_6ValueChanged(app, event)
            app.Latitud = app.LatitudEditField_6.Value;
            
        end

        % Value changed function: LatitudEditField_5
        function LatitudEditField_5ValueChanged(app, event)
           % NADA AQUÍ
        end

        % Value changed function: LongitudEditField_5
        function LongitudEditField_5ValueChanged(app, event)
           %NADA AQUI
        end

        % Value changed function: AzimutEditField_4
        function AzimutEditField_4ValueChanged(app, event)
            %NADA AQUI
            
        end

        % Button pushed function: WGS84Button_2
        function WGS84Button_2Pushed(app, event)
                        % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
    a= 6378137.0;
    f= 1/298.257222101;
    % wgs 84
    a1= 6378137.0;
    f1= 1/298.257223563;
    % HAYFORD
    a2= 6378388;
    f2=1/297;
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
             
    app.Azimut
    e1= 2*(f1)-(f1)^2; %Cálculo de la Primera Excentricidad.
    b= a1*sqrt(1-e1);
    e2= ((a1/b)^2)-1;

    N=((a1)/(sqrt(1-e1*sind(app.Latitud)^2)));

    M=a1*(1-e1)/(1-e1*sind(app.Latitud)^2).^(3/2);

    % APLICAMOS EL PROBLEMA DIRECTO.

    Fi= app.Latitud+(cosd(app.Azimut)/M)*app.Distancia +(-((sind(app.Azimut)^2)/(M*N))*tand(app.Latitud))*(app.Distancia^2)/2;

    Landa=app.Longitud+((sind(app.Azimut))/(N*cosd(app.Latitud)))*app.Distancia +((tand(app.Latitud)*secd(app.Latitud)*sind(2*app.Azimut))/(M*N))*(app.Distancia^2)/2;

    if app.Azimut < 180
        Az1= app.Azimut+180+((sind(app.Azimut)*tand(app.Latitud))/(N))*app.Distancia +((sind(app.Azimut)*cosd(app.Azimut)*(1+2*tand(app.Latitud)^2))/(M*N))*(app.Distancia^2)/2;

    elseif app.Azimut > 180
        Az1= app.Azimut-180+((sind(app.Azimut)*tand(app.Latitud))/(N))*app.Distancia +((sind(app.Azimut)*cosd(app.Azimut)*(1+2*tand(app.Latitud)^2))/(M*N))*(app.Distancia^2)/2;

    end

    Fi_str = sprintf('%.5f', Fi);
    Landa_str = sprintf('%.5f', Landa);
    Az1_str = sprintf('%.5f', Az1);

    app.LatitudEditField_5.Value = Fi_str;
    app.LongitudEditField_5.Value = Landa_str;
    app.AzimutEditField_4.Value = Az1_str;
        end

        % Button pushed function: HAYFORDButton_2
        function HAYFORDButton_2Pushed(app, event)
                        % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
    a= 6378137.0;
    f= 1/298.257222101;
    % wgs 84
    a1= 6378137.0;
    f1= 1/298.257223563;
    % HAYFORD
    a2= 6378388;
    f2=1/297;
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
             
    app.Azimut
    e1= 2*(f2)-(f2)^2; %Cálculo de la Primera Excentricidad.
    b= a2*sqrt(1-e1);
    e2= ((a2/b)^2)-1;

    N=((a2)/(sqrt(1-e1*sind(app.Latitud)^2)));

    M=a2*(1-e1)/(1-e1*sind(app.Latitud)^2).^(3/2);

    % APLICAMOS EL PROBLEMA DIRECTO.

    Fi= app.Latitud+(cosd(app.Azimut)/M)*app.Distancia +(-((sind(app.Azimut)^2)/(M*N))*tand(app.Latitud))*(app.Distancia^2)/2;

    Landa=app.Longitud+((sind(app.Azimut))/(N*cosd(app.Latitud)))*app.Distancia +((tand(app.Latitud)*secd(app.Latitud)*sind(2*app.Azimut))/(M*N))*(app.Distancia^2)/2;

    if app.Azimut < 180
        Az1= app.Azimut+180+((sind(app.Azimut)*tand(app.Latitud))/(N))*app.Distancia +((sind(app.Azimut)*cosd(app.Azimut)*(1+2*tand(app.Latitud)^2))/(M*N))*(app.Distancia^2)/2;

    elseif app.Azimut > 180
        Az1= app.Azimut-180+((sind(app.Azimut)*tand(app.Latitud))/(N))*app.Distancia +((sind(app.Azimut)*cosd(app.Azimut)*(1+2*tand(app.Latitud)^2))/(M*N))*(app.Distancia^2)/2;

    end

    Fi_str = sprintf('%.5f', Fi);
    Landa_str = sprintf('%.5f', Landa);
    Az1_str = sprintf('%.5f', Az1);

    app.LatitudEditField_5.Value = Fi_str;
    app.LongitudEditField_5.Value = Landa_str;
    app.AzimutEditField_4.Value = Az1_str;
        end

        % Button pushed function: ReiniciarButton_4
        function ReiniciarButton_4Pushed(app, event)
            app.latitud=0;
            app.longitud=0;
            app.altura=0;
            app.xEditField_3.Value = '';
            app.yEditField_3.Value = '';
            app.zEditField_3.Value = '';
        end

        % Button pushed function: ReiniciarButton_5
        function ReiniciarButton_5Pushed(app, event)
%             app.xEditField.Value = 0;
%             app.yEditField.value=0;
%             app.zEditField.Value=0;
            app.LatitudEditField.Value=0;
            app.LongitudEditField.Value=0;
            app.AlturaEditField.Value=0;
        end

        % Button pushed function: ReiniciarButton_6
        function ReiniciarButton_6Pushed(app, event)
            app.Grados=0;
            app.Minutos=0;
            app.Segundos=0;
            app.Grados1=0;
            app.Minutos1=0;
            app.Segundos1=0;
            app.Grados2=0;
            app.Minutos2=0;
            app.Segundos2=0;
            app.Distancia=0;
            app.LatitudEditField_4.Value='';
            app.LongitudEditField_3.Value='';
            app.AzimutEditField_2.Value='';
        end

        % Button pushed function: ReiniciarButton_7
        function ReiniciarButton_7Pushed(app, event)
            app.Latitud=0;
            app.Longitud=0;
            app.Azimut=0;
            app.Distancia=0;
            app.LatitudEditField_5.Value = '';
            app.LongitudEditField_5.Value = '';
            app.AzimutEditField_4.Value = '';
        end

        % Value changed function: LatitudEditField_7
        function LatitudEditField_7ValueChanged(app, event)
            app.Latitud = app.LatitudEditField_7.Value;
            
        end

        % Button pushed function: CalcularButton_19
        function CalcularButton_19Pushed4(app, event)
            % EN ESTE TIPO DE CALCULOS SE NECESITA SOLO PARA GRS80
            
            % DATOS PARA GRS 80
            
            gammae=978032.67715;
            gammap=983218.63685;
            m=0.00344978600308;
            a= 6378137.0;
            f= 1/298.257222101;
            e1= 2*(f)-(f)^2;
            b= a*sqrt(1-e1);
            e2= ((a/b)^2)-1;
            
            % EFECTUAMOS LOS CALCULOS
            
            k= ((b*gammap)/(a*gammae))-1;
                  
            gamma= (gammae*((1+k*sind(app.Latitud)^2)/(sqrt(1-e1*sind(app.Latitud)^2))));
            
            gamma_str = sprintf('%.5f', gamma);
                        
            app.GravedadNormalEditField.Value = gamma_str;


        end

        % Button pushed function: VerificarButton
        function VerificarButtonPushed(app, event)
            valorGravedad = str2double(app.GravedadNormalEditField.Value)
            if valorGravedad < app.gammae
                uialert(app.UIFigure, 'El valor no se encuentra dentro de la gravedad referida al Ecuador y a los Polos', 'Verificación del Resultado');
            elseif valorGravedad > app.gammae
                 uialert(app.UIFigure, 'El valor se encuentra dentro de la gravedad referida al Ecuador y a los Polos', 'Verificación del Resultado');
            elseif valorGravedad > app.gammap 
                uialert(app.UIFigure, 'El valor no se encuentra dentro de la gravedad referida al Ecuador y a los Polos', 'Verificación del Resultado');
            end
            
        end

        % Button pushed function: CalcularButton_20
        function CalcularButton_20Pushed(app, event)
             % EN ESTE TIPO DE CALCULOS SE NECESITA SOLO PARA GRS80
            
            % DATOS PARA GRS 80
            
            gammae=978032.67715;
            gammap=983218.63685;
            m=0.00344978600308;
            a= 6378137.0;
            f= 1/298.257222101;
            e1= 2*(f)-(f)^2;
            b= a*sqrt(1-e1);
            e2= ((a/b)^2)-1;
            
            % EFECTUAMOS LOS CALCULOS
            
            k= ((b*gammap)/(a*gammae))-1;
                  
            gamma= (gammae*((1+k*sind(app.Latitud)^2)/(sqrt(1-e1*sind(app.Latitud)^2))));
            
            gammah= gamma - ((2*gammae)/a)*(1+f+m+(-3*f+(5/2)*m)*sind(app.Latitud)^2)*app.altura+3*(gammae/a^2)*app.altura^2;
            
            gammah_str = sprintf('%.5f', gammah);
                        
            app.GravedadResultadoEditField.Value = gammah_str;
        end

        % Value changed function: LatitudEditField_8
        function LatitudEditField_8ValueChanged(app, event)
            app.Latitud = app.LatitudEditField_8.Value;
            
        end

        % Value changed function: AlturaEditField_2
        function AlturaEditField_2ValueChanged(app, event)
            app.altura = app.AlturaEditField_2.Value;
            
        end

        % Button pushed function: ReiniciarButton_8
        function ReiniciarButton_8Pushed(app, event)
            app.Latitud=0;
            app.GravedadNormalEditField.Value='';
        end

        % Button pushed function: ReiniciarButton_9
        function ReiniciarButton_9Pushed(app, event)
            app.Latitud=0;
            app.altura=0;
              app.GravedadResultadoEditField.Value='';
        end

        % Callback function
        function LatitudEditField_9ValueChanged(app, event)
           % Latitud = app.LatitudEditField_9.Value;
            
        end

        % Callback function
        function LongitudEditField_6ValueChanged(app, event)
            %Longitud = app.LongitudEditField_6.Value;
            
        end

        % Value changed function: AlturaElipsoidalEditField
        function AlturaElipsoidalEditFieldValueChanged(app, event)
            app.AlturaElipsoidalA = app.AlturaElipsoidalEditField.Value;
            
        end

        % Value changed function: NEGM08_BEditField
        function NEGM08_BEditFieldValueChanged(app, event)
            app.NEGM08B = app.NEGM08_BEditField.Value;
            
        end

        % Value changed function: AlturaOrtomtricaoNiveladaEditField
        function AlturaOrtomtricaoNiveladaEditFieldValueChanged(app, event)
            app.AlturaOrtometrica = app.AlturaOrtomtricaoNiveladaEditField.Value;
            
        end

        % Value changed function: NEGM08EditField
        function NEGM08EditFieldValueChanged(app, event)
            app.NEGM08 = app.NEGM08EditField.Value;
            
        end

        % Button pushed function: CalcularButton_21
        function CalcularButton_21Pushed(app, event)
            delh=(app.AlturaElipsoidalB-app.AlturaElipsoidalA)
            N=(app.NEGM08B-app.NEGM08)
            HB= delh+app.AlturaOrtometrica-(N)
            
            HB_str = sprintf('%.5f', HB);
                        
            app.ResultadoEditFieldH.Value = HB_str;
            
        end

        % Value changed function: AlturaElipsoidalBEditField
        function AlturaElipsoidalBEditFieldValueChanged(app, event)
            app.AlturaElipsoidalB = app.AlturaElipsoidalBEditField.Value;
            
        end

        % Button pushed function: ReiniciarButton_10
        function ReiniciarButton_10Pushed(app, event)
            AlturaElipsoidalA=0;
            AlturaElipsoidalB=0;
            AlturaOrtometrica=0;
            NEGM08=0;
            NEGM08B=0;
        end

        % Value changed function: LatitudEditField_10
        function LatitudEditField_10ValueChanged(app, event)
            app.Latitud = app.LatitudEditField_10.Value;
            
        end

        % Value changed function: AlturaEditField_3
        function AlturaEditField_3ValueChanged(app, event)
            app.Altura= app.AlturaEditField_3.Value;
            
        end

        % Value changed function: 
        % GravedadenelPuntoObservadoEditField
        function GravedadenelPuntoObservadoEditFieldValueChanged(app, event)
            app.GravedadPo = app.GravedadenelPuntoObservadoEditField.Value;
            
        end

        % Button pushed function: GravedadCA
        function GravedadCAPushed(app, event)
                gammae=978032.67715;
                gammap=983218.63685;
                m=0.00344978600308;
                a= 6378137.0;
                f= 1/298.257222101;
                e1= 2*(f)-(f)^2;
                b= a*sqrt(1-e1);
                e2= ((a/b)^2)-1;
                k= ((b*gammap)/(a*gammae))-1;
                gn= (gammae*((1+k*sind(app.Latitud)^2)/(sqrt(1-e1*sind(app.Latitud)^2))));
                AgAl=0;         
%------------- Efectuamos los cálculos--------------------------------------  
                CAL= 0.30771*app.Altura - 0.000000072*app.Altura^2;
                go= app.GravedadPo + CAL;
                AgAl=go-gn;
                
                AgAl_str = sprintf('%.5f', AgAl);                     
                app.ResultadoEditField_2.Value = AgAl_str;
            
        end

        % Button pushed function: ReiniciarButton_11
        function ReiniciarButton_11Pushed(app, event)
            app.ResultadoEditField_2.Value = '';
        end

        % Value changed function: LatitudEditField_11
        function LatitudEditField_11ValueChanged(app, event)
            app.Latitud= app.LatitudEditField_11.Value;
            
        end

        % Value changed function: AlturaEditField_4
        function AlturaEditField_4ValueChanged(app, event)
            app.Altura = app.AlturaEditField_4.Value;
            
        end

        % Value changed function: DensidadEditField
        function DensidadEditFieldValueChanged(app, event)
            app.Densidad = app.DensidadEditField.Value;
         
        end

        % Button pushed function: CalcularButton_23
        function CalcularButton_23Pushed(app, event)
                %------------ CALCULO DE LA GRAVEDAD NORMAL ---------
                gammae=978032.67715;
                gammap=983218.63685;
                m=0.00344978600308;
                a= 6378137.0;
                f= 1/298.257222101;
                e1= 2*(f)-(f)^2;
                b= a*sqrt(1-e1);
                e2= ((a/b)^2)-1;
                k= ((b*gammap)/(a*gammae))-1;
                gn= (gammae*((1+k*sind(app.Latitud)^2)/(sqrt(1-e1*sind(app.Latitud)^2))))
                AgBs=0;
                % --- EFECTUACION DE LOS CALCULOS-------------------
            
                CAL= 0.30771*app.Altura - 0.000000072*app.Altura^2
                go= app.GravedadPobservado + CAL
                CB=-0.041921*app.Densidad*app.Altura
                AgBs= go+CB-gn
                AgBs_str = sprintf('%.5f', AgBs)
                                         
                app.ResultadoEditField_3.Value = AgBs_str;
        end

        % Value changed function: 
        % GravedadenelPuntoObservadoEditField_2
        function GravedadenelPuntoObservadoEditField_2ValueChanged(app, event)
            app.GravedadPobservado = app.GravedadenelPuntoObservadoEditField_2.Value;
            
        end

        % Button pushed function: ReiniciarButton_12
        function ReiniciarButton_12Pushed(app, event)
             app.ResultadoEditField_3.Value = '';
        end

        % Value changed function: LatitudEditField_12
        function LatitudEditField_12ValueChanged(app, event)
            app.Latitud = app.LatitudEditField_12.Value;
            
        end

        % Value changed function: AlturaEditField_5
        function AlturaEditField_5ValueChanged(app, event)
            app.Altura = app.AlturaEditField_5.Value;
            
        end

        % Value changed function: 
        % GravedadenelpuntoobservadoEditField
        function GravedadenelpuntoobservadoEditFieldValueChanged(app, event)
            app.GravedadObservado = app.GravedadenelpuntoobservadoEditField.Value;
            
        end

        % Button pushed function: CalcularButton_24
        function CalcularButton_24Pushed(app, event)
            gammae=978032.67715;
            gammap=983218.63685;
            m=0.00344978600308;
            a= 6378137.0;
            f= 1/298.257222101;
            e1= 2*(f)-(f)^2;
            b= a*sqrt(1-e1);
            e2= ((a/b)^2)-1;
            k= ((b*gammap)/(a*gammae))-1;
            gn1= (gammae*((1+k*sind(app.Latitud)^2)/(sqrt(1-e1*sind(app.Latitud)^2))))
            Per=0;
            %--------- EFECTUACION DE LOS CALCULOS----------------
            
            CH=0.0371*(1-3*sind(app.Latitud)^2);
            Catm=0.874-0.000099*app.Altura+0.00000000356*app.Altura^2;
            Ch=(0.3087691-0.0004398*sind(app.Latitud)^2)*app.Altura-0.000000072125*app.Altura^2
            gp= gn1-Ch-Catm;
            Per=app.GravedadObservado-gp;
            Per_str = sprintf('%.5f', Per);
            app.ResultadoEditField_4.Value = Per_str;
        end

        % Button pushed function: ReiniciarButton_13
        function ReiniciarButton_13Pushed(app, event)
            app.ResultadoEditField_4.Value = '';
        end

        % Value changed function: GradosEditField_13
        function GradosEditField_13ValueChanged(app, event)
            app.g1 = app.GradosEditField_13.Value;
            
        end

        % Value changed function: MinutosEditField_13
        function MinutosEditField_13ValueChanged(app, event)
            app.g11 = app.MinutosEditField_13.Value;
            
        end

        % Value changed function: SegundosEditField_13
        function SegundosEditField_13ValueChanged(app, event)
            app.g111 = app.SegundosEditField_13.Value;
            
        end

        % Value changed function: GradosEditField_14
        function GradosEditField_14ValueChanged(app, event)
            app.g2 = app.GradosEditField_14.Value;
            
        end

        % Value changed function: MinutosEditField_14
        function MinutosEditField_14ValueChanged(app, event)
            app.g22 = app.MinutosEditField_14.Value;
            
        end

        % Value changed function: SegundosEditField_14
        function SegundosEditField_14ValueChanged(app, event)
            app.g222 = app.SegundosEditField_14.Value;
            
        end

        % Value changed function: GradosEditField_15
        function GradosEditField_15ValueChanged(app, event)
            app.g3 = app.GradosEditField_15.Value;
            
        end

        % Value changed function: MinutosEditField_15
        function MinutosEditField_15ValueChanged(app, event)
            app.g33 = app.MinutosEditField_15.Value;
            
        end

        % Value changed function: SegundosEditField_15
        function SegundosEditField_15ValueChanged(app, event)
            app.g333 = app.SegundosEditField_15.Value;
            
        end

        % Value changed function: GradosEditField_16
        function GradosEditField_16ValueChanged(app, event)
            app.g4 = app.GradosEditField_16.Value;
            
        end

        % Value changed function: MinutosEditField_16
        function MinutosEditField_16ValueChanged(app, event)
            app.g44 = app.MinutosEditField_16.Value;
            
        end

        % Value changed function: SegundosEditField_16
        function SegundosEditField_16ValueChanged(app, event)
            app.g444 = app.SegundosEditField_16.Value;
            
        end

        % Button pushed function: SButton_5
        function SButton_5Pushed(app, event)
            app.Latitud=[app.g1 app.g11 app.g111]; 
            app.Latitudb=[app.g2 app.g22 app.g222];
           
            dms=[app.Latitud;app.Latitudb];
            angleInDegrees=dms2degrees(dms);
            latitud=angleInDegrees(1,1);
            latitudb=angleInDegrees(2,1);
            
            app.Sur=1;
            %app.Longitud1 = Longitud;
            if app.Sur == 1
                app.latitud = latitud* -1;
                app.latitudb= latitudb*-1
            end
            app.SButton.Enable='off';   
        end

        % Button pushed function: OButton_4
        function OButton_4Pushed(app, event)
            app.Longitud=[app.g3 app.g33 app.g333]; 
            app.Longitudb=[app.g4 app.g44 app.g444];
           
            dms=[app.Longitud;app.Longitudb];
            angleInDegrees=dms2degrees(dms);
            longitud=angleInDegrees(1,1);
            longitudb=angleInDegrees(2,1);
            
            app.Oeste=1;
            %app.Longitud1 = Longitud;
            if app.Oeste == 1
                app.longitud = longitud* -1;
                app.longitudb= longitudb*-1
            end
            app.SButton.Enable='off';
        end

        % Button pushed function: CalcularButton_25
        function CalcularButton_25Pushed(app, event)
            % CALCULO DEL PROBLEMA INVERSO
              % Información Preliminar.
            % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
            format long
            a= 6378137.0;
            f= 1/298.257222101;
            
            % wgs 84
            a1= 6378137.0;
            f1= 1/298.257223563;
            e1= 2*(f1)-(f1)^2;
            % HAYFORD
            a2= 6378388;
            f2=1/297;
            % ********** EFECTUACION DE LOS CALCULOS**********************
            format long
            % DIFERNCIA DE LATITUDES.
            fim=(app.latitudb+app.latitud)/2; %latitud media.
            fim_r=deg2rad(fim);
            Dfim=(app.latitudb-app.latitud);
            Dfim_r = deg2rad(Dfim);
            Dlo= (app.longitudb-app.longitud); % Diferencia de longitudes.
            Dlo_r = deg2rad(Dlo);
            % CALCULO DE Nm y Mm
            Nm=((a1)/(sqrt(1-e1*sin(fim_r)^2)));
            %Calculamos M
            Mm=a1*(1-e1)/(1-e1*sin(fim_r)^2).^(3/2);
            % FORMULAS PROBLEMA INVERSO
            %Dz=Dlo_r*sin(fim_r)*sec(Dfim_r/2)+((Dlo)^3/12)*(sin(fim_r)*sec(Dfim_r/2)-sin(fim_r)^3*sec((Dfim_r)/2)^3);
            Dz=Dlo*sin(fim_r)*sec(Dfim_r/2)+((Dlo_r)^3/12)*(sin(fim_r)*sec(Dfim_r/2)-sin(fim_r)^3*sec((Dfim_r)/2)^3);
            Dz_r=deg2rad(Dz)
            %Az12=(atand((cos(fim_r)*sin(Dlo/2))/(sin(((Mm)/2*Nm)*Dfim_r)*Dfim_r)*cos(Dlo/2)))-(Dz_r/2);
            Az12=(atand((cos(fim_r)*sin(Dlo_r/2))/(sin(((Mm)/2*Nm)*Dfim_r)*Dfim_r)*cos(Dlo_r/2)))-(Dz_r/2);
            %S=(asind((cos(fim_r)*sin(Dlo_r/2))/sin(Az12+Dz_r/2))*2*Nm);
            S=(asin((cosd(fim_r)*sind(Dlo_r/2))/sind(Az12+Dz_r/2))*2*Nm);
            % Presentación de Los valores
            S_str = sprintf('%.5f', S);
            Az12_str=sprintf('%.5f', Az12);
            
            app.DistanciaEditField_3.Value=S_str;
            app.AzimutEditField_6.Value=Az12_str;
            app.DistanciaEditField_2.Value = S_str;
            app.AzimutEditField_5.Value=Az12_str;
        end

        % Button pushed function: CalcularButton_26
        function CalcularButton_26Pushed(app, event)
            % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
            format long
            a= 6378137.0;
            f= 1/298.257222101;
            e1= 2*(f)-(f)^2;
            % wgs 84
            a1= 6378137.0;
            f1= 1/298.257223563;
            
            % HAYFORD
            a2= 6378388;
            f2=1/297;
            % ********** EFECTUACION DE LOS CALCULOS**********************
            format long
            % DIFERNCIA DE LATITUDES.
            fim=(app.latitudb+app.latitud)/2; %latitud media.
            fim_r=deg2rad(fim);
            Dfim=(app.latitudb-app.latitud);
            Dfim_r = deg2rad(Dfim);
            Dlo= (app.longitudb-app.longitud); % Diferencia de longitudes.
            Dlo_r = deg2rad(Dlo);
            % CALCULO DE Nm y Mm
            Nm=((a)/(sqrt(1-e1*sin(fim_r).^2)));
            %Calculamos M
            Mm=a*(1-e1)/(1-e1*sin(fim_r)^2).^(3/2);
            % FORMULAS PROBLEMA INVERSO
            %Dz=Dlo_r*sin(fim_r)*sec(Dfim_r/2)+((Dlo)^3/12)*(sin(fim_r)*sec(Dfim_r/2)-sin(fim_r)^3*sec((Dfim_r)/2)^3);
            Dz=Dlo*sin(fim_r)*sec(Dfim_r/2)+((Dlo_r)^3/12)*(sin(fim_r)*sec(Dfim_r/2)-sin(fim_r)^3*sec((Dfim_r)/2)^3);
            Dz_r=deg2rad(Dz)
            %Az12=(atand((cos(fim_r)*sin(Dlo/2))/(sin(((Mm)/2*Nm)*Dfim_r)*Dfim_r)*cos(Dlo/2)))-(Dz_r/2);
            Az12=(atand((cos(fim_r)*sin(Dlo_r/2))/(sin(((Mm)*Dfim_r/2*Nm)))*cos(Dlo_r/2)))-(Dz_r/2);
            %S=(asind((cos(fim_r)*sin(Dlo_r/2))/sin(Az12+Dz_r/2))*2*Nm);
            S=(asin((cosd(fim_r)*sind(Dlo_r/2))/sind(Az12+Dz_r/2))*2*Nm);
            % Presentación de Los valores
            S_str = sprintf('%.5f', S);
            Az12_str=sprintf('%.5f', Az12);
            
            app.DistanciaEditField_3.Value=S_str;
            app.AzimutEditField_6.Value=Az12_str;
        end

        % Button pushed function: CalcularButton_27
        function CalcularButton_27Pushed(app, event)
            % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
            format long
            a= 6378137.0;
            f= 1/298.257222101;
            
            % wgs 84
            a1= 6378137.0;
            f1= 1/298.257223563;
            
            % HAYFORD
            a2= 6378388;
            f2=1/297;
            e1= 2*(f2)-(f2)^2;
            % ********** EFECTUACION DE LOS CALCULOS**********************
            format long
            % DIFERNCIA DE LATITUDES.
            fim=(app.latitudb+app.latitud)/2; %latitud media.
            fim_r=deg2rad(fim);
            Dfim=(app.latitudb-app.latitud);
            Dfim_r = deg2rad(Dfim);
            Dlo= (app.longitudb-app.longitud); % Diferencia de longitudes.
            Dlo_r = deg2rad(Dlo);
            % CALCULO DE Nm y Mm
            Nm=((a2)/(sqrt(1-e1*sin(fim_r)^2)));
            %Calculamos M
            Mm=a2*(1-e1)/(1-e1*sin(fim_r)^2).^(3/2);
            % FORMULAS PROBLEMA INVERSO
            %Dz=Dlo_r*sin(fim_r)*sec(Dfim_r/2)+((Dlo)^3/12)*(sin(fim_r)*sec(Dfim_r/2)-sin(fim_r)^3*sec((Dfim_r)/2)^3);
            Dz=Dlo*sin(fim_r)*sec(Dfim_r/2)+((Dlo_r)^3/12)*(sin(fim_r)*sec(Dfim_r/2)-sin(fim_r)^3*sec((Dfim_r)/2)^3);
            Dz_r=deg2rad(Dz)
            %Az12=(atand((cos(fim_r)*sin(Dlo/2))/(sin(((Mm)/2*Nm)*Dfim_r)*Dfim_r)*cos(Dlo/2)))-(Dz_r/2);
            Az12=(atand((cos(fim_r)*sin(Dlo_r/2))/(sin(((Mm)/2*Nm)*Dfim_r)*Dfim_r)*cos(Dlo_r/2)))-(Dz_r/2);
            %S=(asind((cos(fim_r)*sin(Dlo_r/2))/sin(Az12+Dz_r/2))*2*Nm);
            S=(asin((cosd(fim_r)*sind(Dlo_r/2))/sind(Az12+Dz_r/2))*2*Nm);
            % Presentación de Los valores
            S_str = sprintf('%.5f', S);
            Az12_str=sprintf('%.5f', Az12);
            
            app.DistanciaEditField_4.Value = S_str;
            app.AzimutEditField_7.Value=Az12_str;
        end

        % Button pushed function: ReiniciarButton_14
        function ReiniciarButton_14Pushed(app, event)
            app.DistanciaEditField_4.Value = '';
            app.AzimutEditField_7.Value='';
            app.DistanciaEditField_3.Value = '';
            app.AzimutEditField_6.Value='';
            app.DistanciaEditField_2.Value = '';
            app.AzimutEditField_5.Value='';
            
        end

        % Value changed function: TxEditField
        function TxEditFieldValueChanged(app, event)
            app.Tx = app.TxEditField.Value;
            
        end

        % Value changed function: TyEditField
        function TyEditFieldValueChanged(app, event)
            app.Ty = app.TyEditField.Value;
            
        end

        % Value changed function: TzEditField
        function TzEditFieldValueChanged(app, event)
            app.Tz = app.TzEditField.Value;
            
        end

        % Value changed function: DiferenciadeEscalaEditField
        function DiferenciadeEscalaEditFieldValueChanged(app, event)
            app.DiferenciadeEscala = app.DiferenciadeEscalaEditField.Value;
            
        end

        % Value changed function: RxEditField
        function RxEditFieldValueChanged(app, event)
            app.Rx = app.RxEditField.Value;
            
        end

        % Value changed function: RyEditField
        function RyEditFieldValueChanged(app, event)
            app.Ry = app.RyEditField.Value;
            
        end

        % Value changed function: RzEditField
        function RzEditFieldValueChanged(app, event)
            app.Rz = app.RzEditField.Value;
            
        end

        % Value changed function: RxEditField_2
        function RxEditField_2ValueChanged(app, event)
            app.Rx1 = app.RxEditField_2.Value;
            
        end

        % Value changed function: RyEditField_2
        function RyEditField_2ValueChanged(app, event)
            app.Ry1 = app.RyEditField_2.Value;
            
        end

        % Value changed function: RzEditField_2
        function RzEditField_2ValueChanged(app, event)
            app.Rz1 = app.RzEditField_2.Value;
            
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            app.X = app.XEditField.Value;
            
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            app.Y = app.YEditField.Value;
            
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            app.Z = app.ZEditField.Value;
            
        end

        % Button pushed function: CalcularButton_29
        function CalcularButton_29Pushed(app, event)
            c=app.Rx1/1000;
            d=c/3600;
            d_str = sprintf('%.9f', d);
            app.RxEditField_3.Value=d_str;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            e=app.Ry1/1000;
            f=e/3600;
            g_str=sprintf('%.9f', f);
            app.RyEditField_3.Value=g_str;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            ha=app.Rz1/1000;
            j=ha/3600;
            b_str=sprintf('%.9f', j);
            app.RzEditField_3.Value=b_str;
        end

        % Button pushed function: CalcularButton_28
        function CalcularButton_28Pushed(app, event)
            app.X
            app.Y
            app.Z
            
            T=[app.Tx;app.Ty;app.Tz]
            de=(1+(app.DiferenciadeEscala));
            ro=[1 app.Rz -(app.Ry);-(app.Rz) 1 app.Rx;app.Ry -(app.Rx) 1];
            co=[app.X;app.Y;app.Z];
            
            IT=T+de*ro*co;
            IT
            IT1=IT(1,1)
            IT11_str=sprintf('%.5f', IT1);
            app.XEditField_2.Value=IT11_str;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            IT2=IT(2,1);
            IT22_str=sprintf('%.5f', IT2);
            app.YEditField_2.Value=IT22_str;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            IT3=IT(3,1);
            IT33_str=sprintf('%.5f', IT3);
            app.ZEditField_2.Value=IT33_str;
        end

        % Button pushed function: ReiniciarButton_15
        function ReiniciarButton_15Pushed(app, event)
            app.XEditField_2.Value='';
            
            app.YEditField_2.Value='';
           
            app.ZEditField_2.Value='';
            
            app.RxEditField_3.Value='';
           
            app.RyEditField_3.Value='';
            
            app.RzEditField_3.Value='';
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {609, 609, 609};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {609, 609};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {14, '1x', 12};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CenterPanel = uipanel(app.GridLayout);
            app.CALCULADORADEGEODESIAGENERALLabel = uilabel(app.CenterPanel);
            app.CALCULADORADEGEODESIAGENERALLabel.Text = 'CALCULADORA DE GEODESIA GENERAL';
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.CCoordenadasTab = uitab(app.TabGroup);
            app.CCoordenadasTab.Title = 'C. Coordenadas';
            app.QudeseacalcularPanel = uipanel(app.CCoordenadasTab);
            app.QudeseacalcularPanel.Title = '¿Qué desea calcular?';
            app.CalculodetransformacindeCoordenadasPanel = uipanel(app.QudeseacalcularPanel);
            app.CalculodetransformacindeCoordenadasPanel.Title = 'Calculo de transformación de Coordenadas';
            app.DeCoordenadasxyzaLatitudLongitudyAlturaLabel = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.DeCoordenadasxyzaLatitudLongitudyAlturaLabel.Text = 'De Coordenadas (x,y,z) a (Latitud, Longitud y Altura)';
            app.IngreseelvalordexEditFieldLabel = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.IngreseelvalordexEditFieldLabel.Text = 'Ingrese el valor de x';
            app.IngreseelvalordexEditField = uieditfield(app.CalculodetransformacindeCoordenadasPanel, 'numeric');
            app.IngreseelvalordexEditField.ValueChangedFcn = createCallbackFcn(app, @IngreseelvalordexEditFieldValueChanged, true);
            app.IngreseelvalordeyEditFieldLabel = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.IngreseelvalordeyEditFieldLabel.Text = 'Ingrese el valor de y';
            app.IngreseelvalordeyEditField = uieditfield(app.CalculodetransformacindeCoordenadasPanel, 'numeric');
            app.IngreseelvalordeyEditField.ValueChangedFcn = createCallbackFcn(app, @IngreseelvalordeyEditFieldValueChanged, true);
            app.IngreseelvalordezEditFieldLabel = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.IngreseelvalordezEditFieldLabel.Text = 'Ingrese el valor de z';
            app.IngreseelvalordezEditField = uieditfield(app.CalculodetransformacindeCoordenadasPanel, 'numeric');
            app.IngreseelvalordezEditField.ValueChangedFcn = createCallbackFcn(app, @IngreseelvalordezEditFieldValueChanged, true);
            app.LatitudEditFieldLabel = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.LatitudEditFieldLabel.Text = 'Latitud';
            app.LatitudEditField = uieditfield(app.CalculodetransformacindeCoordenadasPanel, 'numeric');
            app.LatitudEditField.ValueChangedFcn = createCallbackFcn(app, @LatitudEditFieldValueChanged, true);
            app.LongitudEditFieldLabel = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.LongitudEditFieldLabel.Text = 'Longitud';
            app.LongitudEditField = uieditfield(app.CalculodetransformacindeCoordenadasPanel, 'numeric');
            app.LongitudEditField.ValueChangedFcn = createCallbackFcn(app, @LongitudEditFieldValueChanged, true);
            app.AlturaEditFieldLabel = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.AlturaEditFieldLabel.Text = 'Altura';
            app.AlturaEditField = uieditfield(app.CalculodetransformacindeCoordenadasPanel, 'numeric');
            app.AlturaEditField.ValueChangedFcn = createCallbackFcn(app, @AlturaEditFieldValueChanged, true);
            app.DeCoordenadasLatitudLongitudyAlturaaxyzLabel = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.DeCoordenadasLatitudLongitudyAlturaaxyzLabel.Text = 'De Coordenadas (Latitud, Longitud y Altura) a (x,y,z) .';
            app.IngreseelvalordelalatitudendecimalesEditFieldLabel = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.IngreseelvalordelalatitudendecimalesEditFieldLabel.Text = 'Ingrese el valor de la latitud, en decimales';
            app.IngreseelvalordelalatitudendecimalesEditField = uieditfield(app.CalculodetransformacindeCoordenadasPanel, 'numeric');
            app.IngreseelvalordelalatitudendecimalesEditField.ValueChangedFcn = createCallbackFcn(app, @IngreseelvalordelalatitudendecimalesEditFieldValueChanged, true);
            app.IngreseelvalordelalongitudendecimalesEditFieldLabel = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.IngreseelvalordelalongitudendecimalesEditFieldLabel.Text = 'Ingrese el valor de la longitud, en decimales';
            app.IngreseelvalordelalongitudendecimalesEditField = uieditfield(app.CalculodetransformacindeCoordenadasPanel, 'numeric');
            app.IngreseelvalordelalongitudendecimalesEditField.ValueChangedFcn = createCallbackFcn(app, @IngreseelvalordelalongitudendecimalesEditFieldValueChanged, true);
            app.EnGradosMinutosySegundosLabel = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.EnGradosMinutosySegundosLabel.Text = 'En Grados Minutos y Segundos.';
            app.LatitudLabel = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.LatitudLabel.Text = 'Latitud';
            app.LongitudLabel = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.LongitudLabel.Text = 'Longitud';
            app.IngreseelvalordelaalturaEditFieldLabel = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.IngreseelvalordelaalturaEditFieldLabel.Text = 'Ingrese el valor de la altura';
            app.IngreseelvalordelaalturaEditField = uieditfield(app.CalculodetransformacindeCoordenadasPanel, 'numeric');
            app.IngreseelvalordelaalturaEditField.ValueChangedFcn = createCallbackFcn(app, @IngreseelvalordelaalturaEditFieldValueChanged2, true);
            app.CalcularButton = uibutton(app.CalculodetransformacindeCoordenadasPanel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.CalcularButton_2 = uibutton(app.CalculodetransformacindeCoordenadasPanel, 'push');
            app.CalcularButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_2Pushed, true);
            app.CalcularButton_2.Text = 'Calcular';
            app.CalcularButton_3 = uibutton(app.CalculodetransformacindeCoordenadasPanel, 'push');
            app.CalcularButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_3Pushed, true);
            app.CalcularButton_3.Text = 'Calcular';
            app.TiposdeElipsodidesalquedeseaustedcalcularLabel = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.TiposdeElipsodidesalquedeseaustedcalcularLabel.Text = 'Tipos de Elipsodides al que desea usted calcular: ';
            app.GRS80Label = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.GRS80Label.Text = 'GRS 80';
            app.CalcularButton_4 = uibutton(app.CalculodetransformacindeCoordenadasPanel, 'push');
            app.CalcularButton_4.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_4Pushed, true);
            app.CalcularButton_4.Text = 'Calcular';
            app.CalcularButton_5 = uibutton(app.CalculodetransformacindeCoordenadasPanel, 'push');
            app.CalcularButton_5.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_5Pushed, true);
            app.CalcularButton_5.Text = 'Calcular';
            app.WGS84Label = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.WGS84Label.Text = 'WGS 84';
            app.HayfordLabel = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.HayfordLabel.Text = 'Hayford';
            app.GradosEditFieldLabel = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.GradosEditFieldLabel.Text = 'Grados';
            app.GradosEditField = uieditfield(app.CalculodetransformacindeCoordenadasPanel, 'numeric');
            app.GradosEditField.ValueChangedFcn = createCallbackFcn(app, @GradosEditFieldValueChanged, true);
            app.MinutosEditFieldLabel = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.MinutosEditFieldLabel.Text = 'Minutos';
            app.MinutosEditField = uieditfield(app.CalculodetransformacindeCoordenadasPanel, 'numeric');
            app.MinutosEditField.ValueChangedFcn = createCallbackFcn(app, @MinutosEditFieldValueChanged, true);
            app.SegundosEditFieldLabel = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.SegundosEditFieldLabel.Text = 'Segundos';
            app.SegundosEditField = uieditfield(app.CalculodetransformacindeCoordenadasPanel, 'numeric');
            app.SegundosEditField.ValueChangedFcn = createCallbackFcn(app, @SegundosEditFieldValueChanged, true);
            app.GradosEditField_2Label = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.GradosEditField_2Label.Text = 'Grados';
            app.GradosEditField_2 = uieditfield(app.CalculodetransformacindeCoordenadasPanel, 'numeric');
            app.GradosEditField_2.ValueChangedFcn = createCallbackFcn(app, @GradosEditField_2ValueChanged, true);
            app.MinutosEditField_2Label = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.MinutosEditField_2Label.Text = 'Minutos';
            app.MinutosEditField_2 = uieditfield(app.CalculodetransformacindeCoordenadasPanel, 'numeric');
            app.MinutosEditField_2.ValueChangedFcn = createCallbackFcn(app, @MinutosEditField_2ValueChanged, true);
            app.SegundosEditField_9Label = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.SegundosEditField_9Label.Text = 'Segundos';
            app.SegundosEditField_9 = uieditfield(app.CalculodetransformacindeCoordenadasPanel, 'numeric');
            app.SegundosEditField_9.ValueChangedFcn = createCallbackFcn(app, @SegundosEditField_9ValueChanged, true);
            app.CalcularButton_6 = uibutton(app.CalculodetransformacindeCoordenadasPanel, 'push');
            app.CalcularButton_6.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_6Pushed, true);
            app.CalcularButton_6.Text = 'Calcular';
            app.GRS80Label_2 = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.GRS80Label_2.Text = 'GRS 80';
            app.WGS84Label_2 = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.WGS84Label_2.Text = 'WGS 84';
            app.HayfordLabel_2 = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.HayfordLabel_2.Text = 'Hayford';
            app.IngreseelvalordelaAlturaEditFieldLabel = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.IngreseelvalordelaAlturaEditFieldLabel.Text = 'Ingrese el valor de la Altura';
            app.IngreseelvalordelaAlturaEditField = uieditfield(app.CalculodetransformacindeCoordenadasPanel, 'numeric');
            app.IngreseelvalordelaAlturaEditField.ValueChangedFcn = createCallbackFcn(app, @IngreseelvalordelaAlturaEditFieldValueChanged, true);
            app.CalcularButton_7 = uibutton(app.CalculodetransformacindeCoordenadasPanel, 'push');
            app.CalcularButton_7.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_7Pushed, true);
            app.CalcularButton_7.Text = 'Calcular';
            app.CalcularButton_8 = uibutton(app.CalculodetransformacindeCoordenadasPanel, 'push');
            app.CalcularButton_8.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_8Pushed, true);
            app.CalcularButton_8.Text = 'Calcular';
            app.CalcularButton_9 = uibutton(app.CalculodetransformacindeCoordenadasPanel, 'push');
            app.CalcularButton_9.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_9Pushed, true);
            app.CalcularButton_9.Text = 'Calcular';
            app.GRS80Label_3 = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.GRS80Label_3.Text = 'GRS 80';
            app.WGS84Label_3 = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.WGS84Label_3.Text = 'WGS 84';
            app.HayfordLabel_3 = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.HayfordLabel_3.Text = 'Hayford';
            app.xEditField_3Label = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.xEditField_3Label.Text = 'x';
            app.xEditField_3 = uieditfield(app.CalculodetransformacindeCoordenadasPanel, 'text');
            app.yEditField_3Label = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.yEditField_3Label.Text = 'y';
            app.yEditField_3 = uieditfield(app.CalculodetransformacindeCoordenadasPanel, 'text');
            app.zEditField_3Label = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.zEditField_3Label.Text = 'z';
            app.zEditField_3 = uieditfield(app.CalculodetransformacindeCoordenadasPanel, 'text');
            app.xEditField_4Label = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.xEditField_4Label.Text = 'x';
            app.xEditField_4 = uieditfield(app.CalculodetransformacindeCoordenadasPanel, 'text');
            app.yEditField_4Label = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.yEditField_4Label.Text = 'y';
            app.yEditField_4 = uieditfield(app.CalculodetransformacindeCoordenadasPanel, 'text');
            app.zEditField_4Label = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.zEditField_4Label.Text = 'z';
            app.zEditField_4 = uieditfield(app.CalculodetransformacindeCoordenadasPanel, 'text');
            app.SButton = uibutton(app.CalculodetransformacindeCoordenadasPanel, 'push');
            app.SButton.ButtonPushedFcn = createCallbackFcn(app, @SButtonPushed, true);
            app.SButton.Text = 'S';
            app.OButton = uibutton(app.CalculodetransformacindeCoordenadasPanel, 'push');
            app.OButton.ButtonPushedFcn = createCallbackFcn(app, @OButtonPushed, true);
            app.OButton.Text = 'O';
            app.ReiniciarButton = uibutton(app.CalculodetransformacindeCoordenadasPanel, 'push');
            app.ReiniciarButton.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButtonPushed, true);
            app.ReiniciarButton.Text = 'Reiniciar';
            app.ReiniciarButton_4 = uibutton(app.CalculodetransformacindeCoordenadasPanel, 'push');
            app.ReiniciarButton_4.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButton_4Pushed, true);
            app.ReiniciarButton_4.Text = 'Reiniciar';
            app.ReiniciarButton_5 = uibutton(app.CalculodetransformacindeCoordenadasPanel, 'push');
            app.ReiniciarButton_5.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButton_5Pushed, true);
            app.ReiniciarButton_5.Text = 'Reiniciar';
            app.MetrosLabel = uilabel(app.CalculodetransformacindeCoordenadasPanel);
            app.MetrosLabel.Text = 'Metros';
            app.RPrincipalesTab = uitab(app.TabGroup);
            app.RPrincipalesTab.Title = 'R. Principales';
            app.QudeseacalcularRadiosPrincipalesdeCurvaturaPanel = uipanel(app.RPrincipalesTab);
            app.QudeseacalcularRadiosPrincipalesdeCurvaturaPanel.Title = '¿Qué desea calcular? - Radios Principales de Curvatura.';
            app.RadiosPrincipalesPanel = uipanel(app.QudeseacalcularRadiosPrincipalesdeCurvaturaPanel);
            app.RadiosPrincipalesPanel.Title = 'Radios Principales.';
            app.RadiodeCurvaturaconunAzimutdeunaseccinNormalLabel = uilabel(app.RadiosPrincipalesPanel);
            app.RadiodeCurvaturaconunAzimutdeunaseccinNormalLabel.Text = 'Radio de Curvatura con un Azimut de una sección Normal - Grados Minutos y Segundos.';
            app.AzimutLabel = uilabel(app.RadiosPrincipalesPanel);
            app.AzimutLabel.Text = 'Azimut';
            app.GradosEditField_3Label = uilabel(app.RadiosPrincipalesPanel);
            app.GradosEditField_3Label.Text = 'Grados';
            app.GradosEditField_3 = uieditfield(app.RadiosPrincipalesPanel, 'numeric');
            app.GradosEditField_3.ValueChangedFcn = createCallbackFcn(app, @GradosEditField_3ValueChanged, true);
            app.MinutosEditField_3Label = uilabel(app.RadiosPrincipalesPanel);
            app.MinutosEditField_3Label.Text = 'Minutos';
            app.MinutosEditField_3 = uieditfield(app.RadiosPrincipalesPanel, 'numeric');
            app.MinutosEditField_3.ValueChangedFcn = createCallbackFcn(app, @MinutosEditField_3ValueChanged, true);
            app.SegundosEditField_2Label = uilabel(app.RadiosPrincipalesPanel);
            app.SegundosEditField_2Label.Text = 'Segundos';
            app.SegundosEditField_2 = uieditfield(app.RadiosPrincipalesPanel, 'numeric');
            app.SegundosEditField_2.ValueChangedFcn = createCallbackFcn(app, @SegundosEditField_2ValueChanged, true);
            app.GradosEditField_4Label = uilabel(app.RadiosPrincipalesPanel);
            app.GradosEditField_4Label.Text = 'Grados';
            app.GradosEditField_4 = uieditfield(app.RadiosPrincipalesPanel, 'numeric');
            app.GradosEditField_4.ValueChangedFcn = createCallbackFcn(app, @GradosEditField_4ValueChanged, true);
            app.MinutosEditField_4Label = uilabel(app.RadiosPrincipalesPanel);
            app.MinutosEditField_4Label.Text = 'Minutos';
            app.MinutosEditField_4 = uieditfield(app.RadiosPrincipalesPanel, 'numeric');
            app.MinutosEditField_4.ValueChangedFcn = createCallbackFcn(app, @MinutosEditField_4ValueChanged, true);
            app.SegundosEditField_3Label = uilabel(app.RadiosPrincipalesPanel);
            app.SegundosEditField_3Label.Text = 'Segundos';
            app.SegundosEditField_3 = uieditfield(app.RadiosPrincipalesPanel, 'numeric');
            app.SegundosEditField_3.ValueChangedFcn = createCallbackFcn(app, @SegundosEditField_3ValueChanged, true);
            app.LatitudLabel_2 = uilabel(app.RadiosPrincipalesPanel);
            app.LatitudLabel_2.Text = 'Latitud';
            app.RazEditFieldLabel = uilabel(app.RadiosPrincipalesPanel);
            app.RazEditFieldLabel.Text = 'Raz';
            app.RazEditField = uieditfield(app.RadiosPrincipalesPanel, 'text');
            app.CalcularButton_10 = uibutton(app.RadiosPrincipalesPanel, 'push');
            app.CalcularButton_10.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_10Pushed, true);
            app.CalcularButton_10.Text = 'Calcular';
            app.GRS80Label_4 = uilabel(app.RadiosPrincipalesPanel);
            app.GRS80Label_4.Text = 'GRS 80';
            app.CalcularButton_11 = uibutton(app.RadiosPrincipalesPanel, 'push');
            app.CalcularButton_11.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_11Pushed, true);
            app.CalcularButton_11.Text = 'Calcular';
            app.WGS84Label_4 = uilabel(app.RadiosPrincipalesPanel);
            app.WGS84Label_4.Text = 'WGS 84';
            app.CalcularButton_12 = uibutton(app.RadiosPrincipalesPanel, 'push');
            app.CalcularButton_12.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_12Pushed, true);
            app.CalcularButton_12.Text = 'Calcular';
            app.HayfordLabel_4 = uilabel(app.RadiosPrincipalesPanel);
            app.HayfordLabel_4.Text = 'Hayford';
            app.RadiodeCurvaturaconunAzimutdeunaseccinNormalLabel_2 = uilabel(app.RadiosPrincipalesPanel);
            app.RadiodeCurvaturaconunAzimutdeunaseccinNormalLabel_2.Text = 'En grados decimales: ';
            app.AzimutEditFieldLabel = uilabel(app.RadiosPrincipalesPanel);
            app.AzimutEditFieldLabel.Text = 'Azimut';
            app.AzimutEditField = uieditfield(app.RadiosPrincipalesPanel, 'numeric');
            app.AzimutEditField.ValueChangedFcn = createCallbackFcn(app, @AzimutEditFieldValueChanged, true);
            app.LatitudEditField_2Label = uilabel(app.RadiosPrincipalesPanel);
            app.LatitudEditField_2Label.Text = 'Latitud';
            app.LatitudEditField_2 = uieditfield(app.RadiosPrincipalesPanel, 'numeric');
            app.LatitudEditField_2.ValueChangedFcn = createCallbackFcn(app, @LatitudEditField_2ValueChanged, true);
            app.ReiniciarButton_2 = uibutton(app.RadiosPrincipalesPanel, 'push');
            app.ReiniciarButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButton_2Pushed, true);
            app.ReiniciarButton_2.Text = 'Reiniciar';
            app.RadiodeCurvaturaconunAzimutdeunaseccinNormalLabel_3 = uilabel(app.RadiosPrincipalesPanel);
            app.RadiodeCurvaturaconunAzimutdeunaseccinNormalLabel_3.Text = 'Radio Medio de Curvatura.';
            app.RmEditFieldLabel = uilabel(app.RadiosPrincipalesPanel);
            app.RmEditFieldLabel.Text = 'Rm';
            app.RmEditField = uieditfield(app.RadiosPrincipalesPanel, 'text');
            app.RadiodeCurvaturaconunAzimutdeunaseccinNormalLabel_4 = uilabel(app.RadiosPrincipalesPanel);
            app.RadiodeCurvaturaconunAzimutdeunaseccinNormalLabel_4.Text = 'Radio de Curvatura de Paralelo';
            app.RpEditFieldLabel = uilabel(app.RadiosPrincipalesPanel);
            app.RpEditFieldLabel.Text = 'Rp';
            app.RpEditField = uieditfield(app.RadiosPrincipalesPanel, 'text');
            app.GRS80Label_5 = uilabel(app.RadiosPrincipalesPanel);
            app.GRS80Label_5.Text = 'GRS 80';
            app.CalcularButton_13 = uibutton(app.RadiosPrincipalesPanel, 'push');
            app.CalcularButton_13.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_13Pushed, true);
            app.CalcularButton_13.Text = 'Calcular';
            app.WGS84Label_5 = uilabel(app.RadiosPrincipalesPanel);
            app.WGS84Label_5.Text = 'WGS 84';
            app.CalcularButton_14 = uibutton(app.RadiosPrincipalesPanel, 'push');
            app.CalcularButton_14.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_14Pushed, true);
            app.CalcularButton_14.Text = 'Calcular';
            app.HayfordLabel_5 = uilabel(app.RadiosPrincipalesPanel);
            app.HayfordLabel_5.Text = 'Hayford';
            app.CalcularButton_15 = uibutton(app.RadiosPrincipalesPanel, 'push');
            app.CalcularButton_15.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_15Pushed, true);
            app.CalcularButton_15.Text = 'Calcular';
            app.GRS80Label_6 = uilabel(app.RadiosPrincipalesPanel);
            app.GRS80Label_6.Text = 'GRS 80';
            app.CalcularButton_16 = uibutton(app.RadiosPrincipalesPanel, 'push');
            app.CalcularButton_16.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_16Pushed, true);
            app.CalcularButton_16.Text = 'Calcular';
            app.WGS84Label_6 = uilabel(app.RadiosPrincipalesPanel);
            app.WGS84Label_6.Text = 'WGS 84';
            app.CalcularButton_17 = uibutton(app.RadiosPrincipalesPanel, 'push');
            app.CalcularButton_17.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_17Pushed, true);
            app.CalcularButton_17.Text = 'Calcular';
            app.HayfordLabel_6 = uilabel(app.RadiosPrincipalesPanel);
            app.HayfordLabel_6.Text = 'Hayford';
            app.CalcularButton_18 = uibutton(app.RadiosPrincipalesPanel, 'push');
            app.CalcularButton_18.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_18Pushed, true);
            app.CalcularButton_18.Text = 'Calcular';
            app.SButton_2 = uibutton(app.RadiosPrincipalesPanel, 'push');
            app.SButton_2.ButtonPushedFcn = createCallbackFcn(app, @SButton_2Pushed, true);
            app.SButton_2.Text = 'S';
            app.ArcosMeridianoyParaleloTab = uitab(app.TabGroup);
            app.ArcosMeridianoyParaleloTab.Title = 'Arcos Meridiano y Paralelo';
            app.ClculodeLongituddeArcodeParaleloPanel = uipanel(app.ArcosMeridianoyParaleloTab);
            app.ClculodeLongituddeArcodeParaleloPanel.Title = 'Cálculo de Longitud de Arco de Paralelo';
            app.LongituddeArcodeParaleloLabel = uilabel(app.ClculodeLongituddeArcodeParaleloPanel);
            app.LongituddeArcodeParaleloLabel.Text = 'Longitud de Arco de Paralelo';
            app.LatitudLabel_3 = uilabel(app.ClculodeLongituddeArcodeParaleloPanel);
            app.LatitudLabel_3.Text = 'Latitud';
            app.LongitudALabel = uilabel(app.ClculodeLongituddeArcodeParaleloPanel);
            app.LongitudALabel.Text = 'Longitud A';
            app.GradosEditField_5Label = uilabel(app.ClculodeLongituddeArcodeParaleloPanel);
            app.GradosEditField_5Label.Text = 'Grados';
            app.GradosEditField_5 = uieditfield(app.ClculodeLongituddeArcodeParaleloPanel, 'numeric');
            app.GradosEditField_5.ValueChangedFcn = createCallbackFcn(app, @GradosEditField_5ValueChanged, true);
            app.MinutosEditField_5Label = uilabel(app.ClculodeLongituddeArcodeParaleloPanel);
            app.MinutosEditField_5Label.Text = 'Minutos';
            app.MinutosEditField_5 = uieditfield(app.ClculodeLongituddeArcodeParaleloPanel, 'numeric');
            app.MinutosEditField_5.ValueChangedFcn = createCallbackFcn(app, @MinutosEditField_5ValueChanged, true);
            app.SegundosEditField_4Label = uilabel(app.ClculodeLongituddeArcodeParaleloPanel);
            app.SegundosEditField_4Label.Text = 'Segundos';
            app.SegundosEditField_4 = uieditfield(app.ClculodeLongituddeArcodeParaleloPanel, 'numeric');
            app.SegundosEditField_4.ValueChangedFcn = createCallbackFcn(app, @SegundosEditField_4ValueChanged, true);
            app.GradosEditField_6Label = uilabel(app.ClculodeLongituddeArcodeParaleloPanel);
            app.GradosEditField_6Label.Text = 'Grados';
            app.GradosEditField_6 = uieditfield(app.ClculodeLongituddeArcodeParaleloPanel, 'numeric');
            app.GradosEditField_6.ValueChangedFcn = createCallbackFcn(app, @GradosEditField_6ValueChanged, true);
            app.MinutosEditField_6Label = uilabel(app.ClculodeLongituddeArcodeParaleloPanel);
            app.MinutosEditField_6Label.Text = 'Minutos';
            app.MinutosEditField_6 = uieditfield(app.ClculodeLongituddeArcodeParaleloPanel, 'numeric');
            app.MinutosEditField_6.ValueChangedFcn = createCallbackFcn(app, @MinutosEditField_6ValueChanged, true);
            app.CalcularButton_30 = uibutton(app.ClculodeLongituddeArcodeParaleloPanel, 'push');
            app.CalcularButton_30.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_30Pushed, true);
            app.CalcularButton_30.Text = 'Calcular';
            app.CalcularButton_31 = uibutton(app.ClculodeLongituddeArcodeParaleloPanel, 'push');
            app.CalcularButton_31.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_31Pushed, true);
            app.CalcularButton_31.Text = 'Calcular';
            app.CalcularButton_32 = uibutton(app.ClculodeLongituddeArcodeParaleloPanel, 'push');
            app.CalcularButton_32.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_32Pushed, true);
            app.CalcularButton_32.Text = 'Calcular';
            app.GRS80Label_7 = uilabel(app.ClculodeLongituddeArcodeParaleloPanel);
            app.GRS80Label_7.Text = 'GRS 80';
            app.WGS84Label_7 = uilabel(app.ClculodeLongituddeArcodeParaleloPanel);
            app.WGS84Label_7.Text = 'WGS 84';
            app.HayfordLabel_7 = uilabel(app.ClculodeLongituddeArcodeParaleloPanel);
            app.HayfordLabel_7.Text = 'Hayford';
            app.LatitudEditField_3Label = uilabel(app.ClculodeLongituddeArcodeParaleloPanel);
            app.LatitudEditField_3Label.Text = 'Latitud';
            app.LatitudEditField_3 = uieditfield(app.ClculodeLongituddeArcodeParaleloPanel, 'numeric');
            app.LatitudEditField_3.ValueChangedFcn = createCallbackFcn(app, @LatitudEditField_3ValueChanged, true);
            app.LongitudEditField_2Label = uilabel(app.ClculodeLongituddeArcodeParaleloPanel);
            app.LongitudEditField_2Label.Text = 'Longitud';
            app.LongitudEditField_2 = uieditfield(app.ClculodeLongituddeArcodeParaleloPanel, 'numeric');
            app.LongitudEditField_2.ValueChangedFcn = createCallbackFcn(app, @LongitudEditField_2ValueChanged, true);
            app.SpEditFieldLabel = uilabel(app.ClculodeLongituddeArcodeParaleloPanel);
            app.SpEditFieldLabel.Text = 'Sp';
            app.SpEditField = uieditfield(app.ClculodeLongituddeArcodeParaleloPanel, 'text');
            app.SegundosEditField_7Label = uilabel(app.ClculodeLongituddeArcodeParaleloPanel);
            app.SegundosEditField_7Label.Text = 'Segundos';
            app.SegundosEditField_7 = uieditfield(app.ClculodeLongituddeArcodeParaleloPanel, 'numeric');
            app.SegundosEditField_7.ValueChangedFcn = createCallbackFcn(app, @SegundosEditField_7ValueChanged, true);
            app.ReiniciarButton_3 = uibutton(app.ClculodeLongituddeArcodeParaleloPanel, 'push');
            app.ReiniciarButton_3.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButton_3Pushed, true);
            app.ReiniciarButton_3.Text = 'Reiniciar';
            app.SButton_3 = uibutton(app.ClculodeLongituddeArcodeParaleloPanel, 'push');
            app.SButton_3.ButtonPushedFcn = createCallbackFcn(app, @SButton_3Pushed, true);
            app.SButton_3.Text = 'S';
            app.OButton_2 = uibutton(app.ClculodeLongituddeArcodeParaleloPanel, 'push');
            app.OButton_2.ButtonPushedFcn = createCallbackFcn(app, @OButton_2Pushed, true);
            app.OButton_2.Text = 'O';
            app.LongitudBLabel = uilabel(app.ClculodeLongituddeArcodeParaleloPanel);
            app.LongitudBLabel.Text = 'Longitud B';
            app.GradosEditField_9Label = uilabel(app.ClculodeLongituddeArcodeParaleloPanel);
            app.GradosEditField_9Label.Text = 'Grados';
            app.GradosEditField_9 = uieditfield(app.ClculodeLongituddeArcodeParaleloPanel, 'numeric');
            app.GradosEditField_9.ValueChangedFcn = createCallbackFcn(app, @GradosEditField_9ValueChanged, true);
            app.MinutosEditField_9Label = uilabel(app.ClculodeLongituddeArcodeParaleloPanel);
            app.MinutosEditField_9Label.Text = 'Minutos';
            app.MinutosEditField_9 = uieditfield(app.ClculodeLongituddeArcodeParaleloPanel, 'numeric');
            app.MinutosEditField_9.ValueChangedFcn = createCallbackFcn(app, @MinutosEditField_9ValueChanged, true);
            app.SegundosEditField_8Label = uilabel(app.ClculodeLongituddeArcodeParaleloPanel);
            app.SegundosEditField_8Label.Text = 'Segundos';
            app.SegundosEditField_8 = uieditfield(app.ClculodeLongituddeArcodeParaleloPanel, 'numeric');
            app.SegundosEditField_8.ValueChangedFcn = createCallbackFcn(app, @SegundosEditField_8ValueChanged, true);
            app.LongitudBEditFieldLabel = uilabel(app.ClculodeLongituddeArcodeParaleloPanel);
            app.LongitudBEditFieldLabel.Text = 'Longitud B';
            app.LongitudBEditField = uieditfield(app.ClculodeLongituddeArcodeParaleloPanel, 'numeric');
            app.LongitudBEditField.ValueChangedFcn = createCallbackFcn(app, @LongitudBEditFieldValueChanged, true);
            app.Principales = uitab(app.TabGroup);
            app.Principales.Title = 'P. Principales';
            app.ClculodelProblemaDirectodelaGeodesiaPanel = uipanel(app.Principales);
            app.ClculodelProblemaDirectodelaGeodesiaPanel.Title = 'Cálculo del Problema Directo de la Geodesia';
            app.Label = uilabel(app.ClculodelProblemaDirectodelaGeodesiaPanel);
            app.Label.Text = 'Cálculo del Problema Directo si los valores están en Grados, Minutos y Segundos.';
            app.LatitudLabel_4 = uilabel(app.ClculodelProblemaDirectodelaGeodesiaPanel);
            app.LatitudLabel_4.Text = 'Latitud';
            app.LongitudLabel_2 = uilabel(app.ClculodelProblemaDirectodelaGeodesiaPanel);
            app.LongitudLabel_2.Text = 'Longitud';
            app.AzimutLabel_2 = uilabel(app.ClculodelProblemaDirectodelaGeodesiaPanel);
            app.AzimutLabel_2.Text = 'Azimut';
            app.GradosEditField_10Label = uilabel(app.ClculodelProblemaDirectodelaGeodesiaPanel);
            app.GradosEditField_10Label.Text = 'Grados';
            app.GradosEditField_10 = uieditfield(app.ClculodelProblemaDirectodelaGeodesiaPanel, 'numeric');
            app.GradosEditField_10.ValueChangedFcn = createCallbackFcn(app, @GradosEditField_10ValueChanged2, true);
            app.GradosEditField_11Label = uilabel(app.ClculodelProblemaDirectodelaGeodesiaPanel);
            app.GradosEditField_11Label.Text = 'Grados';
            app.GradosEditField_11 = uieditfield(app.ClculodelProblemaDirectodelaGeodesiaPanel, 'numeric');
            app.GradosEditField_11.ValueChangedFcn = createCallbackFcn(app, @GradosEditField_11ValueChanged2, true);
            app.GradosEditField_12Label = uilabel(app.ClculodelProblemaDirectodelaGeodesiaPanel);
            app.GradosEditField_12Label.Text = 'Grados';
            app.GradosEditField_12 = uieditfield(app.ClculodelProblemaDirectodelaGeodesiaPanel, 'numeric');
            app.GradosEditField_12.ValueChangedFcn = createCallbackFcn(app, @GradosEditField_12ValueChanged2, true);
            app.MinutosEditField_10Label = uilabel(app.ClculodelProblemaDirectodelaGeodesiaPanel);
            app.MinutosEditField_10Label.Text = 'Minutos';
            app.MinutosEditField_10 = uieditfield(app.ClculodelProblemaDirectodelaGeodesiaPanel, 'numeric');
            app.MinutosEditField_10.ValueChangedFcn = createCallbackFcn(app, @MinutosEditField_10ValueChanged2, true);
            app.MinutosEditField_11Label = uilabel(app.ClculodelProblemaDirectodelaGeodesiaPanel);
            app.MinutosEditField_11Label.Text = 'Minutos';
            app.MinutosEditField_11 = uieditfield(app.ClculodelProblemaDirectodelaGeodesiaPanel, 'numeric');
            app.MinutosEditField_11.ValueChangedFcn = createCallbackFcn(app, @MinutosEditField_11ValueChanged2, true);
            app.MinutosEditField_12Label = uilabel(app.ClculodelProblemaDirectodelaGeodesiaPanel);
            app.MinutosEditField_12Label.Text = 'Minutos';
            app.MinutosEditField_12 = uieditfield(app.ClculodelProblemaDirectodelaGeodesiaPanel, 'numeric');
            app.MinutosEditField_12.ValueChangedFcn = createCallbackFcn(app, @MinutosEditField_12ValueChanged2, true);
            app.SegundosEditField_10Label = uilabel(app.ClculodelProblemaDirectodelaGeodesiaPanel);
            app.SegundosEditField_10Label.Text = 'Segundos';
            app.SegundosEditField_10 = uieditfield(app.ClculodelProblemaDirectodelaGeodesiaPanel, 'numeric');
            app.SegundosEditField_10.ValueChangedFcn = createCallbackFcn(app, @SegundosEditField_10ValueChanged2, true);
            app.SegundosEditField_11Label = uilabel(app.ClculodelProblemaDirectodelaGeodesiaPanel);
            app.SegundosEditField_11Label.Text = 'Segundos';
            app.SegundosEditField_11 = uieditfield(app.ClculodelProblemaDirectodelaGeodesiaPanel, 'numeric');
            app.SegundosEditField_11.ValueChangedFcn = createCallbackFcn(app, @SegundosEditField_11ValueChanged2, true);
            app.SegundosEditField_12Label = uilabel(app.ClculodelProblemaDirectodelaGeodesiaPanel);
            app.SegundosEditField_12Label.Text = 'Segundos';
            app.SegundosEditField_12 = uieditfield(app.ClculodelProblemaDirectodelaGeodesiaPanel, 'numeric');
            app.SegundosEditField_12.ValueChangedFcn = createCallbackFcn(app, @SegundosEditField_12ValueChanged2, true);
            app.SButton_4 = uibutton(app.ClculodelProblemaDirectodelaGeodesiaPanel, 'push');
            app.SButton_4.ButtonPushedFcn = createCallbackFcn(app, @SButton_4Pushed3, true);
            app.SButton_4.Text = 'S';
            app.OButton_3 = uibutton(app.ClculodelProblemaDirectodelaGeodesiaPanel, 'push');
            app.OButton_3.ButtonPushedFcn = createCallbackFcn(app, @OButton_3Pushed2, true);
            app.OButton_3.Text = 'O';
            app.GRS80Button = uibutton(app.ClculodelProblemaDirectodelaGeodesiaPanel, 'push');
            app.GRS80Button.ButtonPushedFcn = createCallbackFcn(app, @GRS80ButtonPushed, true);
            app.GRS80Button.Text = 'GRS 80';
            app.HAYFORDButton = uibutton(app.ClculodelProblemaDirectodelaGeodesiaPanel, 'push');
            app.HAYFORDButton.ButtonPushedFcn = createCallbackFcn(app, @HAYFORDButtonPushed, true);
            app.HAYFORDButton.Text = 'HAYFORD';
            app.LatitudEditField_4Label = uilabel(app.ClculodelProblemaDirectodelaGeodesiaPanel);
            app.LatitudEditField_4Label.Text = 'Latitud';
            app.LatitudEditField_4 = uieditfield(app.ClculodelProblemaDirectodelaGeodesiaPanel, 'text');
            app.LatitudEditField_4.ValueChangedFcn = createCallbackFcn(app, @LatitudEditField_4ValueChanged2, true);
            app.LongitudEditField_3Label = uilabel(app.ClculodelProblemaDirectodelaGeodesiaPanel);
            app.LongitudEditField_3Label.Text = 'Longitud';
            app.LongitudEditField_3 = uieditfield(app.ClculodelProblemaDirectodelaGeodesiaPanel, 'text');
            app.LongitudEditField_3.ValueChangedFcn = createCallbackFcn(app, @LongitudEditField_3ValueChanged2, true);
            app.AzimutEditField_2Label = uilabel(app.ClculodelProblemaDirectodelaGeodesiaPanel);
            app.AzimutEditField_2Label.Text = 'Azimut';
            app.AzimutEditField_2 = uieditfield(app.ClculodelProblemaDirectodelaGeodesiaPanel, 'text');
            app.AzimutEditField_2.ValueChangedFcn = createCallbackFcn(app, @AzimutEditField_2ValueChanged2, true);
            app.Label_2 = uilabel(app.ClculodelProblemaDirectodelaGeodesiaPanel);
            app.Label_2.Text = 'Cálculo del Problema Directo de la Geodesia si los valores están en grados decimales.';
            app.AzimutEditField_3Label = uilabel(app.ClculodelProblemaDirectodelaGeodesiaPanel);
            app.AzimutEditField_3Label.Text = 'Azimut';
            app.AzimutEditField_3 = uieditfield(app.ClculodelProblemaDirectodelaGeodesiaPanel, 'numeric');
            app.AzimutEditField_3.ValueChangedFcn = createCallbackFcn(app, @AzimutEditField_3ValueChanged, true);
            app.DistanciaEditFieldLabel = uilabel(app.ClculodelProblemaDirectodelaGeodesiaPanel);
            app.DistanciaEditFieldLabel.Text = 'Distancia';
            app.DistanciaEditField = uieditfield(app.ClculodelProblemaDirectodelaGeodesiaPanel, 'numeric');
            app.DistanciaEditField.ValueChangedFcn = createCallbackFcn(app, @DistanciaEditFieldValueChanged2, true);
            app.WGS84Button = uibutton(app.ClculodelProblemaDirectodelaGeodesiaPanel, 'push');
            app.WGS84Button.ButtonPushedFcn = createCallbackFcn(app, @WGS84ButtonPushed2, true);
            app.WGS84Button.Text = 'WGS 84';
            app.LongitudEditField_4Label = uilabel(app.ClculodelProblemaDirectodelaGeodesiaPanel);
            app.LongitudEditField_4Label.Text = 'Longitud';
            app.LongitudEditField_4 = uieditfield(app.ClculodelProblemaDirectodelaGeodesiaPanel, 'numeric');
            app.LongitudEditField_4.ValueChangedFcn = createCallbackFcn(app, @LongitudEditField_4ValueChanged, true);
            app.GRS80Button_2 = uibutton(app.ClculodelProblemaDirectodelaGeodesiaPanel, 'push');
            app.GRS80Button_2.ButtonPushedFcn = createCallbackFcn(app, @GRS80Button_2Pushed, true);
            app.GRS80Button_2.Text = 'GRS 80';
            app.WGS84Button_2 = uibutton(app.ClculodelProblemaDirectodelaGeodesiaPanel, 'push');
            app.WGS84Button_2.ButtonPushedFcn = createCallbackFcn(app, @WGS84Button_2Pushed, true);
            app.WGS84Button_2.Text = 'WGS 84';
            app.HAYFORDButton_2 = uibutton(app.ClculodelProblemaDirectodelaGeodesiaPanel, 'push');
            app.HAYFORDButton_2.ButtonPushedFcn = createCallbackFcn(app, @HAYFORDButton_2Pushed, true);
            app.HAYFORDButton_2.Text = 'HAYFORD';
            app.RESULTADOSLabel = uilabel(app.ClculodelProblemaDirectodelaGeodesiaPanel);
            app.RESULTADOSLabel.Text = 'RESULTADOS';
            app.LatitudEditField_5Label = uilabel(app.ClculodelProblemaDirectodelaGeodesiaPanel);
            app.LatitudEditField_5Label.Text = 'Latitud';
            app.LatitudEditField_5 = uieditfield(app.ClculodelProblemaDirectodelaGeodesiaPanel, 'text');
            app.LatitudEditField_5.ValueChangedFcn = createCallbackFcn(app, @LatitudEditField_5ValueChanged, true);
            app.LongitudEditField_5Label = uilabel(app.ClculodelProblemaDirectodelaGeodesiaPanel);
            app.LongitudEditField_5Label.Text = 'Longitud';
            app.LongitudEditField_5 = uieditfield(app.ClculodelProblemaDirectodelaGeodesiaPanel, 'text');
            app.LongitudEditField_5.ValueChangedFcn = createCallbackFcn(app, @LongitudEditField_5ValueChanged, true);
            app.AzimutEditField_4Label = uilabel(app.ClculodelProblemaDirectodelaGeodesiaPanel);
            app.AzimutEditField_4Label.Text = 'Azimut';
            app.AzimutEditField_4 = uieditfield(app.ClculodelProblemaDirectodelaGeodesiaPanel, 'text');
            app.AzimutEditField_4.ValueChangedFcn = createCallbackFcn(app, @AzimutEditField_4ValueChanged, true);
            app.LatitudEditField_6Label = uilabel(app.ClculodelProblemaDirectodelaGeodesiaPanel);
            app.LatitudEditField_6Label.Text = 'Latitud';
            app.LatitudEditField_6 = uieditfield(app.ClculodelProblemaDirectodelaGeodesiaPanel, 'numeric');
            app.LatitudEditField_6.ValueChangedFcn = createCallbackFcn(app, @LatitudEditField_6ValueChanged, true);
            app.ReiniciarButton_6 = uibutton(app.ClculodelProblemaDirectodelaGeodesiaPanel, 'push');
            app.ReiniciarButton_6.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButton_6Pushed, true);
            app.ReiniciarButton_6.Text = 'Reiniciar';
            app.ReiniciarButton_7 = uibutton(app.ClculodelProblemaDirectodelaGeodesiaPanel, 'push');
            app.ReiniciarButton_7.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButton_7Pushed, true);
            app.ReiniciarButton_7.Text = 'Reiniciar';
            app.PInversoTab = uitab(app.TabGroup);
            app.PInversoTab.Title = 'P.Inverso';
            app.ProblemaInversodelaGeodesiaPanel = uipanel(app.PInversoTab);
            app.ProblemaInversodelaGeodesiaPanel.Title = 'Problema Inverso de la Geodesia.';
            app.GradosEditField_13Label = uilabel(app.ProblemaInversodelaGeodesiaPanel);
            app.GradosEditField_13Label.Text = 'Grados';
            app.GradosEditField_13 = uieditfield(app.ProblemaInversodelaGeodesiaPanel, 'numeric');
            app.GradosEditField_13.ValueChangedFcn = createCallbackFcn(app, @GradosEditField_13ValueChanged, true);
            app.LatitudLabel_5 = uilabel(app.ProblemaInversodelaGeodesiaPanel);
            app.LatitudLabel_5.Text = 'Latitud';
            app.MinutosEditField_13Label = uilabel(app.ProblemaInversodelaGeodesiaPanel);
            app.MinutosEditField_13Label.Text = 'Minutos';
            app.MinutosEditField_13 = uieditfield(app.ProblemaInversodelaGeodesiaPanel, 'numeric');
            app.MinutosEditField_13.ValueChangedFcn = createCallbackFcn(app, @MinutosEditField_13ValueChanged, true);
            app.SegundosEditField_13Label = uilabel(app.ProblemaInversodelaGeodesiaPanel);
            app.SegundosEditField_13Label.Text = 'Segundos';
            app.SegundosEditField_13 = uieditfield(app.ProblemaInversodelaGeodesiaPanel, 'numeric');
            app.SegundosEditField_13.ValueChangedFcn = createCallbackFcn(app, @SegundosEditField_13ValueChanged, true);
            app.LatitudLabel_6 = uilabel(app.ProblemaInversodelaGeodesiaPanel);
            app.LatitudLabel_6.Text = 'Latitud';
            app.GradosEditField_14Label = uilabel(app.ProblemaInversodelaGeodesiaPanel);
            app.GradosEditField_14Label.Text = 'Grados';
            app.GradosEditField_14 = uieditfield(app.ProblemaInversodelaGeodesiaPanel, 'numeric');
            app.GradosEditField_14.ValueChangedFcn = createCallbackFcn(app, @GradosEditField_14ValueChanged, true);
            app.MinutosEditField_14Label = uilabel(app.ProblemaInversodelaGeodesiaPanel);
            app.MinutosEditField_14Label.Text = 'Minutos';
            app.MinutosEditField_14 = uieditfield(app.ProblemaInversodelaGeodesiaPanel, 'numeric');
            app.MinutosEditField_14.ValueChangedFcn = createCallbackFcn(app, @MinutosEditField_14ValueChanged, true);
            app.SegundosEditField_14Label = uilabel(app.ProblemaInversodelaGeodesiaPanel);
            app.SegundosEditField_14Label.Text = 'Segundos';
            app.SegundosEditField_14 = uieditfield(app.ProblemaInversodelaGeodesiaPanel, 'numeric');
            app.SegundosEditField_14.ValueChangedFcn = createCallbackFcn(app, @SegundosEditField_14ValueChanged, true);
            app.IngresodelaLongitudPanel = uipanel(app.ProblemaInversodelaGeodesiaPanel);
            app.IngresodelaLongitudPanel.Title = 'Ingreso de la Longitud';
            app.GradosEditField_15Label = uilabel(app.IngresodelaLongitudPanel);
            app.GradosEditField_15Label.Text = 'Grados';
            app.GradosEditField_15 = uieditfield(app.IngresodelaLongitudPanel, 'numeric');
            app.GradosEditField_15.ValueChangedFcn = createCallbackFcn(app, @GradosEditField_15ValueChanged, true);
            app.MinutosEditField_15Label = uilabel(app.IngresodelaLongitudPanel);
            app.MinutosEditField_15Label.Text = 'Minutos';
            app.MinutosEditField_15 = uieditfield(app.IngresodelaLongitudPanel, 'numeric');
            app.MinutosEditField_15.ValueChangedFcn = createCallbackFcn(app, @MinutosEditField_15ValueChanged, true);
            app.SegundosEditField_15Label = uilabel(app.IngresodelaLongitudPanel);
            app.SegundosEditField_15Label.Text = 'Segundos';
            app.SegundosEditField_15 = uieditfield(app.IngresodelaLongitudPanel, 'numeric');
            app.SegundosEditField_15.ValueChangedFcn = createCallbackFcn(app, @SegundosEditField_15ValueChanged, true);
            app.GradosEditField_16Label = uilabel(app.IngresodelaLongitudPanel);
            app.GradosEditField_16Label.Text = 'Grados';
            app.GradosEditField_16 = uieditfield(app.IngresodelaLongitudPanel, 'numeric');
            app.GradosEditField_16.ValueChangedFcn = createCallbackFcn(app, @GradosEditField_16ValueChanged, true);
            app.MinutosEditField_16Label = uilabel(app.IngresodelaLongitudPanel);
            app.MinutosEditField_16Label.Text = 'Minutos';
            app.MinutosEditField_16 = uieditfield(app.IngresodelaLongitudPanel, 'numeric');
            app.MinutosEditField_16.ValueChangedFcn = createCallbackFcn(app, @MinutosEditField_16ValueChanged, true);
            app.SegundosEditField_16Label = uilabel(app.IngresodelaLongitudPanel);
            app.SegundosEditField_16Label.Text = 'Segundos';
            app.SegundosEditField_16 = uieditfield(app.IngresodelaLongitudPanel, 'numeric');
            app.SegundosEditField_16.ValueChangedFcn = createCallbackFcn(app, @SegundosEditField_16ValueChanged, true);
            app.LongitudLabel_3 = uilabel(app.IngresodelaLongitudPanel);
            app.LongitudLabel_3.Text = 'Longitud';
            app.LongitudLabel_4 = uilabel(app.IngresodelaLongitudPanel);
            app.LongitudLabel_4.Text = 'Longitud';
            app.OButton_4 = uibutton(app.IngresodelaLongitudPanel, 'push');
            app.OButton_4.ButtonPushedFcn = createCallbackFcn(app, @OButton_4Pushed, true);
            app.OButton_4.Text = 'O';
            app.ResultadosPanel = uipanel(app.ProblemaInversodelaGeodesiaPanel);
            app.ResultadosPanel.Title = 'Resultados';
            app.DistanciaEditField_2Label = uilabel(app.ResultadosPanel);
            app.DistanciaEditField_2Label.Text = 'Distancia';
            app.DistanciaEditField_2 = uieditfield(app.ResultadosPanel, 'text');
            app.AzimutEditField_5Label = uilabel(app.ResultadosPanel);
            app.AzimutEditField_5Label.Text = 'Azimut';
            app.AzimutEditField_5 = uieditfield(app.ResultadosPanel, 'text');
            app.CalcularButton_25 = uibutton(app.ResultadosPanel, 'push');
            app.CalcularButton_25.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_25Pushed, true);
            app.CalcularButton_25.Text = 'Calcular';
            app.ReiniciarButton_14 = uibutton(app.ResultadosPanel, 'push');
            app.ReiniciarButton_14.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButton_14Pushed, true);
            app.ReiniciarButton_14.Text = 'Reiniciar';
            app.metrosLabel = uilabel(app.ResultadosPanel);
            app.metrosLabel.Text = 'metros';
            app.GRS80Label_10 = uilabel(app.ResultadosPanel);
            app.GRS80Label_10.Text = 'GRS 80';
            app.metrosLabel_2 = uilabel(app.ResultadosPanel);
            app.metrosLabel_2.Text = 'metros';
            app.CalcularButton_26 = uibutton(app.ResultadosPanel, 'push');
            app.CalcularButton_26.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_26Pushed, true);
            app.CalcularButton_26.Text = 'Calcular';
            app.WGS84Label_8 = uilabel(app.ResultadosPanel);
            app.WGS84Label_8.Text = 'WGS 84';
            app.metrosLabel_3 = uilabel(app.ResultadosPanel);
            app.metrosLabel_3.Text = 'metros';
            app.CalcularButton_27 = uibutton(app.ResultadosPanel, 'push');
            app.CalcularButton_27.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_27Pushed, true);
            app.CalcularButton_27.Text = 'Calcular';
            app.HAYFORDLabel = uilabel(app.ResultadosPanel);
            app.HAYFORDLabel.Text = 'HAYFORD';
            app.DistanciaEditField_3Label = uilabel(app.ResultadosPanel);
            app.DistanciaEditField_3Label.Text = 'Distancia';
            app.DistanciaEditField_3 = uieditfield(app.ResultadosPanel, 'text');
            app.AzimutEditField_6Label = uilabel(app.ResultadosPanel);
            app.AzimutEditField_6Label.Text = 'Azimut';
            app.AzimutEditField_6 = uieditfield(app.ResultadosPanel, 'text');
            app.DistanciaEditField_4Label = uilabel(app.ResultadosPanel);
            app.DistanciaEditField_4Label.Text = 'Distancia';
            app.DistanciaEditField_4 = uieditfield(app.ResultadosPanel, 'text');
            app.AzimutEditField_7Label = uilabel(app.ResultadosPanel);
            app.AzimutEditField_7Label.Text = 'Azimut';
            app.AzimutEditField_7 = uieditfield(app.ResultadosPanel, 'text');
            app.SButton_5 = uibutton(app.ProblemaInversodelaGeodesiaPanel, 'push');
            app.SButton_5.ButtonPushedFcn = createCallbackFcn(app, @SButton_5Pushed, true);
            app.SButton_5.Text = 'S';
            app.GravedadNormalTab = uitab(app.TabGroup);
            app.GravedadNormalTab.Title = 'Gravedad Normal';
            app.GeodesiaFsicaPanel = uipanel(app.GravedadNormalTab);
            app.GeodesiaFsicaPanel.Title = 'Geodesia Física.';
            app.GravedadNormalyGravedadEncimadelElipsoidePanel = uipanel(app.GeodesiaFsicaPanel);
            app.GravedadNormalyGravedadEncimadelElipsoidePanel.Title = 'Gravedad Normal y Gravedad Encima del Elipsoide ';
            app.IngreseelvalordelaLatitudendecimalesLabel = uilabel(app.GravedadNormalyGravedadEncimadelElipsoidePanel);
            app.IngreseelvalordelaLatitudendecimalesLabel.Text = 'Ingrese el valor de la Latitud en decimales.';
            app.LatitudEditField_7Label = uilabel(app.GravedadNormalyGravedadEncimadelElipsoidePanel);
            app.LatitudEditField_7Label.Text = 'Latitud';
            app.LatitudEditField_7 = uieditfield(app.GravedadNormalyGravedadEncimadelElipsoidePanel, 'numeric');
            app.LatitudEditField_7.ValueChangedFcn = createCallbackFcn(app, @LatitudEditField_7ValueChanged, true);
            app.CalcularButton_19 = uibutton(app.GravedadNormalyGravedadEncimadelElipsoidePanel, 'push');
            app.CalcularButton_19.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_19Pushed4, true);
            app.CalcularButton_19.Text = 'Calcular';
            app.GRS80Label_8 = uilabel(app.GravedadNormalyGravedadEncimadelElipsoidePanel);
            app.GRS80Label_8.Text = 'GRS 80';
            app.GravedadNormalEditFieldLabel = uilabel(app.GravedadNormalyGravedadEncimadelElipsoidePanel);
            app.GravedadNormalEditFieldLabel.Text = 'Gravedad Normal';
            app.GravedadNormalEditField = uieditfield(app.GravedadNormalyGravedadEncimadelElipsoidePanel, 'text');
            app.ReiniciarButton_8 = uibutton(app.GravedadNormalyGravedadEncimadelElipsoidePanel, 'push');
            app.ReiniciarButton_8.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButton_8Pushed, true);
            app.ReiniciarButton_8.Text = 'Reiniciar';
            app.ClculodelaGravedadNormalEncimadelElipsoideLabel = uilabel(app.GravedadNormalyGravedadEncimadelElipsoidePanel);
            app.ClculodelaGravedadNormalEncimadelElipsoideLabel.Text = 'Cálculo de la Gravedad Normal Encima del  Elipsoide.';
            app.IngreseelvalordelaLatitudendecimalesLabel_2 = uilabel(app.GravedadNormalyGravedadEncimadelElipsoidePanel);
            app.IngreseelvalordelaLatitudendecimalesLabel_2.Text = 'Ingrese el valor de la Latitud en decimales.';
            app.LatitudEditField_8Label = uilabel(app.GravedadNormalyGravedadEncimadelElipsoidePanel);
            app.LatitudEditField_8Label.Text = 'Latitud';
            app.LatitudEditField_8 = uieditfield(app.GravedadNormalyGravedadEncimadelElipsoidePanel, 'numeric');
            app.LatitudEditField_8.ValueChangedFcn = createCallbackFcn(app, @LatitudEditField_8ValueChanged, true);
            app.GRS80Label_9 = uilabel(app.GravedadNormalyGravedadEncimadelElipsoidePanel);
            app.GRS80Label_9.Text = 'GRS 80';
            app.CalcularButton_20 = uibutton(app.GravedadNormalyGravedadEncimadelElipsoidePanel, 'push');
            app.CalcularButton_20.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_20Pushed, true);
            app.CalcularButton_20.Text = 'Calcular';
            app.GravedadResultadoEditFieldLabel = uilabel(app.GravedadNormalyGravedadEncimadelElipsoidePanel);
            app.GravedadResultadoEditFieldLabel.Text = 'Gravedad - Resultado';
            app.GravedadResultadoEditField = uieditfield(app.GravedadNormalyGravedadEncimadelElipsoidePanel, 'text');
            app.ReiniciarButton_9 = uibutton(app.GravedadNormalyGravedadEncimadelElipsoidePanel, 'push');
            app.ReiniciarButton_9.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButton_9Pushed, true);
            app.ReiniciarButton_9.Text = 'Reiniciar';
            app.VerificarButton = uibutton(app.GravedadNormalyGravedadEncimadelElipsoidePanel, 'push');
            app.VerificarButton.ButtonPushedFcn = createCallbackFcn(app, @VerificarButtonPushed, true);
            app.VerificarButton.Text = 'Verificar';
            app.AlturaEditField_2Label = uilabel(app.GravedadNormalyGravedadEncimadelElipsoidePanel);
            app.AlturaEditField_2Label.Text = 'Altura';
            app.AlturaEditField_2 = uieditfield(app.GravedadNormalyGravedadEncimadelElipsoidePanel, 'numeric');
            app.AlturaEditField_2.ValueChangedFcn = createCallbackFcn(app, @AlturaEditField_2ValueChanged, true);
            app.AlturasyAnomalasTab = uitab(app.TabGroup);
            app.AlturasyAnomalasTab.Title = 'Alturas y Anomalías';
            app.ClculodeAlturasRelativasPanel = uipanel(app.AlturasyAnomalasTab);
            app.ClculodeAlturasRelativasPanel.Title = 'Cálculo de Alturas Relativas.';
            app.AlturaElipsoidalEditFieldLabel = uilabel(app.ClculodeAlturasRelativasPanel);
            app.AlturaElipsoidalEditFieldLabel.Text = 'Altura Elipsoidal';
            app.AlturaElipsoidalEditField = uieditfield(app.ClculodeAlturasRelativasPanel, 'numeric');
            app.AlturaElipsoidalEditField.ValueChangedFcn = createCallbackFcn(app, @AlturaElipsoidalEditFieldValueChanged, true);
            app.AlturaOrtomtricaoNiveladaEditFieldLabel = uilabel(app.ClculodeAlturasRelativasPanel);
            app.AlturaOrtomtricaoNiveladaEditFieldLabel.Text = 'Altura Ortométrica o Nivelada';
            app.AlturaOrtomtricaoNiveladaEditField = uieditfield(app.ClculodeAlturasRelativasPanel, 'numeric');
            app.AlturaOrtomtricaoNiveladaEditField.ValueChangedFcn = createCallbackFcn(app, @AlturaOrtomtricaoNiveladaEditFieldValueChanged, true);
            app.NEGM08EditFieldLabel = uilabel(app.ClculodeAlturasRelativasPanel);
            app.NEGM08EditFieldLabel.Text = 'NEGM08';
            app.NEGM08EditField = uieditfield(app.ClculodeAlturasRelativasPanel, 'numeric');
            app.NEGM08EditField.ValueChangedFcn = createCallbackFcn(app, @NEGM08EditFieldValueChanged, true);
            app.CalcularButton_21 = uibutton(app.ClculodeAlturasRelativasPanel, 'push');
            app.CalcularButton_21.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_21Pushed, true);
            app.CalcularButton_21.Text = 'Calcular';
            app.ResultadoEditFieldLabel = uilabel(app.ClculodeAlturasRelativasPanel);
            app.ResultadoEditFieldLabel.Text = 'Resultado';
            app.ResultadoEditFieldH = uieditfield(app.ClculodeAlturasRelativasPanel, 'text');
            app.AnomalasdeGravedadconCorreccinalAireLibrePanel = uipanel(app.ClculodeAlturasRelativasPanel);
            app.AnomalasdeGravedadconCorreccinalAireLibrePanel.Title = 'Anomalías de Gravedad con Corrección al Aire Libre.';
            app.LatitudEditField_10Label = uilabel(app.AnomalasdeGravedadconCorreccinalAireLibrePanel);
            app.LatitudEditField_10Label.Text = 'Latitud';
            app.LatitudEditField_10 = uieditfield(app.AnomalasdeGravedadconCorreccinalAireLibrePanel, 'numeric');
            app.LatitudEditField_10.ValueChangedFcn = createCallbackFcn(app, @LatitudEditField_10ValueChanged, true);
            app.AlturaEditField_3Label = uilabel(app.AnomalasdeGravedadconCorreccinalAireLibrePanel);
            app.AlturaEditField_3Label.Text = 'Altura';
            app.AlturaEditField_3 = uieditfield(app.AnomalasdeGravedadconCorreccinalAireLibrePanel, 'numeric');
            app.AlturaEditField_3.ValueChangedFcn = createCallbackFcn(app, @AlturaEditField_3ValueChanged, true);
            app.GravedadenelPuntoObservadoEditFieldLabel = uilabel(app.AnomalasdeGravedadconCorreccinalAireLibrePanel);
            app.GravedadenelPuntoObservadoEditFieldLabel.Text = 'Gravedad en el Punto Observado';
            app.GravedadenelPuntoObservadoEditField = uieditfield(app.AnomalasdeGravedadconCorreccinalAireLibrePanel, 'numeric');
            app.GravedadenelPuntoObservadoEditField.ValueChangedFcn = createCallbackFcn(app, @GravedadenelPuntoObservadoEditFieldValueChanged, true);
            app.GravedadCA = uibutton(app.AnomalasdeGravedadconCorreccinalAireLibrePanel, 'push');
            app.GravedadCA.ButtonPushedFcn = createCallbackFcn(app, @GravedadCAPushed, true);
            app.GravedadCA.Text = 'Calcular';
            app.ResultadoEditField_2Label = uilabel(app.AnomalasdeGravedadconCorreccinalAireLibrePanel);
            app.ResultadoEditField_2Label.Text = 'Resultado';
            app.ResultadoEditField_2 = uieditfield(app.AnomalasdeGravedadconCorreccinalAireLibrePanel, 'text');
            app.AnomaladeGravedadconCorrecionesdemonsieurBouguerPanel = uipanel(app.AnomalasdeGravedadconCorreccinalAireLibrePanel);
            app.AnomaladeGravedadconCorrecionesdemonsieurBouguerPanel.Title = 'Anomalía de Gravedad con Correciones de monsieur Bouguer.';
            app.LatitudEditField_11Label = uilabel(app.AnomaladeGravedadconCorrecionesdemonsieurBouguerPanel);
            app.LatitudEditField_11Label.Text = 'Latitud';
            app.LatitudEditField_11 = uieditfield(app.AnomaladeGravedadconCorrecionesdemonsieurBouguerPanel, 'numeric');
            app.LatitudEditField_11.ValueChangedFcn = createCallbackFcn(app, @LatitudEditField_11ValueChanged, true);
            app.AlturaEditField_4Label = uilabel(app.AnomaladeGravedadconCorrecionesdemonsieurBouguerPanel);
            app.AlturaEditField_4Label.Text = 'Altura';
            app.AlturaEditField_4 = uieditfield(app.AnomaladeGravedadconCorrecionesdemonsieurBouguerPanel, 'numeric');
            app.AlturaEditField_4.ValueChangedFcn = createCallbackFcn(app, @AlturaEditField_4ValueChanged, true);
            app.DensidadEditFieldLabel = uilabel(app.AnomaladeGravedadconCorrecionesdemonsieurBouguerPanel);
            app.DensidadEditFieldLabel.Text = 'Densidad';
            app.DensidadEditField = uieditfield(app.AnomaladeGravedadconCorrecionesdemonsieurBouguerPanel, 'numeric');
            app.DensidadEditField.ValueChangedFcn = createCallbackFcn(app, @DensidadEditFieldValueChanged, true);
            app.NotaTextAreaLabel = uilabel(app.AnomaladeGravedadconCorrecionesdemonsieurBouguerPanel);
            app.NotaTextAreaLabel.Text = 'Nota: ';
            app.NotaTextArea = uitextarea(app.AnomaladeGravedadconCorrecionesdemonsieurBouguerPanel);
            app.NotaTextArea.Value = {'Cuando no tenga usted un valor de densidad puede utilizar 2.67g/cm^3'};
            app.CalcularButton_23 = uibutton(app.AnomaladeGravedadconCorrecionesdemonsieurBouguerPanel, 'push');
            app.CalcularButton_23.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_23Pushed, true);
            app.CalcularButton_23.Text = 'Calcular';
            app.ResultadoEditField_3Label = uilabel(app.AnomaladeGravedadconCorrecionesdemonsieurBouguerPanel);
            app.ResultadoEditField_3Label.Text = 'Resultado';
            app.ResultadoEditField_3 = uieditfield(app.AnomaladeGravedadconCorrecionesdemonsieurBouguerPanel, 'text');
            app.MetrosLabel_3 = uilabel(app.AnomaladeGravedadconCorrecionesdemonsieurBouguerPanel);
            app.MetrosLabel_3.Text = 'Metros';
            app.gcm3Label = uilabel(app.AnomaladeGravedadconCorrecionesdemonsieurBouguerPanel);
            app.gcm3Label.Text = 'g/cm^3';
            app.GravedadenelPuntoObservadoEditField_2Label = uilabel(app.AnomaladeGravedadconCorrecionesdemonsieurBouguerPanel);
            app.GravedadenelPuntoObservadoEditField_2Label.Text = 'Gravedad en el Punto Observado';
            app.GravedadenelPuntoObservadoEditField_2 = uieditfield(app.AnomaladeGravedadconCorrecionesdemonsieurBouguerPanel, 'numeric');
            app.GravedadenelPuntoObservadoEditField_2.ValueChangedFcn = createCallbackFcn(app, @GravedadenelPuntoObservadoEditField_2ValueChanged, true);
            app.ReiniciarButton_12 = uibutton(app.AnomaladeGravedadconCorrecionesdemonsieurBouguerPanel, 'push');
            app.ReiniciarButton_12.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButton_12Pushed, true);
            app.ReiniciarButton_12.Text = 'Reiniciar';
            app.mGALLabel_4 = uilabel(app.AnomaladeGravedadconCorrecionesdemonsieurBouguerPanel);
            app.mGALLabel_4.Text = 'mGAL';
            app.mGALLabel_5 = uilabel(app.AnomaladeGravedadconCorrecionesdemonsieurBouguerPanel);
            app.mGALLabel_5.Text = 'mGAL';
            app.PerturbacionesdeGravedadPanel = uipanel(app.AnomalasdeGravedadconCorreccinalAireLibrePanel);
            app.PerturbacionesdeGravedadPanel.Title = 'Perturbaciones de Gravedad. ';
            app.LatitudEditField_12Label = uilabel(app.PerturbacionesdeGravedadPanel);
            app.LatitudEditField_12Label.Text = 'Latitud';
            app.LatitudEditField_12 = uieditfield(app.PerturbacionesdeGravedadPanel, 'numeric');
            app.LatitudEditField_12.ValueChangedFcn = createCallbackFcn(app, @LatitudEditField_12ValueChanged, true);
            app.AlturaEditField_5Label = uilabel(app.PerturbacionesdeGravedadPanel);
            app.AlturaEditField_5Label.Text = 'Altura';
            app.AlturaEditField_5 = uieditfield(app.PerturbacionesdeGravedadPanel, 'numeric');
            app.AlturaEditField_5.ValueChangedFcn = createCallbackFcn(app, @AlturaEditField_5ValueChanged, true);
            app.GravedadenelpuntoobservadoEditFieldLabel = uilabel(app.PerturbacionesdeGravedadPanel);
            app.GravedadenelpuntoobservadoEditFieldLabel.Text = 'Gravedad en el punto observado';
            app.GravedadenelpuntoobservadoEditField = uieditfield(app.PerturbacionesdeGravedadPanel, 'numeric');
            app.GravedadenelpuntoobservadoEditField.ValueChangedFcn = createCallbackFcn(app, @GravedadenelpuntoobservadoEditFieldValueChanged, true);
            app.CalcularButton_24 = uibutton(app.PerturbacionesdeGravedadPanel, 'push');
            app.CalcularButton_24.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_24Pushed, true);
            app.CalcularButton_24.Text = 'Calcular';
            app.ResultadoEditField_4Label = uilabel(app.PerturbacionesdeGravedadPanel);
            app.ResultadoEditField_4Label.Text = 'Resultado';
            app.ResultadoEditField_4 = uieditfield(app.PerturbacionesdeGravedadPanel, 'text');
            app.mGALLabel = uilabel(app.PerturbacionesdeGravedadPanel);
            app.mGALLabel.Text = 'mGAL';
            app.MetrosLabel_4 = uilabel(app.PerturbacionesdeGravedadPanel);

            app.MetrosLabel_4.Text = 'Metros';
            app.mGALLabel_6 = uilabel(app.PerturbacionesdeGravedadPanel);
            app.mGALLabel_6.Text = 'mGAL';
            app.ReiniciarButton_13 = uibutton(app.PerturbacionesdeGravedadPanel, 'push');
            app.ReiniciarButton_13.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButton_13Pushed, true);
            app.ReiniciarButton_13.Text = 'Reiniciar';
            app.MetrosLabel_2 = uilabel(app.AnomalasdeGravedadconCorreccinalAireLibrePanel);
            app.MetrosLabel_2.Text = 'Metros';
            app.mGALLabel_2 = uilabel(app.AnomalasdeGravedadconCorreccinalAireLibrePanel);
            app.mGALLabel_2.Text = 'mGAL';
            app.ReiniciarButton_11 = uibutton(app.AnomalasdeGravedadconCorreccinalAireLibrePanel, 'push');
            app.ReiniciarButton_11.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButton_11Pushed, true);
            app.ReiniciarButton_11.Text = 'Reiniciar';
            app.mGALLabel_3 = uilabel(app.AnomalasdeGravedadconCorreccinalAireLibrePanel);
            app.mGALLabel_3.Text = 'mGAL';
            app.AlturaElipsoidalBEditFieldLabel = uilabel(app.ClculodeAlturasRelativasPanel);
            app.AlturaElipsoidalBEditFieldLabel.Text = 'Altura Elipsoidal (B)';
            app.AlturaElipsoidalBEditField = uieditfield(app.ClculodeAlturasRelativasPanel, 'numeric');
            app.AlturaElipsoidalBEditField.ValueChangedFcn = createCallbackFcn(app, @AlturaElipsoidalBEditFieldValueChanged, true);
            app.NEGM08_BEditFieldLabel = uilabel(app.ClculodeAlturasRelativasPanel);
            app.NEGM08_BEditFieldLabel.Text = 'NEGM08_B';
            app.NEGM08_BEditField = uieditfield(app.ClculodeAlturasRelativasPanel, 'numeric');
            app.NEGM08_BEditField.ValueChangedFcn = createCallbackFcn(app, @NEGM08_BEditFieldValueChanged, true);
            app.ReiniciarButton_10 = uibutton(app.ClculodeAlturasRelativasPanel, 'push');
            app.ReiniciarButton_10.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButton_10Pushed, true);
            app.ReiniciarButton_10.Text = 'Reiniciar';
            app.TMarcosTab = uitab(app.TabGroup);
            app.TMarcosTab.Title = 'T. Marcos';
            app.IngresodeTraslacionesyDiferenciadeEscalaPanel = uipanel(app.TMarcosTab);
            app.IngresodeTraslacionesyDiferenciadeEscalaPanel.Title = 'Ingreso de Traslaciones y Diferencia de Escala';
            app.TxEditFieldLabel = uilabel(app.IngresodeTraslacionesyDiferenciadeEscalaPanel);
            app.TxEditFieldLabel.Text = 'Tx';
            app.TxEditField = uieditfield(app.IngresodeTraslacionesyDiferenciadeEscalaPanel, 'numeric');
            app.TxEditField.ValueChangedFcn = createCallbackFcn(app, @TxEditFieldValueChanged, true);
            app.TyEditFieldLabel = uilabel(app.IngresodeTraslacionesyDiferenciadeEscalaPanel);
            app.TyEditFieldLabel.Text = 'Ty';
            app.TyEditField = uieditfield(app.IngresodeTraslacionesyDiferenciadeEscalaPanel, 'numeric');
            app.TyEditField.ValueChangedFcn = createCallbackFcn(app, @TyEditFieldValueChanged, true);
            app.TzEditFieldLabel = uilabel(app.IngresodeTraslacionesyDiferenciadeEscalaPanel);
            app.TzEditFieldLabel.Text = 'Tz';
            app.TzEditField = uieditfield(app.IngresodeTraslacionesyDiferenciadeEscalaPanel, 'numeric');
            app.TzEditField.ValueChangedFcn = createCallbackFcn(app, @TzEditFieldValueChanged, true);
            app.metrosLabel_4 = uilabel(app.IngresodeTraslacionesyDiferenciadeEscalaPanel);
            app.metrosLabel_4.Text = 'metros';
            app.metrosLabel_5 = uilabel(app.IngresodeTraslacionesyDiferenciadeEscalaPanel);
            app.metrosLabel_5.Text = 'metros';
            app.metrosLabel_6 = uilabel(app.IngresodeTraslacionesyDiferenciadeEscalaPanel);
            app.metrosLabel_6.Text = 'metros';
            app.DiferenciadeEscalaEditFieldLabel = uilabel(app.IngresodeTraslacionesyDiferenciadeEscalaPanel);
            app.DiferenciadeEscalaEditFieldLabel.Text = 'Diferencia de Escala';
            app.DiferenciadeEscalaEditField = uieditfield(app.IngresodeTraslacionesyDiferenciadeEscalaPanel, 'numeric');
            app.DiferenciadeEscalaEditField.ValueChangedFcn = createCallbackFcn(app, @DiferenciadeEscalaEditFieldValueChanged, true);
            app.RotacionesPanel = uipanel(app.TMarcosTab);
            app.RotacionesPanel.Title = 'Rotaciones';
            app.RxEditFieldLabel = uilabel(app.RotacionesPanel);
            app.RxEditFieldLabel.Text = 'Rx';
            app.RxEditField = uieditfield(app.RotacionesPanel, 'numeric');
            app.RxEditField.ValueChangedFcn = createCallbackFcn(app, @RxEditFieldValueChanged, true);
            app.RyEditFieldLabel = uilabel(app.RotacionesPanel);
            app.RyEditFieldLabel.Text = 'Ry';
            app.RyEditField = uieditfield(app.RotacionesPanel, 'numeric');
            app.RyEditField.ValueChangedFcn = createCallbackFcn(app, @RyEditFieldValueChanged, true);
            app.RzEditFieldLabel = uilabel(app.RotacionesPanel);
            app.RzEditFieldLabel.Text = 'Rz';
            app.RzEditField = uieditfield(app.RotacionesPanel, 'numeric');
            app.RzEditField.ValueChangedFcn = createCallbackFcn(app, @RzEditFieldValueChanged, true);
            app.TextArea = uitextarea(app.RotacionesPanel);
            app.TextArea.Value = {'En este apartado es para que los valores se ingresen si son 0 o no. Si son 0 ingréselos directamente, de lo contrario ingréselos primero en el apartado derecho y después en el de Rotaciones.'; ''};
            app.ConversindeRotacionesPanel = uipanel(app.TMarcosTab);
            app.ConversindeRotacionesPanel.Title = 'Conversión de Rotaciones';
            app.RxEditField_2Label = uilabel(app.ConversindeRotacionesPanel);
            app.RxEditField_2Label.Text = 'Rx';
            app.RxEditField_2 = uieditfield(app.ConversindeRotacionesPanel, 'numeric');
            app.RxEditField_2.ValueChangedFcn = createCallbackFcn(app, @RxEditField_2ValueChanged, true);
            app.RyEditField_2Label = uilabel(app.ConversindeRotacionesPanel);
            app.RyEditField_2Label.Text = 'Ry';
            app.RyEditField_2 = uieditfield(app.ConversindeRotacionesPanel, 'numeric');
            app.RyEditField_2.ValueChangedFcn = createCallbackFcn(app, @RyEditField_2ValueChanged, true);
            app.RzEditField_2Label = uilabel(app.ConversindeRotacionesPanel);
            app.RzEditField_2Label.Text = 'Rz';
            app.RzEditField_2 = uieditfield(app.ConversindeRotacionesPanel, 'numeric');
            app.RzEditField_2.ValueChangedFcn = createCallbackFcn(app, @RzEditField_2ValueChanged, true);
            app.mAsLabel = uilabel(app.ConversindeRotacionesPanel);
            app.mAsLabel.Text = 'mAs';
            app.mAsLabel_2 = uilabel(app.ConversindeRotacionesPanel);
            app.mAsLabel_2.Text = 'mAs';
            app.mAsLabel_3 = uilabel(app.ConversindeRotacionesPanel);
            app.mAsLabel_3.Text = 'mAs';
            app.CalcularButton_29 = uibutton(app.ConversindeRotacionesPanel, 'push');
            app.CalcularButton_29.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_29Pushed, true);
            app.CalcularButton_29.Text = 'Calcular';
            app.ResultadoPanel = uipanel(app.TMarcosTab);
            app.ResultadoPanel.Title = 'Resultado';
            app.RxEditField_3Label = uilabel(app.ResultadoPanel);
            app.RxEditField_3Label.Text = 'Rx';
            app.RxEditField_3 = uieditfield(app.ResultadoPanel, 'text');
            app.RyEditField_3Label = uilabel(app.ResultadoPanel);
            app.RyEditField_3Label.Text = 'Ry';
            app.RyEditField_3 = uieditfield(app.ResultadoPanel, 'text');
            app.RzEditField_3Label = uilabel(app.ResultadoPanel);
            app.RzEditField_3Label.Text = 'Rz';
            app.RzEditField_3 = uieditfield(app.ResultadoPanel, 'text');
            app.IngresodeCoordenadasInicialesPanel = uipanel(app.TMarcosTab);
            app.IngresodeCoordenadasInicialesPanel.Title = 'Ingreso de Coordenadas Iniciales';
            app.XEditFieldLabel = uilabel(app.IngresodeCoordenadasInicialesPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.IngresodeCoordenadasInicialesPanel, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.IngresodeCoordenadasInicialesPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.IngresodeCoordenadasInicialesPanel, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.ZEditFieldLabel = uilabel(app.IngresodeCoordenadasInicialesPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.IngresodeCoordenadasInicialesPanel, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.metrosLabel_7 = uilabel(app.IngresodeCoordenadasInicialesPanel);
            app.metrosLabel_7.Text = 'metros';
            app.metrosLabel_8 = uilabel(app.IngresodeCoordenadasInicialesPanel);
            app.metrosLabel_8.Text = 'metros';
            app.metrosLabel_9 = uilabel(app.IngresodeCoordenadasInicialesPanel);
            app.metrosLabel_9.Text = 'metros';
            app.CoordenadasFinalesPanel = uipanel(app.TMarcosTab);
            app.CoordenadasFinalesPanel.Title = 'Coordenadas Finales.';
            app.XEditField_2Label = uilabel(app.CoordenadasFinalesPanel);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.CoordenadasFinalesPanel, 'text');
            app.YEditField_2Label = uilabel(app.CoordenadasFinalesPanel);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.CoordenadasFinalesPanel, 'text');
            app.ZEditField_2Label = uilabel(app.CoordenadasFinalesPanel);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.CoordenadasFinalesPanel, 'text');
            app.metrosLabel_10 = uilabel(app.CoordenadasFinalesPanel);
            app.metrosLabel_10.Text = 'metros';
            app.metrosLabel_11 = uilabel(app.CoordenadasFinalesPanel);
            app.metrosLabel_11.Text = 'metros';
            app.metrosLabel_12 = uilabel(app.CoordenadasFinalesPanel);
            app.metrosLabel_12.Text = 'metros';
            app.CalcularButton_28 = uibutton(app.CoordenadasFinalesPanel, 'push');
            app.CalcularButton_28.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_28Pushed, true);
            app.CalcularButton_28.Text = 'Calcular';
            app.ReiniciarButton_15 = uibutton(app.CoordenadasFinalesPanel, 'push');
            app.ReiniciarButton_15.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButton_15Pushed, true);
            app.ReiniciarButton_15.Text = 'Reiniciar';
            app.AcercadeTab = uitab(app.TabGroup);
            app.AcercadeTab.Title = 'Acerca de';
            app.FnUnicodelaCalculadoradeGeodesiaGeneralPanel = uipanel(app.AcercadeTab);
            app.FnUnicodelaCalculadoradeGeodesiaGeneralPanel.Title = 'Fín Unico de la Calculadora de Geodesia General.';
            app.TextArea_2 = uitextarea(app.FnUnicodelaCalculadoradeGeodesiaGeneralPanel);
            app.TextArea_2.Value = {'Bien decía Montalvo, Montesquieu , Descartes y demás autores de la filosofía clásica que "La connaissance est inutile si elle n’est pas partagée" que por su traducción del francés menciona lo siguiente "el conocimiento es inútil sino se comparte", de esta manera citando a tan vehementes autores se presenta, a los estudiantes, una herrmienta intuitiva para que se puedan ayudar en el estudio de la Geodesia General, cátedra que es impartida en la Universidad de las Fuerzas Armadas ESPE por el Dr. Alfonso Tierra Criollo, ingeniéro geógrafo e investigador. '; ''; 'He ahí que se presenta esta pequeña calculadora, fruto del esfuerzo del autor de la misma y con el fortuoso deseo, de que, quienes se beneficien de ella, puedan seguir aumentando sus conocimientos y sus deseos de aprender. '; ''; ''};
            app.AcercadelAutorPanel = uipanel(app.AcercadeTab);
            app.AcercadelAutorPanel.Title = 'Acerca del Autor. ';
            app.TextArea_3 = uitextarea(app.AcercadelAutorPanel);
            app.TextArea_3.Value = {'Paúl Ismael Escobar Córdova, autor de esta calculadora, es nacido en la muy ciudad ilustre de Ambato, de la provincia de Tungurahua del país Ecuador el 9 de Agosto de 1998. '; ''; 'Sus estudios secundarios fueron realizados en el inclitísimo Colegio Nacional Bolívar de Ambato, estudió Latín, idiomas como el Inglés y Francés, música y filosofía  de doctrina dominicana, en la muy noble y leal ciudad de México.'; ''; 'Actualmente se desempeña como estudiante en la Universidad de las Fuerzas Armadas ESPE dentro de la carrera de Ingeniería Geoespacial.  '; ''};
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GeodesicCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end