classdef Calculadora < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CALCULADORAGEODSICAGENERALLabel  matlab.ui.control.Label
        FuncinPrincipalynicadeestacalculadoraTextAreaLabel  matlab.ui.control.Label
        FuncinPrincipalynicadeestacalculadoraTextArea  matlab.ui.control.TextArea
        QudeseahacerPanel               matlab.ui.container.Panel
        HistoriaButton                  matlab.ui.control.Button
        ClculodeCoordenadasButton       matlab.ui.control.Button
        PrincipalesRadiosdeCurvaturaButton  matlab.ui.control.Button
        ClculodeArcosDropDownLabel      matlab.ui.control.Label
        ClculodeArcosDropDown           matlab.ui.control.DropDown
        ProblemasFundamentalesDropDownLabel  matlab.ui.control.Label
        ProblemasFundamentalesDropDown  matlab.ui.control.DropDown
        CamposdeGravedadDropDownLabel   matlab.ui.control.Label
        CamposdeGravedadDropDown        matlab.ui.control.DropDown
        IngresarlosValoresEditFieldLabel  matlab.ui.control.Label
        IngresarlosValoresEditField     matlab.ui.control.NumericEditField
        ResultadosEditFieldLabel        matlab.ui.control.Label
        ResultadosEditField             matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: HistoriaButton
        function HistoriaButtonPushed(app, event)
        app.FuncinPrincipalynicadeestacalculadoraTextArea.Value = 'Este es el texto que quiero mostrar.';
        end

        % Button pushed function: ClculodeCoordenadasButton
        function ClculodeCoordenadasButtonPushed(app, event)
     %% Conversión de Cordenadas cartesianas espaciales a geodésicas.
     % GRS 80 SISTEMA GEODÉSICO DEL ECUADOR
    a= 6378137.0;
    f= 1/298.257222101;
    % wgs 84 
    a1= 6378137.0;
    f1= 1/298.257223563;
    % HAYFORD
    a2= 6378388;
    f2=1/297;
    format long
    app.FuncinPrincipalynicadeestacalculadoraTextArea.Value = 'Es un placer hacer los cálculos por usted ingeniero mi nombre es geodesiccomputs - Cálculos Geodésicos'
    app.FuncinPrincipalynicadeestacalculadoraTextArea.Value = 'Fui programado por el estudiante Paul Ismael Escobar Córdova'
    
    
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CALCULADORAGEODSICAGENERALLabel = uilabel(app.UIFigure);
            app.CALCULADORAGEODSICAGENERALLabel.Text = 'CALCULADORA GEODÉSICA GENERAL';
            app.FuncinPrincipalynicadeestacalculadoraTextAreaLabel = uilabel(app.UIFigure);
            app.FuncinPrincipalynicadeestacalculadoraTextAreaLabel.Text = 'Función Principal y Única de esta calculadora';
            app.FuncinPrincipalynicadeestacalculadoraTextArea = uitextarea(app.UIFigure);
            app.FuncinPrincipalynicadeestacalculadoraTextArea.Value = {'En el estudio de la Geodesia General en el campo de la docencia y de la enseñanaza es muy peculiar, al momento de resolver  los problemas de transformación de coordenadas, la aplicación de los radios de curvatura y demás; con un número de decimales que requiere la exactitud de la geodesia. '; ''; 'Por ello , la principal función de la presente calculadora es abordar los cálculos principales que se han estudiado en el curso de Geodesia General, cátedra impartida por el Dr. Alfonso Tierra., en la muy prestigiosa Universidad de las Fuerzas Armadas "ESPE". Facilitando e induciendo el estudio de esta rama tan importante en la carrera de ingeniería Geoespacial para el servicio de la Patria. '; ''; 'Además se ofrece una muy resumidad teoría de los temas que se tratan en los cálculos para que los estudiantes obtenegan la información necesaria y adecuadad al momento de realizar sus cálculos.'; ''; ''};
            app.QudeseahacerPanel = uipanel(app.UIFigure);
            app.QudeseahacerPanel.Title = '¿Qué desea hacer?';
            app.HistoriaButton = uibutton(app.QudeseahacerPanel, 'push');
            app.HistoriaButton.ButtonPushedFcn = createCallbackFcn(app, @HistoriaButtonPushed, true);
            app.HistoriaButton.Text = 'Historia';
            app.ClculodeCoordenadasButton = uibutton(app.QudeseahacerPanel, 'push');
            app.ClculodeCoordenadasButton.ButtonPushedFcn = createCallbackFcn(app, @ClculodeCoordenadasButtonPushed, true);
            app.ClculodeCoordenadasButton.Text = 'Cálculo de Coordenadas';
            app.PrincipalesRadiosdeCurvaturaButton = uibutton(app.QudeseahacerPanel, 'push');
            app.PrincipalesRadiosdeCurvaturaButton.Text = 'Principales Radios de Curvatura';
            app.ClculodeArcosDropDownLabel = uilabel(app.QudeseahacerPanel);
            app.ClculodeArcosDropDownLabel.Text = 'Cálculo de Arcos';
            app.ClculodeArcosDropDown = uidropdown(app.QudeseahacerPanel);
            app.ClculodeArcosDropDown.Items = {'Seleccione', 'Longitud Arco Paralelo', 'Longitud Arco de Meridiano.'};
            app.ClculodeArcosDropDown.Value = 'Seleccione';
            app.ProblemasFundamentalesDropDownLabel = uilabel(app.QudeseahacerPanel);
            app.ProblemasFundamentalesDropDownLabel.Text = 'Problemas Fundamentales';
            app.ProblemasFundamentalesDropDown = uidropdown(app.QudeseahacerPanel);
            app.ProblemasFundamentalesDropDown.Items = {'Seleccione', 'Problema Directo', 'Problema Inverso'};
            app.ProblemasFundamentalesDropDown.Value = 'Seleccione';
            app.CamposdeGravedadDropDownLabel = uilabel(app.QudeseahacerPanel);
            app.CamposdeGravedadDropDownLabel.Text = 'Campos de Gravedad';
            app.CamposdeGravedadDropDown = uidropdown(app.QudeseahacerPanel);
            app.CamposdeGravedadDropDown.Items = {'Seleccione', 'Gravedad Normal', 'Gravedad Normal encima del Elipsoide'};
            app.CamposdeGravedadDropDown.Value = 'Seleccione';
            app.IngresarlosValoresEditFieldLabel = uilabel(app.QudeseahacerPanel);
            app.IngresarlosValoresEditFieldLabel.Text = 'Ingresar los Valores';
            app.IngresarlosValoresEditField = uieditfield(app.QudeseahacerPanel, 'numeric');
            app.ResultadosEditFieldLabel = uilabel(app.QudeseahacerPanel);
            app.ResultadosEditFieldLabel.Text = 'Resultados';
            app.ResultadosEditField = uieditfield(app.QudeseahacerPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calculadora

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end